classdef Main_func < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        NewMenu                         matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        SaveasMenu                      matlab.ui.container.Menu
        ver2023Menu                     matlab.ui.container.Menu
        ver2024Menu                     matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        UndoMenu                        matlab.ui.container.Menu
        RedoMenu                        matlab.ui.container.Menu
        CutMenu                         matlab.ui.container.Menu
        CopyMenu                        matlab.ui.container.Menu
        PasteMenu                       matlab.ui.container.Menu
        DeleteMenu                      matlab.ui.container.Menu
        ScaleMenu                       matlab.ui.container.Menu
        PreferenceMenu                  matlab.ui.container.Menu
        DesignMenu                      matlab.ui.container.Menu
        FinsgeometryMenu                matlab.ui.container.Menu
        AnimationMenu                   matlab.ui.container.Menu
        RealtimeMenu                    matlab.ui.container.Menu
        helpMenu                        matlab.ui.container.Menu
        TabGroup2                       matlab.ui.container.TabGroup
        RocketgeometryTab               matlab.ui.container.Tab
        RocketpropertiesTab             matlab.ui.container.Tab
        kgm2Label_22                    matlab.ui.control.Label
        kgm2Label_21                    matlab.ui.control.Label
        kgm2Label_20                    matlab.ui.control.Label
        kgm2Label_19                    matlab.ui.control.Label
        kgm2Label_18                    matlab.ui.control.Label
        kgm2Label_17                    matlab.ui.control.Label
        kgm2Label_16                    matlab.ui.control.Label
        kgm2Label_15                    matlab.ui.control.Label
        kgm2Label_14                    matlab.ui.control.Label
        kgm2Label_13                    matlab.ui.control.Label
        kgm2Label_12                    matlab.ui.control.Label
        kgm2Label_11                    matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        CGDistancefromnoseLabel         matlab.ui.control.Label
        InertiaILabel                   matlab.ui.control.Label
        TailElementLabel_2              matlab.ui.control.Label
        TailElementInertiaEditField_9   matlab.ui.control.NumericEditField
        TailLabel_2                     matlab.ui.control.Label
        TailElementInertiaEditField_8   matlab.ui.control.NumericEditField
        TailElementInertiaEditField_7   matlab.ui.control.NumericEditField
        TailElementInertiaEditField_6   matlab.ui.control.NumericEditField
        TailElementInertiaEditField_5   matlab.ui.control.NumericEditField
        TailElementInertiaEditField_4   matlab.ui.control.NumericEditField
        TailElementInertiaEditField_3   matlab.ui.control.NumericEditField
        TailElementInertiaEditField_2   matlab.ui.control.NumericEditField
        TailElementInertiaEditField     matlab.ui.control.NumericEditField
        FuelInitialLabel_2              matlab.ui.control.Label
        FuelInitialInertiaEditField_9   matlab.ui.control.NumericEditField
        FuelFinalLabel_2                matlab.ui.control.Label
        FuelFinalInertiaEditField_9     matlab.ui.control.NumericEditField
        FuelTankLabel_2                 matlab.ui.control.Label
        FuelTankInertiaEditField_9      matlab.ui.control.NumericEditField
        CCCombustionchamberLabel_2      matlab.ui.control.Label
        CCLabel_2                       matlab.ui.control.Label
        FuelTankInertiaEditField_8      matlab.ui.control.NumericEditField
        FuelTankInertiaEditField_7      matlab.ui.control.NumericEditField
        FuelTankInertiaEditField_6      matlab.ui.control.NumericEditField
        FuelTankInertiaEditField_5      matlab.ui.control.NumericEditField
        FuelTankInertiaEditField_4      matlab.ui.control.NumericEditField
        FuelTankInertiaEditField_3      matlab.ui.control.NumericEditField
        FuelTankInertiaEditField_2      matlab.ui.control.NumericEditField
        FuelTankInertiaEditField        matlab.ui.control.NumericEditField
        FuelInitialInertiaEditField_8   matlab.ui.control.NumericEditField
        FuelInitialInertiaEditField_7   matlab.ui.control.NumericEditField
        FuelInitialInertiaEditField_6   matlab.ui.control.NumericEditField
        FuelInitialInertiaEditField_5   matlab.ui.control.NumericEditField
        FuelInitialInertiaEditField_4   matlab.ui.control.NumericEditField
        FuelInitialInertiaEditField_3   matlab.ui.control.NumericEditField
        FuelInitialInertiaEditField_2   matlab.ui.control.NumericEditField
        FuelInitialInertiaEditField     matlab.ui.control.NumericEditField
        FuelFinalInertiaEditField_8     matlab.ui.control.NumericEditField
        FuelFinalInertiaEditField_7     matlab.ui.control.NumericEditField
        FuelFinalInertiaEditField_6     matlab.ui.control.NumericEditField
        FuelFinalInertiaEditField_5     matlab.ui.control.NumericEditField
        FuelFinalInertiaEditField_4     matlab.ui.control.NumericEditField
        FuelFinalInertiaEditField_3     matlab.ui.control.NumericEditField
        FuelFinalInertiaEditField_2     matlab.ui.control.NumericEditField
        FuelFinalInertiaEditField       matlab.ui.control.NumericEditField
        CVElementInertiaEditField_9     matlab.ui.control.NumericEditField
        CVElementLabel_2                matlab.ui.control.Label
        CVConnectionVolveLabel_2        matlab.ui.control.Label
        CVLabel_2                       matlab.ui.control.Label
        CVElementInertiaEditField_8     matlab.ui.control.NumericEditField
        CVElementInertiaEditField_7     matlab.ui.control.NumericEditField
        CVElementInertiaEditField_6     matlab.ui.control.NumericEditField
        CVElementInertiaEditField_5     matlab.ui.control.NumericEditField
        CVElementInertiaEditField_4     matlab.ui.control.NumericEditField
        CVElementInertiaEditField_3     matlab.ui.control.NumericEditField
        CVElementInertiaEditField_2     matlab.ui.control.NumericEditField
        CVElementInertiaEditField       matlab.ui.control.NumericEditField
        N2OInitialLabel_2               matlab.ui.control.Label
        N2OInitialInertiaEditField_9    matlab.ui.control.NumericEditField
        N2OFinalLabel_2                 matlab.ui.control.Label
        N2OFinalInertiaEditField_9      matlab.ui.control.NumericEditField
        N2OTankLabel_2                  matlab.ui.control.Label
        N2OTankInertiaEditField_9       matlab.ui.control.NumericEditField
        N2OLabel_2                      matlab.ui.control.Label
        N2OTankInertiaEditField_8       matlab.ui.control.NumericEditField
        N2OTankInertiaEditField_7       matlab.ui.control.NumericEditField
        N2OTankInertiaEditField_6       matlab.ui.control.NumericEditField
        N2OTankInertiaEditField_5       matlab.ui.control.NumericEditField
        N2OTankInertiaEditField_4       matlab.ui.control.NumericEditField
        N2OTankInertiaEditField_3       matlab.ui.control.NumericEditField
        N2OTankInertiaEditField_2       matlab.ui.control.NumericEditField
        N2OTankInertiaEditField         matlab.ui.control.NumericEditField
        N2OInitialInertiaEditField_8    matlab.ui.control.NumericEditField
        N2OInitialInertiaEditField_7    matlab.ui.control.NumericEditField
        N2OInitialInertiaEditField_6    matlab.ui.control.NumericEditField
        N2OInitialInertiaEditField_5    matlab.ui.control.NumericEditField
        N2OInitialInertiaEditField_4    matlab.ui.control.NumericEditField
        N2OInitialInertiaEditField_3    matlab.ui.control.NumericEditField
        N2OInitialInertiaEditField_2    matlab.ui.control.NumericEditField
        N2OInitialInertiaEditField      matlab.ui.control.NumericEditField
        N2OFinalInertiaEditField_8      matlab.ui.control.NumericEditField
        N2OFinalInertiaEditField_7      matlab.ui.control.NumericEditField
        N2OFinalInertiaEditField_6      matlab.ui.control.NumericEditField
        N2OFinalInertiaEditField_5      matlab.ui.control.NumericEditField
        N2OFinalInertiaEditField_4      matlab.ui.control.NumericEditField
        N2OFinalInertiaEditField_3      matlab.ui.control.NumericEditField
        N2OFinalInertiaEditField_2      matlab.ui.control.NumericEditField
        N2OFinalInertiaEditField        matlab.ui.control.NumericEditField
        RecoveryLabel_2                 matlab.ui.control.Label
        RecoveryElementLabel_2          matlab.ui.control.Label
        RecoveryElementInertiaEditField_9  matlab.ui.control.NumericEditField
        RecoveryElementInertiaEditField_8  matlab.ui.control.NumericEditField
        RecoveryElementInertiaEditField_7  matlab.ui.control.NumericEditField
        RecoveryElementInertiaEditField_6  matlab.ui.control.NumericEditField
        RecoveryElementInertiaEditField_5  matlab.ui.control.NumericEditField
        RecoveryElementInertiaEditField_4  matlab.ui.control.NumericEditField
        RecoveryElementInertiaEditField_3  matlab.ui.control.NumericEditField
        RecoveryElementInertiaEditField_2  matlab.ui.control.NumericEditField
        RecoveryElementInertiaEditField  matlab.ui.control.NumericEditField
        CanardElementLabel_2            matlab.ui.control.Label
        CanardElementInertiaEditField_9  matlab.ui.control.NumericEditField
        CanardLabel_2                   matlab.ui.control.Label
        CanardElementInertiaEditField_8  matlab.ui.control.NumericEditField
        CanardElementInertiaEditField_7  matlab.ui.control.NumericEditField
        CanardElementInertiaEditField_6  matlab.ui.control.NumericEditField
        CanardElementInertiaEditField_5  matlab.ui.control.NumericEditField
        CanardElementInertiaEditField_4  matlab.ui.control.NumericEditField
        CanardElementInertiaEditField_3  matlab.ui.control.NumericEditField
        CanardElementInertiaEditField_2  matlab.ui.control.NumericEditField
        CanardElementInertiaEditField   matlab.ui.control.NumericEditField
        AvionicsElementLabel_2          matlab.ui.control.Label
        AvionicsElementInertiaEditField_9  matlab.ui.control.NumericEditField
        AvionicsLabel_2                 matlab.ui.control.Label
        AvionicsElementInertiaEditField_8  matlab.ui.control.NumericEditField
        AvionicsElementInertiaEditField_7  matlab.ui.control.NumericEditField
        AvionicsElementInertiaEditField_6  matlab.ui.control.NumericEditField
        AvionicsElementInertiaEditField_5  matlab.ui.control.NumericEditField
        AvionicsElementInertiaEditField_4  matlab.ui.control.NumericEditField
        AvionicsElementInertiaEditField_3  matlab.ui.control.NumericEditField
        AvionicsElementInertiaEditField_2  matlab.ui.control.NumericEditField
        AvionicsElementInertiaEditField  matlab.ui.control.NumericEditField
        NoseElementLabel_2              matlab.ui.control.Label
        NoseElementInertiaEditField_9   matlab.ui.control.NumericEditField
        NoseLabel_2                     matlab.ui.control.Label
        NoseElementInertiaEditField_8   matlab.ui.control.NumericEditField
        NoseElementInertiaEditField_7   matlab.ui.control.NumericEditField
        NoseElementInertiaEditField_6   matlab.ui.control.NumericEditField
        NoseElementInertiaEditField_5   matlab.ui.control.NumericEditField
        NoseElementInertiaEditField_4   matlab.ui.control.NumericEditField
        NoseElementInertiaEditField_3   matlab.ui.control.NumericEditField
        NoseElementInertiaEditField_2   matlab.ui.control.NumericEditField
        NoseElementInertiaEditField     matlab.ui.control.NumericEditField
        Image_10                        matlab.ui.control.Image
        MassCGpartmassCGLabel           matlab.ui.control.Label
        kgLabel_69                      matlab.ui.control.Label
        kgLabel_68                      matlab.ui.control.Label
        kgLabel_67                      matlab.ui.control.Label
        kgLabel_65                      matlab.ui.control.Label
        kgLabel_66                      matlab.ui.control.Label
        kgLabel_63                      matlab.ui.control.Label
        kgLabel_62                      matlab.ui.control.Label
        kgLabel_61                      matlab.ui.control.Label
        kgLabel_60                      matlab.ui.control.Label
        kgLabel_58                      matlab.ui.control.Label
        kgLabel_53                      matlab.ui.control.Label
        kgLabel_51                      matlab.ui.control.Label
        CVConnectionVolveLabel          matlab.ui.control.Label
        kgLabel_47                      matlab.ui.control.Label
        kgLabel_44                      matlab.ui.control.Label
        kgLabel_42                      matlab.ui.control.Label
        kgLabel_40                      matlab.ui.control.Label
        kgLabel_34                      matlab.ui.control.Label
        kgLabel_32                      matlab.ui.control.Label
        kgLabel_6                       matlab.ui.control.Label
        kgLabel_9                       matlab.ui.control.Label
        kgLabel_5                       matlab.ui.control.Label
        CCCombustionchamberLabel        matlab.ui.control.Label
        kgLabel                         matlab.ui.control.Label
        kgLabel_4                       matlab.ui.control.Label
        kgLabel_2                       matlab.ui.control.Label
        SelectModel_Panel               matlab.ui.container.Panel
        SelectModelDropDown             matlab.ui.control.DropDown
        SelectModelDropDownLabel        matlab.ui.control.Label
        NoseLabel                       matlab.ui.control.Label
        NoseElementLabel                matlab.ui.control.Label
        NoseElementMassEditField        matlab.ui.control.NumericEditField
        NoseElementCGEditField          matlab.ui.control.NumericEditField
        AvionicsLabel                   matlab.ui.control.Label
        AvionicsElementLabel            matlab.ui.control.Label
        AvionicsElementMassEditField    matlab.ui.control.NumericEditField
        AvionicsElementCGEditField      matlab.ui.control.NumericEditField
        CanardLabel                     matlab.ui.control.Label
        CanardElementLabel              matlab.ui.control.Label
        CanardElementMassEditField      matlab.ui.control.NumericEditField
        CanardElementCGEditField        matlab.ui.control.NumericEditField
        RecoveryLabel                   matlab.ui.control.Label
        RecoveryElementLabel            matlab.ui.control.Label
        RecoveryElementMassEditField    matlab.ui.control.NumericEditField
        RecoveryElementCGEditField      matlab.ui.control.NumericEditField
        N2OLabel                        matlab.ui.control.Label
        N2OTankLabel                    matlab.ui.control.Label
        N2OTankMassEditField            matlab.ui.control.NumericEditField
        N2OTankCGEditField              matlab.ui.control.NumericEditField
        N2OInitialLabel                 matlab.ui.control.Label
        N2OInitialMassEditField         matlab.ui.control.NumericEditField
        N2OInitialCGEditField           matlab.ui.control.NumericEditField
        CVLabel                         matlab.ui.control.Label
        N2OFinalLabel                   matlab.ui.control.Label
        CVElementLabel                  matlab.ui.control.Label
        N2OFinalMassEditField           matlab.ui.control.NumericEditField
        CVElementMassEditField          matlab.ui.control.NumericEditField
        N2OFinalCGEditField             matlab.ui.control.NumericEditField
        CVElementCGEditField            matlab.ui.control.NumericEditField
        CCLabel                         matlab.ui.control.Label
        FuelTankLabel                   matlab.ui.control.Label
        FuelTankMassEditField           matlab.ui.control.NumericEditField
        FuelTankCGEditField             matlab.ui.control.NumericEditField
        FuelInitialLabel                matlab.ui.control.Label
        FuelInitialMassEditField        matlab.ui.control.NumericEditField
        FuelInitialCGEditField          matlab.ui.control.NumericEditField
        FuelFinalLabel                  matlab.ui.control.Label
        FuelFinalMassEditField          matlab.ui.control.NumericEditField
        FuelFinalCGEditField            matlab.ui.control.NumericEditField
        TailLabel                       matlab.ui.control.Label
        TailElementLabel                matlab.ui.control.Label
        TailElementMassEditField        matlab.ui.control.NumericEditField
        TailElementCGEditField          matlab.ui.control.NumericEditField
        Image_9                         matlab.ui.control.Image
        ThrustcurveTab                  matlab.ui.container.Tab
        SelectModel_Panel_3             matlab.ui.container.Panel
        SelectModelDropDown_3           matlab.ui.control.DropDown
        SelectModelDropDown_3Label      matlab.ui.control.Label
        ThrustTimeCurvePanel            matlab.ui.container.Panel
        T4EditField_2                   matlab.ui.control.NumericEditField
        T4EditField_2Label              matlab.ui.control.Label
        kgfLabel_9                      matlab.ui.control.Label
        T4EditField                     matlab.ui.control.NumericEditField
        T4EditFieldLabel                matlab.ui.control.Label
        sLabel_5                        matlab.ui.control.Label
        kgfLabel_8                      matlab.ui.control.Label
        T0EditField_2                   matlab.ui.control.NumericEditField
        T0EditField_2Label              matlab.ui.control.Label
        kgfLabel_7                      matlab.ui.control.Label
        T1EditField_2                   matlab.ui.control.NumericEditField
        T1EditField_2Label              matlab.ui.control.Label
        kgfLabel_6                      matlab.ui.control.Label
        T2EditField_2                   matlab.ui.control.NumericEditField
        T2EditField_2Label              matlab.ui.control.Label
        T3EditField_2                   matlab.ui.control.NumericEditField
        T3EditField_2Label              matlab.ui.control.Label
        kgfLabel_5                      matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        T0EditField                     matlab.ui.control.NumericEditField
        T0EditFieldLabel                matlab.ui.control.Label
        sLabel_2                        matlab.ui.control.Label
        T1EditField                     matlab.ui.control.NumericEditField
        T1EditFieldLabel                matlab.ui.control.Label
        sLabel_3                        matlab.ui.control.Label
        T2EditField                     matlab.ui.control.NumericEditField
        T2EditFieldLabel                matlab.ui.control.Label
        sLabel_4                        matlab.ui.control.Label
        T3EditField                     matlab.ui.control.NumericEditField
        T3EditFieldLabel                matlab.ui.control.Label
        ThrustTimeAxes                  matlab.ui.control.UIAxes
        FlightSimulationTab             matlab.ui.container.Tab
        TabGroup                        matlab.ui.container.TabGroup
        PositionTab                     matlab.ui.container.Tab
        PositionUIAxes                  matlab.ui.control.UIAxes
        AngularVelocityTab              matlab.ui.container.Tab
        AngularVUIAxes                  matlab.ui.control.UIAxes
        AOAAOSTab                       matlab.ui.container.Tab
        AOA_AOSUIAxes                   matlab.ui.control.UIAxes
        CPCGStabilityTab                matlab.ui.container.Tab
        StabilityUIAxes                 matlab.ui.control.UIAxes
        AttitudeTab                     matlab.ui.container.Tab
        AttitudeUIAxes                  matlab.ui.control.UIAxes
        MachTab                         matlab.ui.container.Tab
        MachUIAxes                      matlab.ui.control.UIAxes
        StageTab                        matlab.ui.container.Tab
        StageUIAxes                     matlab.ui.control.UIAxes
        VelocityBodyTab                 matlab.ui.container.Tab
        VelocityUIAxes                  matlab.ui.control.UIAxes
        MassTab                         matlab.ui.container.Tab
        MassUIAxes                      matlab.ui.control.UIAxes
        ThrustTab                       matlab.ui.container.Tab
        dynamicpressureTab              matlab.ui.container.Tab
        QUIAxes                         matlab.ui.control.UIAxes
        DownloaddataButton              matlab.ui.control.Button
        runtimeEditField                matlab.ui.control.NumericEditField
        runtimeEditFieldLabel           matlab.ui.control.Label
        SimulationconditionPanel        matlab.ui.container.Panel
        StoptimeEditField               matlab.ui.control.NumericEditField
        StoptimeEditFieldLabel          matlab.ui.control.Label
        SamplingtimeEditField           matlab.ui.control.NumericEditField
        SamplingtimeEditFieldLabel      matlab.ui.control.Label
        secLabel_2                      matlab.ui.control.Label
        secLabel                        matlab.ui.control.Label
        ControllerconditionPanel        matlab.ui.container.Panel
        PitchyawcontrolCheckBox         matlab.ui.control.CheckBox
        RollcontrolCheckBox             matlab.ui.control.CheckBox
        EnvironmentconditionPanel       matlab.ui.container.Panel
        WindfieldCheckBox               matlab.ui.control.CheckBox
        WinddataDropDownLabel           matlab.ui.control.Label
        WinddataDropDown                matlab.ui.control.DropDown
        WindgustCheckBox                matlab.ui.control.CheckBox
        Run6DOFButton                   matlab.ui.control.Button
        InitialconditionPanel           matlab.ui.container.Panel
        LaunchraillengthEditFieldLabel  matlab.ui.control.Label
        LaunchraillengthEditField       matlab.ui.control.NumericEditField
        mLabel_4                        matlab.ui.control.Label
        WhichrocketDropDown             matlab.ui.control.DropDown
        WhichrocketDropDownLabel        matlab.ui.control.Label
        LaunchdirectionN0EditFieldLabel  matlab.ui.control.Label
        LaunchdirectionN0EditField      matlab.ui.control.NumericEditField
        degreeLabel_2                   matlab.ui.control.Label
        LaunchangleEditField            matlab.ui.control.NumericEditField
        LaunchangleEditFieldLabel       matlab.ui.control.Label
        degreeLabel                     matlab.ui.control.Label
        RocketCGpositionxEditFieldLabel  matlab.ui.control.Label
        RocketCGpositionxEditField      matlab.ui.control.NumericEditField
        yEditFieldLabel                 matlab.ui.control.Label
        yEditField                      matlab.ui.control.NumericEditField
        zEditFieldLabel                 matlab.ui.control.Label
        zEditField                      matlab.ui.control.NumericEditField
        mLabel_3                        matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        EnvironmentConditionTab         matlab.ui.container.Tab
        ControllerdesignTab             matlab.ui.container.Tab
        MonteCarlomethodCheckBox        matlab.ui.control.CheckBox
        LinearsystemparametersPanel     matlab.ui.container.Panel
        massEditField_28                matlab.ui.control.NumericEditField
        massEditFieldLabel_28           matlab.ui.control.Label
        massEditField_27                matlab.ui.control.NumericEditField
        massEditFieldLabel_27           matlab.ui.control.Label
        massEditField_26                matlab.ui.control.NumericEditField
        massEditFieldLabel_26           matlab.ui.control.Label
        massEditField_25                matlab.ui.control.NumericEditField
        massEditFieldLabel_25           matlab.ui.control.Label
        massEditField_24                matlab.ui.control.NumericEditField
        massEditFieldLabel_24           matlab.ui.control.Label
        massEditField_23                matlab.ui.control.NumericEditField
        massEditFieldLabel_23           matlab.ui.control.Label
        massEditField_22                matlab.ui.control.NumericEditField
        massEditFieldLabel_22           matlab.ui.control.Label
        massEditField_21                matlab.ui.control.NumericEditField
        massEditFieldLabel_21           matlab.ui.control.Label
        massEditField_20                matlab.ui.control.NumericEditField
        massEditFieldLabel_20           matlab.ui.control.Label
        massEditField_19                matlab.ui.control.NumericEditField
        massEditFieldLabel_19           matlab.ui.control.Label
        massEditField_18                matlab.ui.control.NumericEditField
        massEditFieldLabel_18           matlab.ui.control.Label
        SymmetricCheckBox               matlab.ui.control.CheckBox
        kgm2Label_10                    matlab.ui.control.Label
        massEditField_17                matlab.ui.control.NumericEditField
        massEditFieldLabel_17           matlab.ui.control.Label
        kgm2Label_9                     matlab.ui.control.Label
        massEditField_16                matlab.ui.control.NumericEditField
        massEditFieldLabel_16           matlab.ui.control.Label
        kgm2Label_8                     matlab.ui.control.Label
        massEditField_15                matlab.ui.control.NumericEditField
        massEditFieldLabel_15           matlab.ui.control.Label
        kgm2Label_7                     matlab.ui.control.Label
        massEditField_14                matlab.ui.control.NumericEditField
        massEditFieldLabel_14           matlab.ui.control.Label
        kgm2Label_6                     matlab.ui.control.Label
        massEditField_13                matlab.ui.control.NumericEditField
        massEditFieldLabel_13           matlab.ui.control.Label
        kgm2Label_5                     matlab.ui.control.Label
        massEditField_12                matlab.ui.control.NumericEditField
        massEditFieldLabel_12           matlab.ui.control.Label
        kgm2Label_4                     matlab.ui.control.Label
        massEditField_11                matlab.ui.control.NumericEditField
        massEditFieldLabel_11           matlab.ui.control.Label
        kgm2Label_3                     matlab.ui.control.Label
        massEditField_10                matlab.ui.control.NumericEditField
        massEditFieldLabel_10           matlab.ui.control.Label
        kgm2Label_2                     matlab.ui.control.Label
        massEditField_9                 matlab.ui.control.NumericEditField
        massEditFieldLabel_9            matlab.ui.control.Label
        inertiaLabel                    matlab.ui.control.Label
        kgm2Label                       matlab.ui.control.Label
        massEditField_8                 matlab.ui.control.NumericEditField
        massEditFieldLabel_8            matlab.ui.control.Label
        msLabel_3                       matlab.ui.control.Label
        massEditField_7                 matlab.ui.control.NumericEditField
        massEditFieldLabel_7            matlab.ui.control.Label
        msLabel_2                       matlab.ui.control.Label
        massEditField_6                 matlab.ui.control.NumericEditField
        massEditFieldLabel_6            matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        massEditField_5                 matlab.ui.control.NumericEditField
        massEditFieldLabel_5            matlab.ui.control.Label
        radsLabel_3                     matlab.ui.control.Label
        massEditField_4                 matlab.ui.control.NumericEditField
        massEditFieldLabel_4            matlab.ui.control.Label
        radsLabel_2                     matlab.ui.control.Label
        massEditField_3                 matlab.ui.control.NumericEditField
        massEditFieldLabel_3            matlab.ui.control.Label
        radsLabel                       matlab.ui.control.Label
        massEditField_2                 matlab.ui.control.NumericEditField
        massEditFieldLabel_2            matlab.ui.control.Label
        kgLabel_133                     matlab.ui.control.Label
        massEditField                   matlab.ui.control.NumericEditField
        massEditFieldLabel              matlab.ui.control.Label
        ControllerDropDown              matlab.ui.control.DropDown
        ControllerDropDownLabel         matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        RealtimesimulationTab           matlab.ui.container.Tab
        DataloggingPanel                matlab.ui.container.Panel
        FilenameEditField               matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        LoggingStopButton               matlab.ui.control.StateButton
        Panel_2                         matlab.ui.container.Panel
        HeightEditField_2               matlab.ui.control.NumericEditField
        HeightEditField_2Label          matlab.ui.control.Label
        LongitudeEditField_2            matlab.ui.control.NumericEditField
        LongitudeEditField_2Label       matlab.ui.control.Label
        LatitudeEditField_2             matlab.ui.control.NumericEditField
        LatitudeEditField_2Label        matlab.ui.control.Label
        PredictIIPLabel                 matlab.ui.control.Label
        MeasurementdataPanel            matlab.ui.container.Panel
        RealaccelerationPanel           matlab.ui.container.Panel
        RealAccZEditField               matlab.ui.control.NumericEditField
        AccZEditField_2Label            matlab.ui.control.Label
        RealAccYEditField               matlab.ui.control.NumericEditField
        AccYEditField_2Label            matlab.ui.control.Label
        RealAccXEditField               matlab.ui.control.NumericEditField
        AccXEditField_2Label            matlab.ui.control.Label
        ECEFPositionPanel               matlab.ui.container.Panel
        PosZEditField                   matlab.ui.control.NumericEditField
        PosZEditFieldLabel              matlab.ui.control.Label
        PosYEditField                   matlab.ui.control.NumericEditField
        PosYEditFieldLabel              matlab.ui.control.Label
        PosXEditField                   matlab.ui.control.NumericEditField
        PosXEditFieldLabel              matlab.ui.control.Label
        VelocityPanel                   matlab.ui.container.Panel
        VelZEditField                   matlab.ui.control.NumericEditField
        VelZEditFieldLabel              matlab.ui.control.Label
        VelYEditField                   matlab.ui.control.NumericEditField
        VelYEditFieldLabel              matlab.ui.control.Label
        VelXEditField                   matlab.ui.control.NumericEditField
        VelXEditFieldLabel              matlab.ui.control.Label
        FreeaccelerationPanel           matlab.ui.container.Panel
        AccZEditField                   matlab.ui.control.NumericEditField
        AccZEditFieldLabel              matlab.ui.control.Label
        AccYEditField                   matlab.ui.control.NumericEditField
        AccYEditFieldLabel              matlab.ui.control.Label
        AccXEditField                   matlab.ui.control.NumericEditField
        AccXEditFieldLabel              matlab.ui.control.Label
        RateOfTurnPanel                 matlab.ui.container.Panel
        YawrateEditField                matlab.ui.control.NumericEditField
        YawrateEditFieldLabel           matlab.ui.control.Label
        PitchrateEditField              matlab.ui.control.NumericEditField
        PitchrateEditFieldLabel         matlab.ui.control.Label
        RollrateEditField               matlab.ui.control.NumericEditField
        RollrateEditFieldLabel          matlab.ui.control.Label
        AttitudePanel                   matlab.ui.container.Panel
        YawEditField                    matlab.ui.control.NumericEditField
        YawEditFieldLabel               matlab.ui.control.Label
        PitchEditField                  matlab.ui.control.NumericEditField
        PitchEditFieldLabel             matlab.ui.control.Label
        RollEditField                   matlab.ui.control.NumericEditField
        RollEditFieldLabel              matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        HeightEditField                 matlab.ui.control.NumericEditField
        HeightEditFieldLabel            matlab.ui.control.Label
        LongitudeEditField              matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel         matlab.ui.control.Label
        LatitudeEditField               matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel          matlab.ui.control.Label
        CurrentLabel                    matlab.ui.control.Label
        INSstatusPanel                  matlab.ui.container.Panel
        CautionLabel_2                  matlab.ui.control.Label
        SatalitenumberGauge             matlab.ui.control.LinearGauge
        SatalitenumberGaugeLabel        matlab.ui.control.Label
        IMUtempuratureGauge             matlab.ui.control.LinearGauge
        IMUtempuratureGaugeLabel        matlab.ui.control.Label
        GNSSSatInfoEditField            matlab.ui.control.NumericEditField
        GNSSSatInfoEditFieldLabel       matlab.ui.control.Label
        GDOPEditField                   matlab.ui.control.NumericEditField
        GDOPEditFieldLabel              matlab.ui.control.Label
        TimefixedLabel                  matlab.ui.control.Label
        GNSSDeadreckoningLabel          matlab.ui.control.Label
        NofixedLabel                    matlab.ui.control.Label
        DeadreckoningLabel              matlab.ui.control.Label
        DfixedLabel_2                   matlab.ui.control.Label
        DfixedLabel                     matlab.ui.control.Label
        GNSSFixedGauge                  matlab.ui.control.LinearGauge
        GNSSFixedGaugeLabel             matlab.ui.control.Label
        FilterModeGauge                 matlab.ui.control.LinearGauge
        FilterModeGaugeLabel            matlab.ui.control.Label
        WithoutGNSSLabel                matlab.ui.control.Label
        UTCtimeEditField                matlab.ui.control.EditField
        CoastingModelossGNSSin60secLabel  matlab.ui.control.Label
        WithGNSSLabel                   matlab.ui.control.Label
        UTCtimeEditFieldLabel           matlab.ui.control.Label
        StatuswordEditField             matlab.ui.control.NumericEditField
        StatuswordEditFieldLabel        matlab.ui.control.Label
        LoRarelatedPanel                matlab.ui.container.Panel
        LinkstatusPanel                 matlab.ui.container.Panel
        LoRaRSSIGaugeLabel              matlab.ui.control.Label
        LoRaRSSIGauge                   matlab.ui.control.LinearGauge
        LoRaSNREditField                matlab.ui.control.NumericEditField
        LoRaSNREditFieldLabel           matlab.ui.control.Label
        LoRaRSSIEditField               matlab.ui.control.NumericEditField
        LoRaRSSIEditFieldLabel          matlab.ui.control.Label
        BaudrateListBox                 matlab.ui.control.ListBox
        BaudrateListBoxLabel            matlab.ui.control.Label
        COMportListBox                  matlab.ui.control.ListBox
        COMportListBoxLabel             matlab.ui.control.Label
        StartLamp                       matlab.ui.control.Lamp
        StopLinkingButton               matlab.ui.control.Button
        StartLinkingButton              matlab.ui.control.Button
        UplinkmessagePanel              matlab.ui.container.Panel
        FlightmodeOFFButton             matlab.ui.control.StateButton
        Tree                            matlab.ui.container.CheckBoxTree
        ChangeMessageNode               matlab.ui.container.TreeNode
        Node2                           matlab.ui.container.TreeNode
        Node3                           matlab.ui.container.TreeNode
        Node4                           matlab.ui.container.TreeNode
        ModifydownlinkmessageButton     matlab.ui.control.Button
        RebootFCButton                  matlab.ui.control.Button
        ResetcoordinateButton           matlab.ui.control.Button
        testEditField                   matlab.ui.control.NumericEditField
        testEditFieldLabel              matlab.ui.control.Label
        CautionLabel                    matlab.ui.control.Label
        PAtempuratureGauge              matlab.ui.control.LinearGauge
        PAtempuratureGaugeLabel         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        RealAccelerationAxes            matlab.ui.control.UIAxes
        VelocityAxes                    matlab.ui.control.UIAxes
        AccelerationAxes                matlab.ui.control.UIAxes
        RateofTurnAxes                  matlab.ui.control.UIAxes
        AttitudeAxes                    matlab.ui.control.UIAxes
        PositionAxes                    matlab.ui.control.UIAxes
        RocketViewAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %% Serial port variables
        % portname=[' '];             %COM3,COM4,...etc.
        % baudrate;                   %57600,125600,...rtc.
        comport
        bitrate
        timeout
        ArduinoObj
        Count
        %data list
        data_list = {'UTCTimeHr', 'UTCTimeMin', 'UTCTimeSec', 'UTCTimeSubsec', 'PackCount', ...
                'IMUTemp', 'Quat0', 'Quat1', 'Quat2', 'Quat3', ...
                'Rollrate', 'Pitchrate', 'Yawrate', 'AccX', 'AccY', 'AccZ', ...
                'RealAccX', 'RealAccY', 'RealAccZ', 'ECEFX', 'ECEFY', 'ECEFZ', ...
                'Long', 'Lat', 'Hei', 'VelX', 'VelY', 'VelZ', ...
                'PATemp', 'IMUStatus', 'LoRaStatus', 'LoRaRSSI', 'LoRaSNR'};

        currentName % when log data to avoid same name

        RollPlot
        PitchPlot
        YawPlot
        PositionPlot
        RollRatePlot
        PitchRatePlot
        YawRatePlot
        VelXPlot
        VelYPlot
        VelZPlot
        AccXPlot
        AccYPlot
        AccZPlot
        RealAccXPlot
        RealAccYPlot
        RealAccZPlot
        %% 
        % CallingApp % Description
        % DialogApp % For fins_design
        % DialogApp2 % For mass_distribution

        %% rocket view
        % flagAXIBOD
        % finNum
        % finShape
        % finPhase
        % X
        % R
        % % Constants
        % FinGeoIndex = struct('IndexXLE',1,'IndexSSPAN',2,'IndexCHORD',3);
        % NoseShape = struct('CONE',0,'OGIVE',1,'POWER',2,'HAACK',3,'KARMAN',4);
        % BodyColor
        % FinColorList
        % 
        % timer=0;
        %% rocket mass & CG property
        %rocket_properties.location(1) = mass,
        %rocket_property.location(2) = CG

%         rocket_properties = struct('Nose',zeros(2,1),'Payload',zeros(2,1), ...
%             'Canard',zeros(2,1),'Avionics',zeros(2,1),'N2O_tank_initial',zeros(2,1), ...
%             'N2O_tank_final',zeros(2,1),'Connection',zeros(2,1),'CC_initial',zeros(2,1), ...
%             'CC_final',zeros(2,1),'Tail',zeros(2,1));
%         rocket_properties = struct('Mass',0,'CG',0,'Inertia',zeros(3));
        %Mass,CG,I

%         rocket_properties = struct('NoseTube',{app.NoseTubeMassEditField.Value});

%             app.NoseTubeCGEditField.Value = 0;
%             app.NoseElementCGEditField.Value = 0;
%             app.Connector1ElementCGEditField.Value = 0;
%             app.AvionicsTubeCGEditField.Value = 0;
%             app.AvionicsElementCGEditField.Value = 0;
%             app.Connector2ElementCGEditField.Value = 0;
%             app.ActuatorTubeCGEditField.Value = 0;
%             app.ActuatorElementCGEditField.Value = 0;
%             app.CanardElementCGEditField.Value = 0;
%             app.Connector3ElementCGEditField.Value = 0;
%             app.RecoveryTubeCGEditField.Value = 0;
%             app.RecoveryElementCGEditField.Value = 0;
%             app.N2OTankCGEditField.Value = 0;
%             app.N2OInitialCGEditField.Value = 0;
%             app.N2OFinalCGEditField.Value = 0;
%             app.CVTubeCGEditField.Value = 0;
%             app.CVElementCGEditField.Value = 0;
%             app.FuelTankCGEditField.Value = 0;
%             app.FuelInitialCGEditField.Value = 0;
%             app.FuelFinalCGEditField.Value = 0;
%             app.TailElementCGEditField.Value = 0;

        % loadstruct 
        % Construct downlink data size
        TTC_data = struct('PackCount_index',0,'PackCount_endindex',0,'PackCount_size',2,...
                          'UTCTimeHr_index',0,'UTCTimeHr_endindex',0,'UTCTimeHr_size',1,...
                          'UTCTimeMin_index',0,'UTCTimeMin_endindex',0,'UTCTimeMin_size',1,...
                          'UTCTimeSec_index',0,'UTCTimeSec_endindex',0,'UTCTimeSec_size',1,...
                          'UTCTimeSubsec_index',0,'UTCTimeSubsec_endindex',0,'UTCTimeSubsec_size',1,...
                          'IMUTemp_index',0,'IMUTemp_endindex',0,'IMUTemp_size',2,...
                          'Quat0_index',0,'Quat0_endindex',0,'Quat0_size',4,...
                          'Quat1_index',0,'Quat1_endindex',0,'Quat1_size',4,...
                          'Quat2_index',0,'Quat2_endindex',0,'Quat2_size',4,...
                          'Quat3_index',0,'Quat3_endindex',0,'Quat3_size',4,...
                          'Rollrate_index',0,'Rollrate_endindex',0,'Rollrate_size',2,...    %rate of turn
                          'Pitchrate_index',0,'Pitchrate_endindex',0,'Pitchrate_size',2,...
                          'Yawrate_index',0,'Yawrate_endindex',0,'Yawrate_size',2,...
                          'AccX_index',0,'AccX_endindex',0,'AccX_size',2,...       %free acceleration
                          'AccY_index',0,'AccY_endindex',0,'AccY_size',2,...
                          'AccZ_index',0,'AccZ_endindex',0,'AccZ_size',2,...
                          'RealAccX_index',0,'RealAccX_endindex',0,'RealAccX_size',2,...
                          'RealAccY_index',0,'RealAccY_endindex',0,'RealAccY_size',2,...
                          'RealAccZ_index',0,'RealAccZ_endindex',0,'RealAccZ_size',2,...
                          'ECEFX_index',0,'ECEFX_endindex',0,'ECEFX_size',2,...
                          'ECEFY_index',0,'ECEFY_endindex',0,'ECEFY_size',2,...
                          'ECEFZ_index',0,'ECEFZ_endindex',0,'ECEFZ_size',2,...
                          'Long_index',0,'Long_endindex',0,'Long_size',4,...      %LLH
                          'Lat_index',0,'Lat_endindex',0,'Lat_size',4,...
                          'Hei_index',0,'Hei_endindex',0,'Hei_size',4,...
                          'VelX_index',0,'VelX_endindex',0,'VelX_size',2,...      %GPS cal velocity
                          'VelY_index',0,'VelY_endindex',0,'VelY_size',2,...
                          'VelZ_index',0,'VelZ_endindex',0,'VelZ_size',2,...
                          'IMUStatus_index',0,'IMUStatus_endindex',0,'IMUStatus_size',2,...
                          'LoRaStatus_index',0,'LoRaStatus_endindex',0,'LoRaStatus_size',2,...
                          'PATemp_index',0,'PATemp_endindex',0,'PATemp_size',2,...
                          'LoRaRSSI_index',0,'LoRaRSSI_endindex',0,'LoRaRSSI_size',2,...
                          'LoRaSNR_index',0,'LoRaSNR_endindex',0,'LoRaSNR_size',2);
        
    end
    
    properties (Access = public)
        % Rocket_ver
        % 
        % 
        % d % diameter of rocket(m)
        % S % Section area of rocket(m^2)
        % g % gravity(m/s^2)
        % ts
        % stage
        % mis_angle
        % Modify_config
        % Time 
        % Position_X
        % Position_Y
        % Position_Z
        % p
        % q
        % r
        % CP
        % CG
        % Stability
        % Roll
        % Pitch
        % Yaw
        % AOA 
        % AOS 
        % Mach
        % Vel
        % Q
        % 
    end

    methods (Access = private)  % Sub-function start from here
        %% initailize function
        function AppVariables(app)
            % app.Rocket_ver = 'ver2023';
        end
        function InitProperties(app)
            %mass
%             app.NoseTubeMassEditField.Value = 0;%app.rocket_properties.Nose(1);
%             app.NoseElementMassEditField.Value = 0;%app.rocket_properties.Payload(1);
%             app.Connector1ElementMassEditField.Value = 0;
%             app.AvionicsTubeMassEditField.Value = 0;
%             app.AvionicsElementMassEditField.Value = 0;%app.rocket_properties.Avionics(1);
%             app.Connector2ElementMassEditField.Value = 0;
%             app.ActuatorTubeMassEditField.Value = 0;
%             app.ActuatorElementMassEditField.Value = 0;
%             app.CanardElementMassEditField.Value = 0;%app.rocket_properties.Canard(1);
%             app.Connector3ElementMassEditField.Value = 0;
%             app.RecoveryTubeMassEditField.Value = 0;
%             app.RecoveryElementMassEditField.Value = 0;
%             app.N2OTankMassEditField.Value = 0;
%             app.N2OInitialMassEditField.Value = 0;%app.rocket_properties.N2O_tank_initial(1);
%             app.N2OFinalMassEditField.Value = 0;%app.rocket_properties.N2O_tank_final(1);
%             app.CVTubeMassEditField.Value = 0;
%             app.CVElementMassEditField.Value = 0;%app.rocket_properties.Connection(1);
%             app.FuelTankMassEditField.Value = 0;%app.rocket_properties.CC_final(1);
%             app.FuelInitialMassEditField.Value = 0;%app.rocket_properties.CC_initial(1);
%             app.FuelFinalMassEditField.Value = 0;%app.rocket_properties.CC_initial(1);
%             app.TailElementMassEditField.Value = 0;%app.rocket_properties.Tail(1);
%             %CG
%             app.NoseTubeCGEditField.Value = 0;
%             app.NoseElementCGEditField.Value = 0;
%             app.Connector1ElementCGEditField.Value = 0;
%             app.AvionicsTubeCGEditField.Value = 0;
%             app.AvionicsElementCGEditField.Value = 0;
%             app.Connector2ElementCGEditField.Value = 0;
%             app.ActuatorTubeCGEditField.Value = 0;
%             app.ActuatorElementCGEditField.Value = 0;
%             app.CanardElementCGEditField.Value = 0;
%             app.Connector3ElementCGEditField.Value = 0;
%             app.RecoveryTubeCGEditField.Value = 0;
%             app.RecoveryElementCGEditField.Value = 0;
%             app.N2OTankCGEditField.Value = 0;
%             app.N2OInitialCGEditField.Value = 0;
%             app.N2OFinalCGEditField.Value = 0;
%             app.CVTubeCGEditField.Value = 0;
%             app.CVElementCGEditField.Value = 0;
%             app.FuelTankCGEditField.Value = 0;
%             app.FuelInitialCGEditField.Value = 0;
%             app.FuelFinalCGEditField.Value = 0;
%             app.TailElementCGEditField.Value = 0;
%             %inertia
%             app.NoseTubeInertiaEditField.Value = 0;
%             app.NoseTubeInertiaEditField_2.Value = 0;
%             app.NoseTubeInertiaEditField_3.Value = 0;
%             app.NoseTubeInertiaEditField_4.Value = 0;
%             app.NoseTubeInertiaEditField_5.Value = 0;
%             app.NoseTubeInertiaEditField_6.Value = 0;
%             app.NoseTubeInertiaEditField_7.Value = 0;
%             app.NoseTubeInertiaEditField_8.Value = 0;
%             app.NoseTubeInertiaEditField_9.Value = 0;
%             app.NoseElementInertiaEditField.Value = 0;
%             app.NoseElementInertiaEditField_2.Value = 0;
%             app.NoseElementInertiaEditField_3.Value = 0;
%             app.NoseElementInertiaEditField_4.Value = 0;
%             app.NoseElementInertiaEditField_5.Value = 0;
%             app.NoseElementInertiaEditField_6.Value = 0;
%             app.NoseElementInertiaEditField_7.Value = 0;
%             app.NoseElementInertiaEditField_8.Value = 0;
%             app.NoseElementInertiaEditField_9.Value = 0;
%             app.Connector1ElementInertiaEditField.Value = 0;
%             app.Connector1ElementInertiaEditField_2.Value = 0;
%             app.Connector1ElementInertiaEditField_3.Value = 0;
%             app.Connector1ElementInertiaEditField_4.Value = 0;
%             app.Connector1ElementInertiaEditField_5.Value = 0;
%             app.Connector1ElementInertiaEditField_6.Value = 0;
%             app.Connector1ElementInertiaEditField_7.Value = 0;
%             app.Connector1ElementInertiaEditField_8.Value = 0;
%             app.Connector1ElementInertiaEditField_9.Value = 0;
%             app.AvionicsTubeInertiaEditField.Value = 0;
%             app.AvionicsTubeInertiaEditField_2.Value = 0;
%             app.AvionicsTubeInertiaEditField_3.Value = 0;
%             app.AvionicsTubeInertiaEditField_4.Value = 0;
%             app.AvionicsTubeInertiaEditField_5.Value = 0;
%             app.AvionicsTubeInertiaEditField_6.Value = 0;
%             app.AvionicsTubeInertiaEditField_7.Value = 0;
%             app.AvionicsTubeInertiaEditField_8.Value = 0;
%             app.AvionicsTubeInertiaEditField_9.Value = 0;
%             app.AvionicsElementInertiaEditField.Value = 0;
%             app.AvionicsElementInertiaEditField_2.Value = 0;
%             app.AvionicsElementInertiaEditField_3.Value = 0;
%             app.AvionicsElementInertiaEditField_4.Value = 0;
%             app.AvionicsElementInertiaEditField_5.Value = 0;
%             app.AvionicsElementInertiaEditField_6.Value = 0;
%             app.AvionicsElementInertiaEditField_7.Value = 0;
%             app.AvionicsElementInertiaEditField_8.Value = 0;
%             app.AvionicsElementInertiaEditField_9.Value = 0;
%             app.Connector2ElementInertiaEditField.Value = 0;
%             app.Connector2ElementInertiaEditField_2.Value = 0;
%             app.Connector2ElementInertiaEditField_3.Value = 0;
%             app.Connector2ElementInertiaEditField_4.Value = 0;
%             app.Connector2ElementInertiaEditField_5.Value = 0;
%             app.Connector2ElementInertiaEditField_6.Value = 0;
%             app.Connector2ElementInertiaEditField_7.Value = 0;
%             app.Connector2ElementInertiaEditField_8.Value = 0;
%             app.Connector2ElementInertiaEditField_9.Value = 0;
%             app.ActuatorTubeInertiaEditField.Value = 0;
%             app.ActuatorTubeInertiaEditField_2.Value = 0;
%             app.ActuatorTubeInertiaEditField_3.Value = 0;
%             app.ActuatorTubeInertiaEditField_4.Value = 0;
%             app.ActuatorTubeInertiaEditField_5.Value = 0;
%             app.ActuatorTubeInertiaEditField_6.Value = 0;
%             app.ActuatorTubeInertiaEditField_7.Value = 0;
%             app.ActuatorTubeInertiaEditField_8.Value = 0;
%             app.ActuatorTubeInertiaEditField_9.Value = 0;
%             app.ActuatorElementInertiaEditField.Value = 0;
%             app.ActuatorElementInertiaEditField_2.Value = 0;
%             app.ActuatorElementInertiaEditField_3.Value = 0;
%             app.ActuatorElementInertiaEditField_4.Value = 0;
%             app.ActuatorElementInertiaEditField_5.Value = 0;
%             app.ActuatorElementInertiaEditField_6.Value = 0;
%             app.ActuatorElementInertiaEditField_7.Value = 0;
%             app.ActuatorElementInertiaEditField_8.Value = 0;
%             app.ActuatorElementInertiaEditField_9.Value = 0;
%             app.CanardElementInertiaEditField.Value = 0;
%             app.CanardElementInertiaEditField_2.Value = 0;
%             app.CanardElementInertiaEditField_3.Value = 0;
%             app.CanardElementInertiaEditField_4.Value = 0;
%             app.CanardElementInertiaEditField_5.Value = 0;
%             app.CanardElementInertiaEditField_6.Value = 0;
%             app.CanardElementInertiaEditField_7.Value = 0;
%             app.CanardElementInertiaEditField_8.Value = 0;
%             app.CanardElementInertiaEditField_9.Value = 0;
%             app.Connector3ElementInertiaEditField.Value = 0;
%             app.Connector3ElementInertiaEditField_2.Value = 0;
%             app.Connector3ElementInertiaEditField_3.Value = 0;
%             app.Connector3ElementInertiaEditField_4.Value = 0;
%             app.Connector3ElementInertiaEditField_5.Value = 0;
%             app.Connector3ElementInertiaEditField_6.Value = 0;
%             app.Connector3ElementInertiaEditField_7.Value = 0;
%             app.Connector3ElementInertiaEditField_8.Value = 0;
%             app.Connector3ElementInertiaEditField_9.Value = 0;
%             app.RecoveryTubeInertiaEditField.Value = 0;
%             app.RecoveryTubeInertiaEditField_2.Value = 0;
%             app.RecoveryTubeInertiaEditField_3.Value = 0;
%             app.RecoveryTubeInertiaEditField_4.Value = 0;
%             app.RecoveryTubeInertiaEditField_5.Value = 0;
%             app.RecoveryTubeInertiaEditField_6.Value = 0;
%             app.RecoveryTubeInertiaEditField_7.Value = 0;
%             app.RecoveryTubeInertiaEditField_8.Value = 0;
%             app.RecoveryTubeInertiaEditField_9.Value = 0;
%             app.RecoveryElementInertiaEditField.Value = 0;
%             app.RecoveryElementInertiaEditField_2.Value = 0;
%             app.RecoveryElementInertiaEditField_3.Value = 0;
%             app.RecoveryElementInertiaEditField_4.Value = 0;
%             app.RecoveryElementInertiaEditField_5.Value = 0;
%             app.RecoveryElementInertiaEditField_6.Value = 0;
%             app.RecoveryElementInertiaEditField_7.Value = 0;
%             app.RecoveryElementInertiaEditField_8.Value = 0;
%             app.RecoveryElementInertiaEditField_9.Value = 0;
%             app.N2OTankInertiaEditField.Value = 0;
%             app.N2OTankInertiaEditField_2.Value = 0;
%             app.N2OTankInertiaEditField_3.Value = 0;
%             app.N2OTankInertiaEditField_4.Value = 0;
%             app.N2OTankInertiaEditField_5.Value = 0;
%             app.N2OTankInertiaEditField_6.Value = 0;
%             app.N2OTankInertiaEditField_7.Value = 0;
%             app.N2OTankInertiaEditField_8.Value = 0;
%             app.N2OTankInertiaEditField_9.Value = 0;
%             app.N2OInitialInertiaEditField.Value = 0;
%             app.N2OInitialInertiaEditField_2.Value = 0;
%             app.N2OInitialInertiaEditField_3.Value = 0;
%             app.N2OInitialInertiaEditField_4.Value = 0;
%             app.N2OInitialInertiaEditField_5.Value = 0;
%             app.N2OInitialInertiaEditField_6.Value = 0;
%             app.N2OInitialInertiaEditField_7.Value = 0;
%             app.N2OInitialInertiaEditField_8.Value = 0;
%             app.N2OInitialInertiaEditField_9.Value = 0;
%             app.N2OFinalInertiaEditField.Value = 0;
%             app.N2OFinalInertiaEditField_2.Value = 0;
%             app.N2OFinalInertiaEditField_3.Value = 0;
%             app.N2OFinalInertiaEditField_4.Value = 0;
%             app.N2OFinalInertiaEditField_5.Value = 0;
%             app.N2OFinalInertiaEditField_6.Value = 0;
%             app.N2OFinalInertiaEditField_7.Value = 0;
%             app.N2OFinalInertiaEditField_8.Value = 0;
%             app.N2OFinalInertiaEditField_9.Value = 0;
%             app.CVTubeInertiaEditField.Value = 0;
%             app.CVTubeInertiaEditField_2.Value = 0;
%             app.CVTubeInertiaEditField_3.Value = 0;
%             app.CVTubeInertiaEditField_4.Value = 0;
%             app.CVTubeInertiaEditField_5.Value = 0;
%             app.CVTubeInertiaEditField_6.Value = 0;
%             app.CVTubeInertiaEditField_7.Value = 0;
%             app.CVTubeInertiaEditField_8.Value = 0;
%             app.CVTubeInertiaEditField_9.Value = 0;
%             app.CVElementInertiaEditField.Value = 0;
%             app.CVElementInertiaEditField_2.Value = 0;
%             app.CVElementInertiaEditField_3.Value = 0;
%             app.CVElementInertiaEditField_4.Value = 0;
%             app.CVElementInertiaEditField_5.Value = 0;
%             app.CVElementInertiaEditField_6.Value = 0;
%             app.CVElementInertiaEditField_7.Value = 0;
%             app.CVElementInertiaEditField_8.Value = 0;
%             app.CVElementInertiaEditField_9.Value = 0;
%             app.FuelTankInertiaEditField.Value = 0;
%             app.FuelTankInertiaEditField_2.Value = 0;
%             app.FuelTankInertiaEditField_3.Value = 0;
%             app.FuelTankInertiaEditField_4.Value = 0;
%             app.FuelTankInertiaEditField_5.Value = 0;
%             app.FuelTankInertiaEditField_6.Value = 0;
%             app.FuelTankInertiaEditField_7.Value = 0;
%             app.FuelTankInertiaEditField_8.Value = 0;
%             app.FuelTankInertiaEditField_9.Value = 0;
%             app.FuelInitialInertiaEditField.Value = 0;
%             app.FuelInitialInertiaEditField_2.Value = 0;
%             app.FuelInitialInertiaEditField_3.Value = 0;
%             app.FuelInitialInertiaEditField_4.Value = 0;
%             app.FuelInitialInertiaEditField_5.Value = 0;
%             app.FuelInitialInertiaEditField_6.Value = 0;
%             app.FuelInitialInertiaEditField_7.Value = 0;
%             app.FuelInitialInertiaEditField_8.Value = 0;
%             app.FuelInitialInertiaEditField_9.Value = 0;
%             app.FuelFinalInertiaEditField.Value = 0;
%             app.FuelFinalInertiaEditField_2.Value = 0;
%             app.FuelFinalInertiaEditField_3.Value = 0;
%             app.FuelFinalInertiaEditField_4.Value = 0;
%             app.FuelFinalInertiaEditField_5.Value = 0;
%             app.FuelFinalInertiaEditField_6.Value = 0;
%             app.FuelFinalInertiaEditField_7.Value = 0;
%             app.FuelFinalInertiaEditField_8.Value = 0;
%             app.FuelFinalInertiaEditField_9.Value = 0;
%             app.TailElementInertiaEditField.Value = 0;
%             app.TailElementInertiaEditField_2.Value = 0;
%             app.TailElementInertiaEditField_3.Value = 0;
%             app.TailElementInertiaEditField_4.Value = 0;
%             app.TailElementInertiaEditField_5.Value = 0;
%             app.TailElementInertiaEditField_6.Value = 0;
%             app.TailElementInertiaEditField_7.Value = 0;
%             app.TailElementInertiaEditField_8.Value = 0;
%             app.TailElementInertiaEditField_9.Value = 0;
        end

        %% change run 6dof button
        function toggleUIC(app, state, label)
            % toggle the state of the uicontrols in the app
            uics = findall(app.UIFigure);
            for idx = 1:length(uics)
               uic = uics(idx);
               if isprop(uic,'Enable')
                   uic.Enable = state;
               end
            end
            % update the simulate button text
            app.Run6DOFButton.Text = label;
            drawnow; % flush the ui events queue
        end % toggleUIC

        %% Rocket view sub-function
        function rocketView(app)
            shortDescription = 'Asfaloth(ver1)'; % latex format
            inputFileName = './datcom/analysis_fingeometry/Miss5.dat';

            %% Init
            % Variables
            app.flagAXIBOD = 0; % Reading flag of AXIBOD
            app.finNum = 0; % Number after $FINSET
            app.finShape = zeros(100, 3);
            app.finPhase = [];
            app.X = [];
            app.R = [];

            % Constants
            app.BodyColor = [250 172 88]/255; % Light orange
            app.FinColorList = [0.6 0.8 0.7 0.9 0.65 0.85 0.75]; % 7 types of gray


            %% Read file
            lines = readlines(inputFileName);
            for i = 1:length(lines)
                ctext = lines(i);

                % Read Body, X-R type
                if contains(ctext, ' X=') || contains(ctext, ' X(')
                    app.X = [app.X; app.splitStandardForm(ctext)];
                end
                if contains(ctext, ' R=') || contains(ctext, ' R(')
                    app.R = [app.R; app.splitStandardForm(ctext)];
                end
                % Read Body, NOSE-CENTR-AFT type
                if contains(ctext, '$AXIBOD'), app.flagAXIBOD = 1; end
                if contains(ctext, ' TNOSE='),  tnose = app.splitStandardForm(ctext); end
                if contains(ctext, ' LNOSE='),  lnose = app.splitStandardForm(ctext); end
                if contains(ctext, ' DNOSE='),  dnose = app.splitStandardForm(ctext); end
                if contains(ctext, ' POWER='),  pnose = app.splitStandardForm(ctext); end
                if contains(ctext, ' LCENTR='), lbody = app.splitStandardForm(ctext); end
                if contains(ctext, ' DCENTR='), dbody = app.splitStandardForm(ctext); end
                if contains(ctext, ' TAFT='),   taft = app.splitStandardForm(ctext); end
                if contains(ctext, ' LAFT='),   laft = app.splitStandardForm(ctext); end
                if contains(ctext, ' DAFT='),   daft = app.splitStandardForm(ctext); end
                % Body data points for N-C-A type
                if contains(ctext, '$END') && app.flagAXIBOD == 1 && exist('lnose', 'var')
                    app.flagAXIBOD = 0;
                    % Default type
                    if ~exist('tnose', 'var'), tnose = app.NoseShape.OGIVE; end
                    if ~exist('taft', 'var'), taft = app.NoseShape.CONE; end
                    % Nosecone part
                    app.X = 0:lnose/50:lnose;
                    app.X = app.X';
                    switch tnose
                        case app.NoseShape.CONE % Conical
                            app.R = dnose/2 / lnose * app.X;
                        case app.NoseShape.OGIVE % Tangent ogive
                            rho = ((dnose/2)^2 + lnose^2) / dnose;
                            r0 = sqrt(rho^2 - lnose^2);
                            app.R = sqrt(rho^2 - (lnose-app.X).^2) - r0;
                        case app.NoseShape.POWER % Power law
                            app.R = dnose/2 * (app.X/lnose).^pnose;
                        case app.NoseShape.HAACK % HAACK L-V (C=1/3)
                            theta = acos(1-2*app.X/lnose);
                            app.R = dnose/2/sqrt(pi) * ...
                                sqrt(theta - sin(2*theta)/2 + 1/3*(sin(theta)).^3);
                        case app.NoseShape.KARMAN % KARMAN L-D (C=0)
                            theta = acos(1-2*app.X/lnose);
                            app.R = dnose/2/sqrt(pi) * ...
                                sqrt(theta - sin(2*theta)/2);
                    end
                    % Body part
                    app.X = [app.X; app.X(end)+lbody];
                    app.R = [app.R; dbody/2];
                    % After part
                    if exist('laft', 'var')
                        Xaft = 0:laft/20:laft;
                        r0 = (dbody - daft) / 2;
                        switch taft
                            case app.NoseShape.CONE % Conical
                                Raft = r0 - r0 * Xaft / laft + daft/2;
                            case app.NoseShape.OGIVE % Tangent ogive
                                rho = (r0^2 + laft^2) / (r0*2);
                                r0 = sqrt(rho^2 - laft^2);
                                Raft = sqrt(rho^2 - Xaft.^2) - r0 + daft/2;
                        end
                        app.X = [app.X; Xaft(:)+max(app.X)];
                        app.R = [app.R; Raft(:)];
                    end
                end

                % Fin sets
                if contains(ctext, '$FINSET')
                    temp = regexp(ctext,'\d*','Match');
                    app.finNum = str2double(temp(1));
                end
                if contains(ctext, ' XLE=')
                    temp = app.splitStandardForm(ctext);
                    app.finShape(1:length(temp), app.FinGeoIndex.IndexXLE, app.finNum) = temp;
                end
                if contains(ctext, ' SSPAN=')
                    temp = app.splitStandardForm(ctext);
                    app.finShape(1:length(temp), app.FinGeoIndex.IndexSSPAN, app.finNum) = temp;
                end
                if contains(ctext, ' CHORD=')
                    temp = app.splitStandardForm(ctext);
                    app.finShape(1:length(temp), app.FinGeoIndex.IndexCHORD, app.finNum) = temp;
                end
                if contains(ctext, ' PHIF=')
                    app.finPhase(:, app.finNum) = NaN(10, 1);
                    temp = app.splitStandardForm(ctext);
                    app.finPhase(1:length(temp), app.finNum) = temp;
                end
            end
            %% Plot
            %             fig = figure('units','normalized','outerposition',[0 0 1 1]);
            % Body
            app.X = [app.X; flip(app.X)];
            app.R = [app.R; -flip(app.R)];
            fillBody = fill(app.RocketViewAxes,app.X, app.R, app.BodyColor);
            fillBody.EdgeColor = app.BodyColor;
            hold(app.RocketViewAxes, 'on' )

            % Fins
            for i = 1:app.finNum
                cfin = app.finShape(:, :, i);
                cfin = cfin(cfin(:, app.FinGeoIndex.IndexXLE)>0, :);
                finColor = [1 1 1] * app.FinColorList(rem(i, length(app.FinColorList)));
                % Fin data points
                x = [cfin(:, app.FinGeoIndex.IndexXLE); flip(cfin(:, app.FinGeoIndex.IndexXLE)+cfin(:, app.FinGeoIndex.IndexCHORD))];
                r = [cfin(:, app.FinGeoIndex.IndexSSPAN); flip(cfin(:, app.FinGeoIndex.IndexSSPAN))];
                % Rotate and place
                for j = 1:length(app.finPhase)
                    cphase = app.finPhase(j, i);
                    if cphase <= 180
                        fill(app.RocketViewAxes,x, r*cosd(cphase), finColor);
                    end
                end
                % Text notes
                finSpan = max(r) - min(r);
                finQty = sum(~isnan(app.finPhase(:, i)));
                minPhase = min(app.finPhase(:, i));
                str = append('\leftarrow', ...
                    '#', num2str(i), ...
                    ' span ', num2str(max(r)-min(r)), 'cm', ...
                    '\times', num2str(finQty), ' , ', ...
                    num2str(minPhase), '^{\circ} ');
                indexArrow = size(cfin, 1) + 1;
                text(x(indexArrow), r(indexArrow)*cosd(minPhase), str,FontSize=15);
            end
            %             axes(app.RocketViewAxes,'equal')
            app.RocketViewAxes.DataAspectRatio = [1 1 1];
            % Formats
            %             alpha(0.8);
            %             grid on;
            %             % Plot limits
            %             set(app.RocketViewAxes,'axes','equal tight')
            %             xlim(xlim*1.1);
            %             ylim(ylim*1.2);
            %             % Title
            %             xlabel("unit: cm");
            %             title(shortDescription);
            %% Save to files
            %             filename = app.replaceSymbol(shortDescription);
            %             copyfile(inputFileName, append(filename, '.dat'));
            %             saveas(fig, append(filename, '.png'));
        end

        function out = splitStandardForm(app,in)
            temp = split(in, '=');
            temp = split(temp(2), '    ');
            temp = str2double(split(temp(1), ','));
            out = temp(~isnan(temp));
        end

        function out = replaceSymbol(app,in)
            numIndex = in>=0x30 & in<=0x39;
            letterIndex = isletter(in);
            out = in;
            out(~(numIndex | letterIndex)) = '_';
        end

        %% Thrust-Time Curve Plot sub-function
        function Thrust_Time_Plot(app)
        % cla(app.ThrustTimeAxes)    
        % hold(app.ThrustTimeAxes,'on')
        % plot(app.ThrustTimeAxes, linspace(app.T0EditField.Value,app.T1EditField.Value), linspace(app.T0EditField_2.Value,app.T1EditField_2.Value));
        % plot(app.ThrustTimeAxes, linspace(app.T1EditField.Value,app.T2EditField.Value), linspace(app.T1EditField_2.Value,app.T2EditField_2.Value));
        % plot(app.ThrustTimeAxes, linspace(app.T2EditField.Value,app.T3EditField.Value), linspace(app.T2EditField_2.Value,app.T3EditField_2.Value));
        % plot(app.ThrustTimeAxes, linspace(app.T3EditField.Value,app.T4EditField.Value), linspace(app.T3EditField_2.Value,app.T4EditField_2.Value));
        % hold(app.ThrustTimeAxes,'off')
        end

        %% Save rocket properties sub-function
        function SaveRocketModel(app,ver)
            % str = "Config_"+ver+".mat";
            % [Filename,FilePosition] = uiputfile(str);
            % if (Filename~=0)
            %     Nose = struct('NoseTubeM',app.NoseTubeMassEditField.Value,'NoseTubeCG',app.NoseTubeCGEditField.Value,'NoseEleM',app.NoseElementMassEditField.Value,'NoseEleCG',app.NoseElementCGEditField.Value,'NoseTubeI',{app.NoseTubeInertiaEditField.Value,app.NoseTubeInertiaEditField_2.Value,app.NoseTubeInertiaEditField_3.Value,app.NoseTubeInertiaEditField_4.Value,app.NoseTubeInertiaEditField_5.Value,app.NoseTubeInertiaEditField_6.Value,app.NoseTubeInertiaEditField_7.Value,app.NoseTubeInertiaEditField_8.Value,app.NoseTubeInertiaEditField_9.Value},'NoseEleI',{app.NoseElementInertiaEditField.Value,app.NoseElementInertiaEditField_2.Value,app.NoseElementInertiaEditField_3.Value,app.NoseElementInertiaEditField_4.Value,app.NoseElementInertiaEditField_5.Value,app.NoseElementInertiaEditField_6.Value,app.NoseElementInertiaEditField_7.Value,app.NoseElementInertiaEditField_8.Value,app.NoseElementInertiaEditField_9.Value});
            %     Connector1 = struct('Connector1EleM',app.Connector1ElementMassEditField.Value,'Connector1EleCG',app.Connector1ElementCGEditField.Value,'Connector1EleI',{app.Connector1ElementInertiaEditField.Value,app.Connector1ElementInertiaEditField_2.Value,app.Connector1ElementInertiaEditField_3.Value,app.Connector1ElementInertiaEditField_4.Value,app.Connector1ElementInertiaEditField_5.Value,app.Connector1ElementInertiaEditField_6.Value,app.Connector1ElementInertiaEditField_7.Value,app.Connector1ElementInertiaEditField_8.Value,app.Connector1ElementInertiaEditField_9.Value});
            %     Avionics = struct('AvionicsTubeM',app.AvionicsTubeMassEditField.Value,'AvionicsTubeCG',app.AvionicsTubeCGEditField.Value,'AvionicsEleM',app.AvionicsElementMassEditField.Value,'AvionicsEleCG',app.AvionicsElementCGEditField.Value,'AvionicsTubeI',{app.AvionicsTubeInertiaEditField.Value,app.AvionicsTubeInertiaEditField_2.Value,app.AvionicsTubeInertiaEditField_3.Value,app.AvionicsTubeInertiaEditField_4.Value,app.AvionicsTubeInertiaEditField_5.Value,app.AvionicsTubeInertiaEditField_6.Value,app.AvionicsTubeInertiaEditField_7.Value,app.AvionicsTubeInertiaEditField_8.Value,app.AvionicsTubeInertiaEditField_9.Value},'AvionicsEleI',{app.AvionicsElementInertiaEditField.Value,app.AvionicsElementInertiaEditField_2.Value,app.AvionicsElementInertiaEditField_3.Value,app.AvionicsElementInertiaEditField_4.Value,app.AvionicsElementInertiaEditField_5.Value,app.AvionicsElementInertiaEditField_6.Value,app.AvionicsElementInertiaEditField_7.Value,app.AvionicsElementInertiaEditField_8.Value,app.AvionicsElementInertiaEditField_9.Value});
            %     Connector2 = struct('Connector2EleM',app.Connector2ElementMassEditField.Value,'Connector2EleCG',app.Connector2ElementCGEditField.Value,'Connector2EleI',{app.Connector2ElementInertiaEditField.Value,app.Connector2ElementInertiaEditField_2.Value,app.Connector2ElementInertiaEditField_3.Value,app.Connector2ElementInertiaEditField_4.Value,app.Connector2ElementInertiaEditField_5.Value,app.Connector2ElementInertiaEditField_6.Value,app.Connector2ElementInertiaEditField_7.Value,app.Connector2ElementInertiaEditField_8.Value,app.Connector2ElementInertiaEditField_9.Value});
            %     Recovery = struct('RecoveryTubeM',app.RecoveryTubeMassEditField.Value,'RecoveryTubeCG',app.RecoveryTubeCGEditField.Value,'RecoveryEleM',app.RecoveryElementMassEditField.Value,'RecoveryEleCG',app.RecoveryElementCGEditField.Value,'RecoveryTubeI',{app.RecoveryTubeInertiaEditField.Value,app.RecoveryTubeInertiaEditField_2.Value,app.RecoveryTubeInertiaEditField_3.Value,app.RecoveryTubeInertiaEditField_4.Value,app.RecoveryTubeInertiaEditField_5.Value,app.RecoveryTubeInertiaEditField_6.Value,app.RecoveryTubeInertiaEditField_7.Value,app.RecoveryTubeInertiaEditField_8.Value,app.RecoveryTubeInertiaEditField_9.Value},'RecoveryEleI',{app.RecoveryElementInertiaEditField.Value,app.RecoveryElementInertiaEditField_2.Value,app.RecoveryElementInertiaEditField_3.Value,app.RecoveryElementInertiaEditField_4.Value,app.RecoveryElementInertiaEditField_5.Value,app.RecoveryElementInertiaEditField_6.Value,app.RecoveryElementInertiaEditField_7.Value,app.RecoveryElementInertiaEditField_8.Value,app.RecoveryElementInertiaEditField_9.Value});
            %     Connector3 = struct('Connector3EleM',app.Connector3ElementMassEditField.Value,'Connector3EleCG',app.Connector3ElementCGEditField.Value,'Connector3EleI',{app.Connector3ElementInertiaEditField.Value,app.Connector3ElementInertiaEditField_2.Value,app.Connector3ElementInertiaEditField_3.Value,app.Connector3ElementInertiaEditField_4.Value,app.Connector3ElementInertiaEditField_5.Value,app.Connector3ElementInertiaEditField_6.Value,app.Connector3ElementInertiaEditField_7.Value,app.Connector3ElementInertiaEditField_8.Value,app.Connector3ElementInertiaEditField_9.Value});
            %     Actuator = struct('ActuatorTubeM',app.ActuatorTubeMassEditField.Value,'ActuatorTubeCG',app.ActuatorTubeCGEditField.Value,'ActuatorEleM',app.ActuatorElementMassEditField.Value,'ActuatorEleCG',app.ActuatorElementCGEditField.Value,'ActuatorTubeI',{app.ActuatorTubeInertiaEditField.Value,app.ActuatorTubeInertiaEditField_2.Value,app.ActuatorTubeInertiaEditField_3.Value,app.ActuatorTubeInertiaEditField_4.Value,app.ActuatorTubeInertiaEditField_5.Value,app.ActuatorTubeInertiaEditField_6.Value,app.ActuatorTubeInertiaEditField_7.Value,app.ActuatorTubeInertiaEditField_8.Value,app.ActuatorTubeInertiaEditField_9.Value},'ActuatorEleI',{app.ActuatorElementInertiaEditField.Value,app.ActuatorElementInertiaEditField_2.Value,app.ActuatorElementInertiaEditField_3.Value,app.ActuatorElementInertiaEditField_4.Value,app.ActuatorElementInertiaEditField_5.Value,app.ActuatorElementInertiaEditField_6.Value,app.ActuatorElementInertiaEditField_7.Value,app.ActuatorElementInertiaEditField_8.Value,app.ActuatorElementInertiaEditField_9.Value});
            %     Canard = struct('CanardEleM',app.CanardElementMassEditField.Value,'CanardEleCG',app.CanardElementCGEditField.Value,'CanardEleI',{app.CanardElementInertiaEditField.Value,app.CanardElementInertiaEditField_2.Value,app.CanardElementInertiaEditField_3.Value,app.CanardElementInertiaEditField_4.Value,app.CanardElementInertiaEditField_5.Value,app.CanardElementInertiaEditField_6.Value,app.CanardElementInertiaEditField_7.Value,app.CanardElementInertiaEditField_8.Value,app.CanardElementInertiaEditField_9.Value});
            %     N2O = struct('N2OTankM',app.N2OTankMassEditField.Value,'N2OTankCG',app.N2OTankCGEditField.Value,'N2OInitM',app.N2OInitialMassEditField.Value,'N2OEleCG',app.N2OInitialCGEditField.Value,'N2OFinM',app.N2OFinalMassEditField.Value,'N2OFinCG',app.N2OFinalCGEditField.Value,'N2OTankI',{app.N2OTankInertiaEditField.Value,app.N2OTankInertiaEditField_2.Value,app.N2OTankInertiaEditField_3.Value,app.N2OTankInertiaEditField_4.Value,app.N2OTankInertiaEditField_5.Value,app.N2OTankInertiaEditField_6.Value,app.N2OTankInertiaEditField_7.Value,app.N2OTankInertiaEditField_8.Value,app.N2OTankInertiaEditField_9.Value},'N2OInitI',{app.N2OInitialInertiaEditField.Value,app.N2OInitialInertiaEditField_2.Value,app.N2OInitialInertiaEditField_3.Value,app.N2OInitialInertiaEditField_4.Value,app.N2OInitialInertiaEditField_5.Value,app.N2OInitialInertiaEditField_6.Value,app.N2OInitialInertiaEditField_7.Value,app.N2OInitialInertiaEditField_8.Value,app.N2OInitialInertiaEditField_9.Value},'N2OFinI',{app.N2OFinalInertiaEditField.Value,app.N2OFinalInertiaEditField_2.Value,app.N2OFinalInertiaEditField_3.Value,app.N2OFinalInertiaEditField_4.Value,app.N2OFinalInertiaEditField_5.Value,app.N2OFinalInertiaEditField_6.Value,app.N2OFinalInertiaEditField_7.Value,app.N2OFinalInertiaEditField_8.Value,app.N2OFinalInertiaEditField_9.Value});
            %     CV = struct('CVTubeM',app.CVTubeMassEditField.Value,'CVTubeCG',app.CVTubeCGEditField.Value,'CVEleM',app.CVElementMassEditField.Value,'CVEleCG',app.CVElementCGEditField.Value,'CVTubeI',{app.CVTubeInertiaEditField.Value,app.CVTubeInertiaEditField_2.Value,app.CVTubeInertiaEditField_3.Value,app.CVTubeInertiaEditField_4.Value,app.CVTubeInertiaEditField_5.Value,app.CVTubeInertiaEditField_6.Value,app.CVTubeInertiaEditField_7.Value,app.CVTubeInertiaEditField_8.Value,app.CVTubeInertiaEditField_9.Value},'CVEleI',{app.CVElementInertiaEditField.Value,app.CVElementInertiaEditField_2.Value,app.CVElementInertiaEditField_3.Value,app.CVElementInertiaEditField_4.Value,app.CVElementInertiaEditField_5.Value,app.CVElementInertiaEditField_6.Value,app.CVElementInertiaEditField_7.Value,app.CVElementInertiaEditField_8.Value,app.CVElementInertiaEditField_9.Value});
            %     Fuel = struct('FuelTankM',app.FuelTankMassEditField.Value,'FuelTankCG',app.FuelTankCGEditField.Value,'FuelInitM',app.FuelInitialMassEditField.Value,'FuelEleCG',app.FuelInitialCGEditField.Value,'FuelFinM',app.FuelFinalMassEditField.Value,'FuelFinCG',app.FuelFinalCGEditField.Value,'FuelTankI',{app.FuelTankInertiaEditField.Value,app.FuelTankInertiaEditField_2.Value,app.FuelTankInertiaEditField_3.Value,app.FuelTankInertiaEditField_4.Value,app.FuelTankInertiaEditField_5.Value,app.FuelTankInertiaEditField_6.Value,app.FuelTankInertiaEditField_7.Value,app.FuelTankInertiaEditField_8.Value,app.FuelTankInertiaEditField_9.Value},'FuelInitI',{app.FuelInitialInertiaEditField.Value,app.FuelInitialInertiaEditField_2.Value,app.FuelInitialInertiaEditField_3.Value,app.FuelInitialInertiaEditField_4.Value,app.FuelInitialInertiaEditField_5.Value,app.FuelInitialInertiaEditField_6.Value,app.FuelInitialInertiaEditField_7.Value,app.FuelInitialInertiaEditField_8.Value,app.FuelInitialInertiaEditField_9.Value},'FuelFinI',{app.FuelFinalInertiaEditField.Value,app.FuelFinalInertiaEditField_2.Value,app.FuelFinalInertiaEditField_3.Value,app.FuelFinalInertiaEditField_4.Value,app.FuelFinalInertiaEditField_5.Value,app.FuelFinalInertiaEditField_6.Value,app.FuelFinalInertiaEditField_7.Value,app.FuelFinalInertiaEditField_8.Value,app.FuelFinalInertiaEditField_9.Value});
            %     Tail = struct('TailEleM',app.TailElementMassEditField.Value,'TailEleCG',app.TailElementCGEditField.Value,'TailEleI',{app.TailElementInertiaEditField.Value,app.TailElementInertiaEditField_2.Value,app.TailElementInertiaEditField_3.Value,app.TailElementInertiaEditField_4.Value,app.TailElementInertiaEditField_5.Value,app.TailElementInertiaEditField_6.Value,app.TailElementInertiaEditField_7.Value,app.TailElementInertiaEditField_8.Value,app.TailElementInertiaEditField_9.Value});
            %     save(fullfile(FilePosition,Filename),'Nose','Connector1','Avionics','Connector2','Recovery','Connector3','Actuator','Canard','N2O','CV','Fuel','Tail');
            % end
        end

        %% Load rocket properties sub-function
        function LoadRocketModel(app,FileName)
            load(FileName)
%             app.Actuator.ActuatorEleCG
%             app.NoseTubeCGEditField.Value = evalin('base','Nose(1)')
            %mass
%             app.NoseTubeMassEditField.Value = 0;%app.rocket_properties.Nose(1);
%             app.NoseElementMassEditField.Value = 0;%app.rocket_properties.Payload(1);
%             app.Connector1ElementMassEditField.Value = 0;
%             app.AvionicsTubeMassEditField.Value = 0;
%             app.AvionicsElementMassEditField.Value = 0;%app.rocket_properties.Avionics(1);
%             app.Connector2ElementMassEditField.Value = 0;
%             app.ActuatorTubeMassEditField.Value = 0;
%             app.ActuatorElementMassEditField.Value = 0;
%             app.CanardElementMassEditField.Value = 0;%app.rocket_properties.Canard(1);
%             app.Connector3ElementMassEditField.Value = 0;
%             app.RecoveryTubeMassEditField.Value = 0;
%             app.RecoveryElementMassEditField.Value = 0;
%             app.N2OTankMassEditField.Value = 0;
%             app.N2OInitialMassEditField.Value = 0;%app.rocket_properties.N2O_tank_initial(1);
%             app.N2OFinalMassEditField.Value = 0;%app.rocket_properties.N2O_tank_final(1);
%             app.CVTubeMassEditField.Value = 0;
%             app.CVElementMassEditField.Value = 0;%app.rocket_properties.Connection(1);
%             app.FuelTankMassEditField.Value = 0;%app.rocket_properties.CC_final(1);
%             app.FuelInitialMassEditField.Value = 0;%app.rocket_properties.CC_initial(1);
%             app.FuelFinalMassEditField.Value = 0;%app.rocket_properties.CC_initial(1);
%             app.TailElementMassEditField.Value = 0;%app.rocket_properties.Tail(1);
%             %CG
%             app.NoseTubeCGEditField.Value = 0;
%             app.NoseElementCGEditField.Value = 0;
%             app.Connector1ElementCGEditField.Value = 0;
%             app.AvionicsTubeCGEditField.Value = 0;
%             app.AvionicsElementCGEditField.Value = 0;
%             app.Connector2ElementCGEditField.Value = 0;
%             app.ActuatorTubeCGEditField.Value = 0;
%             app.ActuatorElementCGEditField.Value = 0;
%             app.CanardElementCGEditField.Value = 0;
%             app.Connector3ElementCGEditField.Value = 0;
%             app.RecoveryTubeCGEditField.Value = 0;
%             app.RecoveryElementCGEditField.Value = 0;
%             app.N2OTankCGEditField.Value = 0;
%             app.N2OInitialCGEditField.Value = 0;
%             app.N2OFinalCGEditField.Value = 0;
%             app.CVTubeCGEditField.Value = 0;
%             app.CVElementCGEditField.Value = 0;
%             app.FuelTankCGEditField.Value = 0;
%             app.FuelInitialCGEditField.Value = 0;
%             app.FuelFinalCGEditField.Value = 0;
%             app.TailElementCGEditField.Value = 0;
%             %inertia
%             app.NoseTubeInertiaEditField.Value = 0;
%             app.NoseTubeInertiaEditField_2.Value = 0;
%             app.NoseTubeInertiaEditField_3.Value = 0;
%             app.NoseTubeInertiaEditField_4.Value = 0;
%             app.NoseTubeInertiaEditField_5.Value = 0;
%             app.NoseTubeInertiaEditField_6.Value = 0;
%             app.NoseTubeInertiaEditField_7.Value = 0;
%             app.NoseTubeInertiaEditField_8.Value = 0;
%             app.NoseTubeInertiaEditField_9.Value = 0;
%             app.NoseElementInertiaEditField.Value = 0;
%             app.NoseElementInertiaEditField_2.Value = 0;
%             app.NoseElementInertiaEditField_3.Value = 0;
%             app.NoseElementInertiaEditField_4.Value = 0;
%             app.NoseElementInertiaEditField_5.Value = 0;
%             app.NoseElementInertiaEditField_6.Value = 0;
%             app.NoseElementInertiaEditField_7.Value = 0;
%             app.NoseElementInertiaEditField_8.Value = 0;
%             app.NoseElementInertiaEditField_9.Value = 0;
%             app.Connector1ElementInertiaEditField.Value = 0;
%             app.Connector1ElementInertiaEditField_2.Value = 0;
%             app.Connector1ElementInertiaEditField_3.Value = 0;
%             app.Connector1ElementInertiaEditField_4.Value = 0;
%             app.Connector1ElementInertiaEditField_5.Value = 0;
%             app.Connector1ElementInertiaEditField_6.Value = 0;
%             app.Connector1ElementInertiaEditField_7.Value = 0;
%             app.Connector1ElementInertiaEditField_8.Value = 0;
%             app.Connector1ElementInertiaEditField_9.Value = 0;
%             app.AvionicsTubeInertiaEditField.Value = 0;
%             app.AvionicsTubeInertiaEditField_2.Value = 0;
%             app.AvionicsTubeInertiaEditField_3.Value = 0;
%             app.AvionicsTubeInertiaEditField_4.Value = 0;
%             app.AvionicsTubeInertiaEditField_5.Value = 0;
%             app.AvionicsTubeInertiaEditField_6.Value = 0;
%             app.AvionicsTubeInertiaEditField_7.Value = 0;
%             app.AvionicsTubeInertiaEditField_8.Value = 0;
%             app.AvionicsTubeInertiaEditField_9.Value = 0;
%             app.AvionicsElementInertiaEditField.Value = 0;
%             app.AvionicsElementInertiaEditField_2.Value = 0;
%             app.AvionicsElementInertiaEditField_3.Value = 0;
%             app.AvionicsElementInertiaEditField_4.Value = 0;
%             app.AvionicsElementInertiaEditField_5.Value = 0;
%             app.AvionicsElementInertiaEditField_6.Value = 0;
%             app.AvionicsElementInertiaEditField_7.Value = 0;
%             app.AvionicsElementInertiaEditField_8.Value = 0;
%             app.AvionicsElementInertiaEditField_9.Value = 0;
%             app.Connector2ElementInertiaEditField.Value = 0;
%             app.Connector2ElementInertiaEditField_2.Value = 0;
%             app.Connector2ElementInertiaEditField_3.Value = 0;
%             app.Connector2ElementInertiaEditField_4.Value = 0;
%             app.Connector2ElementInertiaEditField_5.Value = 0;
%             app.Connector2ElementInertiaEditField_6.Value = 0;
%             app.Connector2ElementInertiaEditField_7.Value = 0;
%             app.Connector2ElementInertiaEditField_8.Value = 0;
%             app.Connector2ElementInertiaEditField_9.Value = 0;
%             app.ActuatorTubeInertiaEditField.Value = 0;
%             app.ActuatorTubeInertiaEditField_2.Value = 0;
%             app.ActuatorTubeInertiaEditField_3.Value = 0;
%             app.ActuatorTubeInertiaEditField_4.Value = 0;
%             app.ActuatorTubeInertiaEditField_5.Value = 0;
%             app.ActuatorTubeInertiaEditField_6.Value = 0;
%             app.ActuatorTubeInertiaEditField_7.Value = 0;
%             app.ActuatorTubeInertiaEditField_8.Value = 0;
%             app.ActuatorTubeInertiaEditField_9.Value = 0;
%             app.ActuatorElementInertiaEditField.Value = 0;
%             app.ActuatorElementInertiaEditField_2.Value = 0;
%             app.ActuatorElementInertiaEditField_3.Value = 0;
%             app.ActuatorElementInertiaEditField_4.Value = 0;
%             app.ActuatorElementInertiaEditField_5.Value = 0;
%             app.ActuatorElementInertiaEditField_6.Value = 0;
%             app.ActuatorElementInertiaEditField_7.Value = 0;
%             app.ActuatorElementInertiaEditField_8.Value = 0;
%             app.ActuatorElementInertiaEditField_9.Value = 0;
%             app.CanardElementInertiaEditField.Value = 0;
%             app.CanardElementInertiaEditField_2.Value = 0;
%             app.CanardElementInertiaEditField_3.Value = 0;
%             app.CanardElementInertiaEditField_4.Value = 0;
%             app.CanardElementInertiaEditField_5.Value = 0;
%             app.CanardElementInertiaEditField_6.Value = 0;
%             app.CanardElementInertiaEditField_7.Value = 0;
%             app.CanardElementInertiaEditField_8.Value = 0;
%             app.CanardElementInertiaEditField_9.Value = 0;
%             app.Connector3ElementInertiaEditField.Value = 0;
%             app.Connector3ElementInertiaEditField_2.Value = 0;
%             app.Connector3ElementInertiaEditField_3.Value = 0;
%             app.Connector3ElementInertiaEditField_4.Value = 0;
%             app.Connector3ElementInertiaEditField_5.Value = 0;
%             app.Connector3ElementInertiaEditField_6.Value = 0;
%             app.Connector3ElementInertiaEditField_7.Value = 0;
%             app.Connector3ElementInertiaEditField_8.Value = 0;
%             app.Connector3ElementInertiaEditField_9.Value = 0;
%             app.RecoveryTubeInertiaEditField.Value = 0;
%             app.RecoveryTubeInertiaEditField_2.Value = 0;
%             app.RecoveryTubeInertiaEditField_3.Value = 0;
%             app.RecoveryTubeInertiaEditField_4.Value = 0;
%             app.RecoveryTubeInertiaEditField_5.Value = 0;
%             app.RecoveryTubeInertiaEditField_6.Value = 0;
%             app.RecoveryTubeInertiaEditField_7.Value = 0;
%             app.RecoveryTubeInertiaEditField_8.Value = 0;
%             app.RecoveryTubeInertiaEditField_9.Value = 0;
%             app.RecoveryElementInertiaEditField.Value = 0;
%             app.RecoveryElementInertiaEditField_2.Value = 0;
%             app.RecoveryElementInertiaEditField_3.Value = 0;
%             app.RecoveryElementInertiaEditField_4.Value = 0;
%             app.RecoveryElementInertiaEditField_5.Value = 0;
%             app.RecoveryElementInertiaEditField_6.Value = 0;
%             app.RecoveryElementInertiaEditField_7.Value = 0;
%             app.RecoveryElementInertiaEditField_8.Value = 0;
%             app.RecoveryElementInertiaEditField_9.Value = 0;
%             app.N2OTankInertiaEditField.Value = 0;
%             app.N2OTankInertiaEditField_2.Value = 0;
%             app.N2OTankInertiaEditField_3.Value = 0;
%             app.N2OTankInertiaEditField_4.Value = 0;
%             app.N2OTankInertiaEditField_5.Value = 0;
%             app.N2OTankInertiaEditField_6.Value = 0;
%             app.N2OTankInertiaEditField_7.Value = 0;
%             app.N2OTankInertiaEditField_8.Value = 0;
%             app.N2OTankInertiaEditField_9.Value = 0;
%             app.N2OInitialInertiaEditField.Value = 0;
%             app.N2OInitialInertiaEditField_2.Value = 0;
%             app.N2OInitialInertiaEditField_3.Value = 0;
%             app.N2OInitialInertiaEditField_4.Value = 0;
%             app.N2OInitialInertiaEditField_5.Value = 0;
%             app.N2OInitialInertiaEditField_6.Value = 0;
%             app.N2OInitialInertiaEditField_7.Value = 0;
%             app.N2OInitialInertiaEditField_8.Value = 0;
%             app.N2OInitialInertiaEditField_9.Value = 0;
%             app.N2OFinalInertiaEditField.Value = 0;
%             app.N2OFinalInertiaEditField_2.Value = 0;
%             app.N2OFinalInertiaEditField_3.Value = 0;
%             app.N2OFinalInertiaEditField_4.Value = 0;
%             app.N2OFinalInertiaEditField_5.Value = 0;
%             app.N2OFinalInertiaEditField_6.Value = 0;
%             app.N2OFinalInertiaEditField_7.Value = 0;
%             app.N2OFinalInertiaEditField_8.Value = 0;
%             app.N2OFinalInertiaEditField_9.Value = 0;
%             app.CVTubeInertiaEditField.Value = 0;
%             app.CVTubeInertiaEditField_2.Value = 0;
%             app.CVTubeInertiaEditField_3.Value = 0;
%             app.CVTubeInertiaEditField_4.Value = 0;
%             app.CVTubeInertiaEditField_5.Value = 0;
%             app.CVTubeInertiaEditField_6.Value = 0;
%             app.CVTubeInertiaEditField_7.Value = 0;
%             app.CVTubeInertiaEditField_8.Value = 0;
%             app.CVTubeInertiaEditField_9.Value = 0;
%             app.CVElementInertiaEditField.Value = 0;
%             app.CVElementInertiaEditField_2.Value = 0;
%             app.CVElementInertiaEditField_3.Value = 0;
%             app.CVElementInertiaEditField_4.Value = 0;
%             app.CVElementInertiaEditField_5.Value = 0;
%             app.CVElementInertiaEditField_6.Value = 0;
%             app.CVElementInertiaEditField_7.Value = 0;
%             app.CVElementInertiaEditField_8.Value = 0;
%             app.CVElementInertiaEditField_9.Value = 0;
%             app.FuelTankInertiaEditField.Value = 0;
%             app.FuelTankInertiaEditField_2.Value = 0;
%             app.FuelTankInertiaEditField_3.Value = 0;
%             app.FuelTankInertiaEditField_4.Value = 0;
%             app.FuelTankInertiaEditField_5.Value = 0;
%             app.FuelTankInertiaEditField_6.Value = 0;
%             app.FuelTankInertiaEditField_7.Value = 0;
%             app.FuelTankInertiaEditField_8.Value = 0;
%             app.FuelTankInertiaEditField_9.Value = 0;
%             app.FuelInitialInertiaEditField.Value = 0;
%             app.FuelInitialInertiaEditField_2.Value = 0;
%             app.FuelInitialInertiaEditField_3.Value = 0;
%             app.FuelInitialInertiaEditField_4.Value = 0;
%             app.FuelInitialInertiaEditField_5.Value = 0;
%             app.FuelInitialInertiaEditField_6.Value = 0;
%             app.FuelInitialInertiaEditField_7.Value = 0;
%             app.FuelInitialInertiaEditField_8.Value = 0;
%             app.FuelInitialInertiaEditField_9.Value = 0;
%             app.FuelFinalInertiaEditField.Value = 0;
%             app.FuelFinalInertiaEditField_2.Value = 0;
%             app.FuelFinalInertiaEditField_3.Value = 0;
%             app.FuelFinalInertiaEditField_4.Value = 0;
%             app.FuelFinalInertiaEditField_5.Value = 0;
%             app.FuelFinalInertiaEditField_6.Value = 0;
%             app.FuelFinalInertiaEditField_7.Value = 0;
%             app.FuelFinalInertiaEditField_8.Value = 0;
%             app.FuelFinalInertiaEditField_9.Value = 0;
%             app.TailElementInertiaEditField.Value = 0;
%             app.TailElementInertiaEditField_2.Value = 0;
%             app.TailElementInertiaEditField_3.Value = 0;
%             app.TailElementInertiaEditField_4.Value = 0;
%             app.TailElementInertiaEditField_5.Value = 0;
%             app.TailElementInertiaEditField_6.Value = 0;
%             app.TailElementInertiaEditField_7.Value = 0;
%             app.TailElementInertiaEditField_8.Value = 0;
%             app.TailElementInertiaEditField_9.Value = 0;
        end
        %% Arrange the TT&C data list
        function insertdata(app,data_ID)
            % datalist = append(datalist,data_ID);
        end
        %% link comport
        function StartLinkCOMport(app)
            if app.StartLinkingButton.Enable == 1 %Debug
                %Change button state
                app.StartLinkingButton.Enable = 0;
                app.StopLinkingButton.Enable = 1;
                app.StartLamp.Color = 'green';
                %Link COM port and set callback
                app.comport = app.COMportListBox.Value;%app.COMportListBox.Value;
                str2double(app.BaudrateListBox.Value);
                app.bitrate = str2double(app.BaudrateListBox.Value);%
                app.timeout = 100;
                app.ArduinoObj = serialport(app.comport,app.bitrate,"Timeout",app.timeout);%connect to serial port
                configureTerminator(app.ArduinoObj,"CR/LF");
                flush(app.ArduinoObj);%Flush the serialport object to remove any old data.
                % app.ArduinoObj.UserData = struct("Data",[],"Count",1);%Prepare the UserData property to store the Arduino data.
                maxpoint = 50;
                %create animatedline
                app.RollPlot = animatedline(app.AttitudeAxes,'Marker','.','MaximumNumPoints',maxpoint,'Color','m');
                app.PitchPlot = animatedline(app.AttitudeAxes,'MaximumNumPoints',maxpoint,'Color','g');
                app.YawPlot = animatedline(app.AttitudeAxes,'MaximumNumPoints',maxpoint,'Color','b');
                legend(app.AttitudeAxes,'Roll','Pitch','Yaw')
                app.PositionPlot = animatedline(app.PositionAxes);
                view(app.PositionAxes,3);
                app.RollRatePlot = animatedline(app.RateofTurnAxes,'MaximumNumPoints',maxpoint,'Color','m');
                app.PitchRatePlot = animatedline(app.RateofTurnAxes,'MaximumNumPoints',maxpoint,'Color','g');
                app.YawRatePlot = animatedline(app.RateofTurnAxes,'MaximumNumPoints',maxpoint,'Color','b');
                legend(app.RateofTurnAxes,'Roll rate','Pitch rate','Yaw rate')
                app.VelXPlot = animatedline(app.VelocityAxes,'MaximumNumPoints',maxpoint,'Color','m');
                app.VelYPlot = animatedline(app.VelocityAxes,'MaximumNumPoints',maxpoint,'Color','g');
                app.VelZPlot = animatedline(app.VelocityAxes,'MaximumNumPoints',maxpoint,'Color','b');
                legend(app.VelocityAxes,'VelX','VelY','VelZ')
                app.AccXPlot = animatedline(app.AccelerationAxes,'MaximumNumPoints',maxpoint,'Color','m');
                app.AccYPlot = animatedline(app.AccelerationAxes,'MaximumNumPoints',maxpoint,'Color','g');
                app.AccZPlot = animatedline(app.AccelerationAxes,'MaximumNumPoints',maxpoint,'Color','b');
                legend(app.AccelerationAxes,'AccX','AccY','AccZ')
                app.RealAccXPlot = animatedline(app.RealAccelerationAxes,'MaximumNumPoints',maxpoint,'Color','m');
                app.RealAccYPlot = animatedline(app.RealAccelerationAxes,'MaximumNumPoints',maxpoint,'Color','g');
                app.RealAccZPlot = animatedline(app.RealAccelerationAxes,'MaximumNumPoints',maxpoint,'Color','b');
                legend(app.RealAccelerationAxes,'RealAccX','RealAccY','RealAccZ')

                %set the callback function
                configureCallback(app.ArduinoObj,"terminator",@app.SerialTerminFCN);
            end
        end
        function StopLinkCOMport(app)
            if app.StartLinkingButton.Enable == 0 %Debug
                app.StartLinkingButton.Enable = 1;
                app.StopLinkingButton.Enable = 0;
                app.StartLamp.Color = 'red';
                configureCallback(app.ArduinoObj,"off")
                delete(app.ArduinoObj);
            end
        end
        
        function Parse_data(app,dataArr,dataIndex,dataSize)
            % for i = 1:length(dataArr)
            %     %data_table(1,i):dataIndex, data_table(i,1):dataSize
            %     app.data_table(i,2) = dataArr(dataIndex:dataIndex+dataSize-1);
            % % not very easy to parse, because the data size and type is not the same
            % 
            % end
        end

        %% real time read/log serial data
        function SerialTerminFCN(app,arduinoObj,~)


            %read data string from stm32 or Arduino
            datachar = readline(arduinoObj);

            %Parse data
            splited_double = str2double(strsplit(datachar,','));%data array
            %assign data from data array
            % app.testEditField.Value = str2double(datachar);%str2double(strsplit(datachar,','))
            % app.testEditField_2.Value = str2double(strsplit(datachar,','));
            UTChr = splited_double(app.TTC_data.UTCTimeHr_index:app.TTC_data.UTCTimeHr_endindex)+8;
            UTCmin = splited_double(app.TTC_data.UTCTimeMin_index:app.TTC_data.UTCTimeMin_endindex);
            UTCsec = splited_double(app.TTC_data.UTCTimeSec_index:app.TTC_data.UTCTimeSec_endindex);
            UTCsubsec = splited_double(app.TTC_data.UTCTimeSubsec_index:app.TTC_data.UTCTimeSubsec_endindex);
            app.UTCtimeEditField.Value = sprintf('%02d:%02d:%02d:%02d',UTChr,UTCmin,UTCsec,UTCsubsec);
            UTCtimedouble = UTCmin*60+UTCsec+UTCsubsec/100;

            Pack_count = swapbytes(typecast(uint8(splited_double(app.TTC_data.PackCount_index:app.TTC_data.PackCount_endindex)),'uint16'));

            app.IMUtempuratureGauge.Value = inputStr2float16(app,splited_double(app.TTC_data.IMUTemp_index:app.TTC_data.IMUTemp_endindex)).double;

            q0 = inputStr2float32(app,splited_double(app.TTC_data.Quat0_index:app.TTC_data.Quat0_endindex));
            q1 = inputStr2float32(app,splited_double(app.TTC_data.Quat1_index:app.TTC_data.Quat1_endindex));
            q2 = inputStr2float32(app,splited_double(app.TTC_data.Quat2_index:app.TTC_data.Quat2_endindex));
            q3 = inputStr2float32(app,splited_double(app.TTC_data.Quat3_index:app.TTC_data.Quat3_endindex));
            [Euler_yaw,Euler_pitch,Euler_roll] = quat2angle([q0 q1 q2 q3]);
            app.RollEditField.Value = rad2deg(Euler_roll);
            app.PitchEditField.Value = rad2deg(Euler_pitch);
            app.YawEditField.Value = rad2deg(Euler_yaw);

            app.RollrateEditField.Value = inputStr2float16(app,splited_double(app.TTC_data.Rollrate_index:app.TTC_data.Rollrate_endindex)).double;
            app.PitchrateEditField.Value = inputStr2float16(app,splited_double(app.TTC_data.Pitchrate_index:app.TTC_data.Pitchrate_endindex)).double;
            app.YawrateEditField.Value = inputStr2float16(app,splited_double(app.TTC_data.Yawrate_index:app.TTC_data.Yawrate_endindex)).double;

            app.AccXEditField.Value = inputStr2float16(app,splited_double(app.TTC_data.AccX_index:app.TTC_data.AccX_endindex)).double;
            app.AccYEditField.Value = inputStr2float16(app,splited_double(app.TTC_data.AccY_index:app.TTC_data.AccY_endindex)).double;
            app.AccZEditField.Value = inputStr2float16(app,splited_double(app.TTC_data.AccZ_index:app.TTC_data.AccZ_endindex)).double;

            app.RealAccXEditField.Value = inputStr2float16(app,splited_double(app.TTC_data.RealAccX_index:app.TTC_data.RealAccX_endindex)).double;
            app.RealAccYEditField.Value = inputStr2float16(app,splited_double(app.TTC_data.RealAccY_index:app.TTC_data.RealAccY_endindex)).double;
            app.RealAccZEditField.Value = inputStr2float16(app,splited_double(app.TTC_data.RealAccZ_index:app.TTC_data.RealAccZ_endindex)).double;

            app.PosXEditField.Value = inputStr2float16(app,splited_double(app.TTC_data.ECEFX_index:app.TTC_data.ECEFX_endindex)).double;
            app.PosYEditField.Value = inputStr2float16(app,splited_double(app.TTC_data.ECEFY_index:app.TTC_data.ECEFY_endindex)).double;
            app.PosZEditField.Value = inputStr2float16(app,splited_double(app.TTC_data.ECEFZ_index:app.TTC_data.ECEFZ_endindex)).double;

            app.LongitudeEditField.Value = inputStr2float32(app,splited_double(app.TTC_data.Long_index:app.TTC_data.Long_endindex));
            app.LatitudeEditField.Value = inputStr2float32(app,splited_double(app.TTC_data.Lat_index:app.TTC_data.Lat_endindex));
            app.HeightEditField.Value = inputStr2float32(app,splited_double(app.TTC_data.Hei_index:app.TTC_data.Hei_endindex));

            app.VelXEditField.Value = inputStr2float16(app,splited_double(app.TTC_data.VelX_index:app.TTC_data.VelX_endindex)).double;
            app.VelYEditField.Value = inputStr2float16(app,splited_double(app.TTC_data.VelY_index:app.TTC_data.VelY_endindex)).double;
            app.VelZEditField.Value = inputStr2float16(app,splited_double(app.TTC_data.VelZ_index:app.TTC_data.VelZ_endindex)).double;

            app.PAtempuratureGauge.Value = inputStr2float16(app,splited_double(app.TTC_data.PATemp_index:app.TTC_data.PATemp_endindex)).double;
            % timer = splited_double(45);
            % plot(app.AttitudeUIAxes,timer,Euler_roll)
            % hold(app.AttitudeUIAxes, "on")
            
            app.LoRaRSSIEditField.Value = splited_double(app.TTC_data.LoRaRSSI_index);
            app.LoRaRSSIGauge.Value = splited_double(app.TTC_data.LoRaRSSI_index);
            app.LoRaSNREditField.Value = splited_double(app.TTC_data.LoRaSNR_index);

            %plot data and modify tge axes boundary
            addpoints(app.RollPlot,UTCtimedouble,app.RollEditField.Value);
            addpoints(app.PitchPlot,UTCtimedouble,app.PitchEditField.Value);
            addpoints(app.YawPlot,UTCtimedouble,app.YawEditField.Value);
            app.AttitudeAxes.XLim = [UTCtimedouble-5,UTCtimedouble+5];

            addpoints(app.PositionPlot,app.LongitudeEditField.Value,app.LatitudeEditField.Value,app.HeightEditField.Value)

            addpoints(app.RollRatePlot,UTCtimedouble,app.RollrateEditField.Value)
            addpoints(app.PitchRatePlot,UTCtimedouble,app.PitchrateEditField.Value)
            addpoints(app.YawRatePlot,UTCtimedouble,app.YawrateEditField.Value)
            app.RateofTurnAxes.XLim = [UTCtimedouble-5,UTCtimedouble+5];

            addpoints(app.VelXPlot,UTCtimedouble,app.VelXEditField.Value)
            addpoints(app.VelYPlot,UTCtimedouble,app.VelYEditField.Value)
            addpoints(app.VelZPlot,UTCtimedouble,app.VelZEditField.Value)
            app.VelocityAxes.XLim = [UTCtimedouble-5,UTCtimedouble+5];

            addpoints(app.AccXPlot,UTCtimedouble,app.AccXEditField.Value)
            addpoints(app.AccYPlot,UTCtimedouble,app.AccYEditField.Value)
            addpoints(app.AccZPlot,UTCtimedouble,app.AccZEditField.Value)
            app.AccelerationAxes.XLim = [UTCtimedouble-5,UTCtimedouble+5];

            addpoints(app.RealAccXPlot,UTCtimedouble,app.RealAccXEditField.Value)
            addpoints(app.RealAccYPlot,UTCtimedouble,app.RealAccYEditField.Value)
            addpoints(app.RealAccZPlot,UTCtimedouble,app.RealAccZEditField.Value)
            app.RealAccelerationAxes.XLim = [UTCtimedouble-5,UTCtimedouble+5];

            
            % drawnow limitrate

            %log data
            if app.LoggingStopButton.Value==1
                yyyymmdd(datetime);
                filename = "Flight_data\"+app.currentName+".txt";
                fileID = fopen(filename,'a');
                if ftell(fileID)==0          %Add information at first row like time, quaternion,... 
                    datalist = {'UTC',...
                                'Pack_count',...
                                'IMUTemp',...
                                'q0',...
                                'q1',...
                                'q2',...
                                'q3',...
                                'Roll',...
                                'Pitch',...
                                'Yaw',...
                                'RollRate',...
                                'PitchRate',...
                                'YawRate',...
                                'AccX',...
                                'AccY',...
                                'AccZ',...
                                'RealAccX',...
                                'RealAccY',...
                                'RealAccZ',...
                                'PosX',...
                                'PosY',...
                                'PosZ',...
                                'Long',...
                                'Lat',...
                                'Height',...
                                'VelX',...
                                'VelY',...
                                'VelZ',...
                                'RSSI',...
                                'SNR'};
                    datalist = strjoin(datalist,',');
                    fprintf(fileID,'%s,',datalist);
                    fprintf(fileID,'\n');
                end
                savestr = {app.UTCtimeEditField.Value,...
                                    num2str(Pack_count),...
                                    num2str(app.IMUtempuratureGauge.Value),...
                                    num2str([q0,q1,q2,q3]),...
                                    num2str([app.RollEditField.Value,app.PitchEditField.Value,app.YawEditField.Value]),...
                                    num2str([app.RollrateEditField.Value,app.PitchrateEditField.Value,app.YawrateEditField.Value]),...
                                    num2str([app.AccXEditField.Value,app.AccYEditField.Value,app.AccZEditField.Value]),...
                                    num2str([app.RealAccXEditField.Value,app.RealAccYEditField.Value,app.RealAccZEditField.Value]),...
                                    num2str([app.PosXEditField.Value,app.PosYEditField.Value,app.PosZEditField.Value]),...
                                    num2str([app.LongitudeEditField.Value,app.LatitudeEditField.Value,app.HeightEditField.Value]),...
                                    num2str([app.VelXEditField.Value,app.VelYEditField.Value,app.HeightEditField.Value]),...
                                    num2str(app.LoRaRSSIEditField.Value),...
                                    num2str(app.LoRaSNREditField.Value)};
                savestr = strjoin(savestr,",");
                fprintf(fileID,"%s,"',savestr);
                fprintf(fileID,'\n');
%                 fclose(fileID);
                fclose all;    %if file still in OPEN
            end
        end

        function output_float = inputStr2float32(app,inputStr)
            dataStr = '';
            for i=1:4
                dataStr = strcat(dataStr,dec2bin(inputStr(i),8));% convert into 32 bits binary
            end
            output_float_array = typecast(uint32(bin2dec(dataStr)),'single');
            output_float = double(output_float_array);
        end
        function output_float = inputStr2float16(app,inputdouble)
            output_float = half.typecast(typecast(flip(uint8(inputdouble)), 'uint16'));
        end

        % function PAtemp = PATemperature_cal(app,ADCval)
        %     PAtemp = -(ADCval-925)/6.7+25;
        % end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% initialize app variables
            % AppVariables(app);
%             %% initialize rocket mass & CG property
%             InitProperties(app);
% 
%             %% load rocket model
%             LoadRocketModel(app,'Config_ver2023');
% 
%             %% step 1 : select model
% %             app.SelectModel_Panel.Tooltip = 'Step1:select model';
% 
%             %% step 2 : change rocket properties
% 
%             %% step 3 : save data
% 

            
        end

        % Menu selected function: FinsgeometryMenu
        function FinsgeometryMenuSelected(app, event)
            % app.DialogApp = Fins_design();
        end

        % Button pushed function: Run6DOFButton
        function Run6DOFButtonPushed(app, event)
%             ts = app.SamplingtimeEditField.Value;
%             simout = sim('model_6dof')
            try
                app.toggleUIC('off', 'Simulating ...')
%                 clf(app.PositionUIAxes)
%                 cla(app.AngularVUIAxes)
%                 cla(app.AOA_AOSUIAxes)
%                 cla(app.StabilityUIAxes)
%                 cla(app.AttitudeUIAxes)
%                 cla(app.MachUIAxes)
%                 cla(app.StageUIAxes)
%                 cla(app.MassUIAxes)
                switch app.WhichrocketDropDown.Value
                    case 'ver2023'
                        copyfile Config_ver1.m Rocket_property.m;
                    case 'ver2024'
                        copyfile Config_ver2.m Rocket_property.m;
                    otherwise
                        error('Configuration fault')
                end
                
                % create the simulation input
                simInp = Simulink.SimulationInput('model_6dof');

                %rocket property
                simInp = simInp.setVariable('SIM_CASE',1);

                %% set initial launch condition

                %rocket property
                simInp = simInp.setVariable('d',0.265);

                % set initial NED position(m)(z must be negative!!!)
                simInp = simInp.setVariable('x0',0);
                simInp = simInp.setVariable('y0',0);
                simInp = simInp.setVariable('z0',-app.zEditField.Value);

                % set initial NED velocity(m/s)
                simInp = simInp.setVariable('vx0',0);
                simInp = simInp.setVariable('vy0',0);
                simInp = simInp.setVariable('vz0',0);

                % set initial body velocity(m/s)
                simInp = simInp.setVariable('u0',0);
                simInp = simInp.setVariable('v0',0);
                simInp = simInp.setVariable('w0',0);

                % set initial angular velocity(rad/s)
                simInp = simInp.setVariable('p0',0);
                simInp = simInp.setVariable('q0',0);
                simInp = simInp.setVariable('r0',0);

                % set initial attitude(degree),rotation sequence is psi-theta-phi
                phid0 = 0;
                thetad0 = app.LaunchangleEditField.Value;
                psid0 = app.LaunchdirectionN0EditField.Value;
                phi0 = phid0*pi/180;
                theta0 = thetad0*pi/180;
                psi0 = psid0*pi/180;
                e00=cos(0.5*phi0)*cos(0.5*theta0)*cos(0.5*psi0)+sin(0.5*phi0)*sin(0.5*theta0)*sin(0.5*psi0);
                ex0=sin(0.5*phi0)*cos(0.5*theta0)*cos(0.5*psi0)-cos(0.5*phi0)*sin(0.5*theta0)*sin(0.5*psi0);
                ey0=cos(0.5*phi0)*sin(0.5*theta0)*cos(0.5*psi0)+sin(0.5*phi0)*cos(0.5*theta0)*sin(0.5*psi0);
                ez0=cos(0.5*phi0)*cos(0.5*theta0)*sin(0.5*psi0)-sin(0.5*phi0)*sin(0.5*theta0)*cos(0.5*psi0);

                simInp = simInp.setVariable('e00',e00);
                simInp = simInp.setVariable('ex0',ex0);
                simInp = simInp.setVariable('ey0',ey0);
                simInp = simInp.setVariable('ez0',ez0);

                % set launch rail length
                simInp = simInp.setVariable('rail_len',app.LaunchraillengthEditField.Value);

                % set aerodynamic coefficients
                coe_1000 = readmatrix('./datcom/rocketcoef/Asfaloth_ver1.xlsx','Range','A1:L104');
                simInp = simInp.setVariable('Minter',coe_1000(1,2:12));
                simInp = simInp.setVariable('atkinter',coe_1000(2:10,1));
                simInp = simInp.setVariable('cn1000',coe_1000(2:10,2:12));
                simInp = simInp.setVariable('caoff1000',coe_1000(12:20,2:12));
                simInp = simInp.setVariable('caon1000',coe_1000(22:30,2:12));
                simInp = simInp.setVariable('xcp1000',coe_1000(32:40,2:12));
                simInp = simInp.setVariable('cnq1000',coe_1000(42,2:12));
                simInp = simInp.setVariable('cmq1000',coe_1000(44,2:12));
                simInp = simInp.setVariable('cndq1000',coe_1000(46,2:12));
                simInp = simInp.setVariable('cldp1000',coe_1000(48,2:12));
                simInp = simInp.setVariable('clpt_1000',coe_1000(50,2:12));
                simInp = simInp.setVariable('cl_phi1000',coe_1000(52:60,2:12));
                simInp = simInp.setVariable('xcp_c1000',coe_1000(62:70,2:12));
                simInp = simInp.setVariable('clpc_1000',coe_1000(72,2:12));
                simInp = simInp.setVariable('cmad_1000',coe_1000(74,2:12));
                simInp = simInp.setVariable('cna_1000',coe_1000(76:84,2:12));
                simInp = simInp.setVariable('cma_1000',coe_1000(86:94,2:12));
                simInp = simInp.setVariable('xcp_t1000',coe_1000(96:104,2:12));

                % set wind property
                Windtablestr = "./wind table/wind_table/" + "hualien_" + num2str(app.WinddataDropDown.Value) + "00" + ".csv";
                wind = readmatrix(Windtablestr,'Range','A:C');
                wind(1,:) = [];
                simInp = simInp.setVariable('wind_h',wind(:,1));
                simInp = simInp.setVariable('wind_north',wind(:,2));
                simInp = simInp.setVariable('wind_east',wind(:,3));
                simInp = simInp.setVariable('wind_on',app.WindfieldCheckBox.Value);
                simInp = simInp.setVariable('turb_on',app.WindgustCheckBox.Value);

                % on/off controller
                simInp = simInp.setVariable('K1',4.3);
                simInp = simInp.setVariable('K2',0.2);
                simInp = simInp.setVariable('K3',0.3);
                simInp = simInp.setVariable('KP',2);
                wg = 2;
                simInp = simInp.setVariable('tou1',5/wg);
                phi_m = 30;
                simInp = simInp.setVariable('beta',15);
                afa = (1-sind(phi_m))/(1+sind(phi_m));
                simInp = simInp.setVariable('afa',afa);
                wm = 5;
                tou2 = 1/wm*sqrt(1/afa);
                simInp = simInp.setVariable('tou2',tou2);
                simInp = simInp.setVariable('roll_control_on',app.RollcontrolCheckBox.Value);
                simInp = simInp.setVariable('pitch_control_on',app.PitchyawcontrolCheckBox.Value);

                % set motor parameters
                BW_HZ = 100;%hz
                BW = BW_HZ*2*pi;%rad/s
                damp = 0.9;
                simInp = simInp.setVariable('damp',damp);
                simInp = simInp.setVariable('wn',BW/sqrt(1-2*damp^2+sqrt(2-4*damp^2+4*damp^4)));%rad/s

                % set the time step
                simInp = simInp.setVariable('ts', app.SamplingtimeEditField.Value);

                % set the stop time
                simInp = simInp.setVariable('stopt', app.StoptimeEditField.Value);

                for i=1:app.runtimeEditField.Value
                    % run
                    simout = sim(simInp);

                    %plot
                    app.Time = simout.mass_property.time;
                    app.AOA = simout.air(12,:);
                    app.AOS = simout.air(13,:);
                    %Position
                    app.PositionUIAxes.XLim = [0 max(app.Time)];
                    app.Position_X = simout.vstate(10,:);
                    app.Position_Y = simout.vstate(11,:);
                    app.Position_Z = simout.vstate(12,:);
                    P1 = plot(app.PositionUIAxes,app.Time,app.Position_X);
                    hold(app.PositionUIAxes,'on')
                    P2 = plot(app.PositionUIAxes,app.Time,app.Position_Y);
                    P3 = plot(app.PositionUIAxes,app.Time,app.Position_Z);
                    legend(app.PositionUIAxes,[P1,P2,P3],'XRange','YRange','Height')
                    hold(app.PositionUIAxes,'off')
                    %Angular velocity
                    app.AngularVUIAxes.XLim = [0 max(app.Time)];
                    hold(app.AngularVUIAxes,'on')
                    app.p = simout.vstate(13,:)/(2*pi);
                    app.q = simout.vstate(14,:)/(2*pi);
                    app.r = simout.vstate(15,:)/(2*pi);
                    AV1 = plot(app.AngularVUIAxes,app.Time,app.p);
                    AV2 = plot(app.AngularVUIAxes,app.Time,app.q);
                    AV3 = plot(app.AngularVUIAxes,app.Time,app.r);
                    legend(app.AngularVUIAxes,[AV1,AV2,AV3],'p','q','r')
                    hold(app.AngularVUIAxes,'off')
                    %AOA/AOS
                    app.AOA_AOSUIAxes.XLim = [0 max(app.Time)];
                    hold(app.AOA_AOSUIAxes,'on')
                    AS1 = plot(app.AOA_AOSUIAxes,app.Time,app.AOA);
                    AS2 = plot(app.AOA_AOSUIAxes,app.Time,app.AOS);
                    legend(app.AOA_AOSUIAxes,[AS1,AS2],'AOA','AOS')
                    hold(app.AOA_AOSUIAxes,'off')
                    %CP/CG/Stability
                    app.StabilityUIAxes.XLim = [0 max(app.Time)];
                    hold(app.StabilityUIAxes,'on')
                    app.CP = simout.aeroCoef(:,7)*app.d;
                    app.CG = simout.mass_property.signals.values(:,2);
                    app.Stability = (app.CP-app.CG)/app.d;
                    S1 = plot(app.StabilityUIAxes,app.Time,app.CP);
                    S2 = plot(app.StabilityUIAxes,app.Time,app.CG);
                    S3 = plot(app.StabilityUIAxes,app.Time,app.Stability);
                    legend(app.StabilityUIAxes,[S1,S2,S3],'CP(m)','CG(m)','Stability(caliber)')
                    hold(app.StabilityUIAxes,'off')
                    %Attitude
                    app.AttitudeUIAxes.XLim = [0 max(app.Time)];
                    app.Roll = simout.vstate(20,:);
                    app.Pitch = simout.vstate(21,:);
                    app.Yaw = simout.vstate(22,:);
                    hold(app.AttitudeUIAxes,'on')
                    A1 = plot(app.AttitudeUIAxes,app.Time,app.Roll);
                    A2 = plot(app.AttitudeUIAxes,app.Time,app.Pitch);
                    A3 = plot(app.AttitudeUIAxes,app.Time,app.Yaw);
                    legend(app.AttitudeUIAxes,[A1,A2,A3],'Roll','Pitch','Yaw')
                    hold(app.AttitudeUIAxes,'off')
                    %Mach number
                    app.Mach = simout.air(2,:);
                    app.MachUIAxes.XLim = [0 max(app.Time)];
                    plot(app.MachUIAxes,app.Time,app.Mach)
                    %                 legend(app.MachUIAxes,'Mach')
                    %dynamic pressure
                    app.Q = simout.air(3,:);
                    app.QUIAxes.XLim = [0 max(app.Time)];
                    plot(app.QUIAxes,app.Time,app.Q)
                    %Stage
                    %                 app.StageUIAxes.XLim = [0 max(app.Time)];
                    %                 plot(app.StageUIAxes,app.Time,simout.air(2,:))
                    %Velocity in body frame
                    %%%

                    %

                    %Mass
                    app.MassUIAxes.XLim = [0 max(app.Time)];
                    plot(app.MassUIAxes,app.Time,simout.mass_property.signals.values(:,1))
                    %                 legend(app.MassUIAxes,'Mass(KG)')
                    %Thrust
                    %                 app.MassUIAxes.XLim = [0 max(app.Time)];
                    %                 plot(app.MassUIAxes,app.Time,simout.mass_property.signals.values(:,1))
                end
            catch ME
                errordlg(ME.message);    
            end
        
            app.toggleUIC('on','Run 6DOF');
        end

        % Callback function
        function ver1MenuSelected(app, event)
            % app.Modify_config = 1;
            % app.DialogApp2 = Mass_distribution(app.Modify_config);
        end

        % Callback function
        function ver2MenuSelected(app, event)
            % app.Modify_config = 2;
            % app.DialogApp2 = Mass_distribution(app.Modify_config);            
        end

        % Button pushed function: DownloaddataButton
        function DownloaddataButtonPushed(app, event)
            label = ["Time","X","Y","Z","p","q","r","AOA","AOS","CP","CG","Stability","Roll","Pitch","Yaw","Mach"];
            table = [app.Time,app.Position_X',app.Position_Y',app.Position_Z',app.p',app.q',app.r', ...
                app.AOA',app.AOS',app.CP,app.CG,app.Stability,app.Roll',app.Pitch',app.Yaw',app.Mach'];%
            delete test.xlsx
            writematrix([label;table],'test.xlsx')
        end

        % Menu selected function: AnimationMenu
        function AnimationMenuSelected(app, event)
            % Animation_plot()
        end

        % Button down function: RocketViewAxes
        function RocketViewAxesButtonDown(app, event)


        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            
        end

        % Value changed function: SelectModelDropDown
        function SelectModelDropDownValueChanged(app, event)
            %% read rocket properties data
            app.Rocket_ver = app.SelectModelDropDown.Value;
            switch app.Rocket_ver
                case 'launch1'
                    rocket_prop = readmatrix("rocket_properties\Launch1 mass properties.xlsx","Range","B2:G11"); %import mass, CG, inertia
                    
%                     filename = 'Config_ver2023.mat';
%                     if exist(filename, 'file') == 2
%                         LoadRocketModel(app,filename);
% %                         app.loadstruct = load(filename);
%                     else
%                         warndlg('Config_ver2023.mat does not exist','warning')
%                     end
                case 'launch2'
%                     filename = 'Config_ver2024.mat';
%                     if exist(filename, 'file') == 2
%                         LoadRocketModel(app,filename);
% %                         app.loadstruct = load(filename);
%                     else
%                         warndlg('Config_ver2024.mat does not exist','warning')
%                     end
                otherwise
                    warndlg('Error on select rocket','warning');
            end

            %% write data into editfield
%             app.NoseTubeMassEditField.Value = app.loadstruct.Nose(1);
        end

        % Menu selected function: ver2023Menu
        function ver2023MenuSelected(app, event)
            % SaveRocketModel(app,'ver2023');
        end

        % Menu selected function: ver2024Menu
        function ver2024MenuSelected(app, event)
            % SaveRocketModel(app,'ver2024');
        end

        % Callback function
        function CanardCGEditFieldValueChanged(app, event)
            % value = app.CanardCGEditField.Value;
            % app.rocketView();
        end

        % Callback function
        function TailCGEditFieldValueChanged(app, event)
            % value = app.TailCGEditField.Value;
            % app.rocketView();
        end

        % Menu selected function: helpMenu
        function helpMenuSelected(app, event)
            % filename = fullfile( './APP_6DOF', 'Readme.txt');
            % open('Readme.txt');
        end

        % Button down function: FlightSimulationTab
        function FlightSimulationTabButtonDown(app, event)
            % app.WhichrocketDropDown.Value = app.Rocket_ver;
            % app.stage = 0;
            % app.ts = 0.01;
            % app.d = 0.265;
            % app.S = 1/4*pi*app.d^2;
            % app.g = 9.81;
            % app.mis_angle = 0.1*pi/180;
            % 
            % % load simulink to pay the one time cost of loading libraries
            % % % during app startup.
            % start_simulink;
        end

        % Value changed function: NoseElementMassEditField
        function NoseElementMassEditFieldValueChanged(app, event)
            % value = app.NoseElementMassEditField.Value;
            
        end

        % Value changed function: AvionicsElementMassEditField
        function AvionicsElementMassEditFieldValueChanged(app, event)
            % value = app.AvionicsElementMassEditField.Value;
            
        end

        % Value changed function: T0EditField
        function T0EditFieldValueChanged(app, event)
            % app.Thrust_Time_Plot();
        end

        % Button down function: ThrustcurveTab
        function ThrustcurveTabButtonDown(app, event)
            % app.SelectModelDropDown_3.Value = app.Rocket_ver;
            % app.Thrust_Time_Plot();
        end

        % Value changed function: T0EditField_2
        function T0EditField_2ValueChanged(app, event)
            % app.Thrust_Time_Plot();
        end

        % Value changed function: T1EditField
        function T1EditFieldValueChanged(app, event)
            % app.Thrust_Time_Plot();
        end

        % Value changed function: T1EditField_2
        function T1EditField_2ValueChanged(app, event)
            % app.Thrust_Time_Plot();
        end

        % Value changed function: T2EditField
        function T2EditFieldValueChanged(app, event)
            % app.Thrust_Time_Plot();
        end

        % Value changed function: T2EditField_2
        function T2EditField_2ValueChanged(app, event)
            % app.Thrust_Time_Plot();
        end

        % Value changed function: T3EditField
        function T3EditFieldValueChanged(app, event)
            % app.Thrust_Time_Plot();
        end

        % Value changed function: T3EditField_2
        function T3EditField_2ValueChanged(app, event)
            % app.Thrust_Time_Plot();
        end

        % Value changed function: T4EditField
        function T4EditFieldValueChanged(app, event)
            % app.Thrust_Time_Plot();
        end

        % Value changed function: T4EditField_2
        function T4EditField_2ValueChanged(app, event)
            % app.Thrust_Time_Plot();
        end

        % Menu selected function: UndoMenu
        function UndoMenuSelected(app, event)
            
        end

        % Button down function: RocketpropertiesTab
        function RocketpropertiesTabButtonDown(app, event)
             % app.SelectModelDropDown.Value = app.Rocket_ver;
        end

        % Callback function
        function RocketInertiaTabButtonDown(app, event)
            % app.SelectModelDropDown_2.Value = app.Rocket_ver;
        end

        % Button down function: RealtimesimulationTab
        function RealtimesimulationTabButtonDown(app, event)
            %Judge file mode or COM Port mode.

            %arrange the order of data
            app.data_list = {'UTCTimeHr', 'UTCTimeMin', 'UTCTimeSec', 'UTCTimeSubsec', 'PackCount', ...
                'IMUTemp', 'Quat0', 'Quat1', 'Quat2', 'Quat3', ...
                'Rollrate', 'Pitchrate', 'Yawrate', 'AccX', 'AccY', 'AccZ', ...
                'RealAccX', 'RealAccY', 'RealAccZ', 'ECEFX', 'ECEFY', 'ECEFZ', ...
                'Long', 'Lat', 'Hei', 'VelX', 'VelY', 'VelZ', ...
                'PATemp','LoRaRSSI', 'LoRaSNR'};% 'IMUStatus', 'LoRaStatus', 
            %current index
            currentIndex = 1;
            %go through datalist to assign data index
            for i = 1:length(app.data_list)
                dataName = app.data_list{i};
                dataSize = app.TTC_data.([dataName, '_size']);
                %assign data index
                app.TTC_data.([dataName, '_index']) = currentIndex;
                app.TTC_data.([dataName, '_endindex']) = currentIndex+dataSize-1;
                %update current index
                currentIndex = currentIndex + dataSize;
            end
        end

        % Button pushed function: StartLinkingButton
        function StartLinkingButtonPushed(app, event)
            %check link available


            StartLinkCOMport(app);
            
            app.Count = 0;%initialize counter

%             vir_data_size=5;%num of data size
%             app.portname="COM8";
% 
%             
%             cport=serialport(app.portname,brate,'Timeout',120); %port, baudrate, timeout
%             while cport
%                 read(cport,vir_data_size,"uint8");
%             end
%             clear cport

%             cport = 0;
%             ngps=0;
%             if app.dd1.Value~="None Available"
%                 app.portname= app.dd1.Value;
%                 brate=str2double(app.baudrate);
%                 cport=serialport(app.portname,brate,'Timeout',120); %port, baudrate, timeout
%             else
%                 uialert(app.UIFigure,'Please confirm whether the computer is connected to the ground station.','Warning' ...
%                     ,'Icon','warning');
%                 close(app.fig);
%             end
        end

        % Button pushed function: StopLinkingButton
        function StopLinkingButtonPushed(app, event)
            StopLinkCOMport(app);
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            % SaveRocketModel(app,'ver2023');
        end

        % Value changed function: FlightmodeOFFButton
        function FlightmodeOFFButtonValueChanged(app, event)
            if app.FlightmodeOFFButton.Value==1
            %start flight
            app.FlightmodeOFFButton.BackgroundColor = 'green';
            app.FlightmodeOFFButton.Text = ['FlightMode',newline,'ON'];
            %send flight mode trigger signal to arduino and transmit to FC by LoRa until the confirm signal return

            else
            %stop flight
            app.FlightmodeOFFButton.BackgroundColor = 'red';
            app.FlightmodeOFFButton.Text = ['FlightMode',newline,'OFF'];
            end
        end

        % Value changed function: LoggingStopButton
        function LoggingStopButtonValueChanged(app, event)
            if app.LoggingStopButton.Value==1
            %start record data, data recording behavior is doing in SerialTerminalFCN
            app.LoggingStopButton.BackgroundColor = 'green';
            app.LoggingStopButton.Text = ['Logging',newline,'Start'];
            conflictName = 0;
            app.currentName = app.FilenameEditField.Value;
            %check whether the file name is exist
            while(isfile("Flight_data\"+app.currentName+".txt"))
                app.currentName = app.FilenameEditField.Value+"_"+num2str(conflictName);
                conflictName = conflictName + 1;
            end
            else
            %stop record data
            app.LoggingStopButton.BackgroundColor = 'red';
            app.LoggingStopButton.Text = ['Logging',newline,'Stop'];
            end
        end

        % Button pushed function: ModifydownlinkmessageButton
        function ModifydownlinkmessageButtonPushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Enable = 'off';
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Enable = 'off';
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.Text = 'Save as';
            app.ver2023Menu = uimenu(app.SaveasMenu);
            app.ver2023Menu.MenuSelectedFcn = createCallbackFcn(app, @ver2023MenuSelected, true);
            app.ver2023Menu.Text = 'ver2023';
            app.ver2024Menu = uimenu(app.SaveasMenu);
            app.ver2024Menu.MenuSelectedFcn = createCallbackFcn(app, @ver2024MenuSelected, true);
            app.ver2024Menu.Text = 'ver2024';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Enable = 'off';
            app.EditMenu.Text = 'Edit';
            app.UndoMenu = uimenu(app.EditMenu);
            app.UndoMenu.MenuSelectedFcn = createCallbackFcn(app, @UndoMenuSelected, true);
            app.UndoMenu.Enable = 'off';
            app.UndoMenu.Text = 'Undo';
            app.RedoMenu = uimenu(app.EditMenu);
            app.RedoMenu.Enable = 'off';
            app.RedoMenu.Text = 'Redo';
            app.CutMenu = uimenu(app.EditMenu);
            app.CutMenu.Enable = 'off';
            app.CutMenu.Separator = 'on';
            app.CutMenu.Text = 'Cut';
            app.CopyMenu = uimenu(app.EditMenu);
            app.CopyMenu.Enable = 'off';
            app.CopyMenu.Text = 'Copy';
            app.PasteMenu = uimenu(app.EditMenu);
            app.PasteMenu.Enable = 'off';
            app.PasteMenu.Text = 'Paste';
            app.DeleteMenu = uimenu(app.EditMenu);
            app.DeleteMenu.Enable = 'off';
            app.DeleteMenu.Text = 'Delete';
            app.ScaleMenu = uimenu(app.EditMenu);
            app.ScaleMenu.Enable = 'off';
            app.ScaleMenu.Separator = 'on';
            app.ScaleMenu.Text = 'Scale';
            app.PreferenceMenu = uimenu(app.EditMenu);
            app.PreferenceMenu.Enable = 'off';
            app.PreferenceMenu.Text = 'Preference';
            app.DesignMenu = uimenu(app.UIFigure);
            app.DesignMenu.Enable = 'off';
            app.DesignMenu.Text = 'Design';
            app.FinsgeometryMenu = uimenu(app.DesignMenu);
            app.FinsgeometryMenu.MenuSelectedFcn = createCallbackFcn(app, @FinsgeometryMenuSelected, true);
            app.FinsgeometryMenu.Text = 'Fins geometry';
            app.AnimationMenu = uimenu(app.UIFigure);
            app.AnimationMenu.MenuSelectedFcn = createCallbackFcn(app, @AnimationMenuSelected, true);
            app.AnimationMenu.Enable = 'off';
            app.AnimationMenu.Text = 'Animation';
            app.RealtimeMenu = uimenu(app.UIFigure);
            app.RealtimeMenu.Enable = 'off';
            app.RealtimeMenu.Text = 'Real-time';
            app.helpMenu = uimenu(app.UIFigure);
            app.helpMenu.MenuSelectedFcn = createCallbackFcn(app, @helpMenuSelected, true);
            app.helpMenu.Text = 'help';
            app.RocketViewAxes = uiaxes(app.UIFigure);
            title(app.RocketViewAxes, 'Rocket view')
            xlabel(app.RocketViewAxes, 'X')
            ylabel(app.RocketViewAxes, 'Y')
            zlabel(app.RocketViewAxes, 'Z')
            app.RocketViewAxes.ButtonDownFcn = createCallbackFcn(app, @RocketViewAxesButtonDown, true);
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.RocketgeometryTab = uitab(app.TabGroup2);
            app.RocketgeometryTab.Title = 'Rocket geometry';
            app.RocketpropertiesTab = uitab(app.TabGroup2);
            app.RocketpropertiesTab.Title = 'Rocket properties';
            app.RocketpropertiesTab.ButtonDownFcn = createCallbackFcn(app, @RocketpropertiesTabButtonDown, true);
            app.Image_9 = uiimage(app.RocketpropertiesTab);
            app.Image_9.Enable = 'off';
            app.TailElementCGEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.TailElementMassEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.TailElementLabel = uilabel(app.RocketpropertiesTab);
            app.TailElementLabel.Text = 'Element';
            app.TailLabel = uilabel(app.RocketpropertiesTab);
            app.TailLabel.Text = 'Tail';
            app.FuelFinalCGEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.FuelFinalMassEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.FuelFinalLabel = uilabel(app.RocketpropertiesTab);
            app.FuelFinalLabel.Text = 'Fuel Final';
            app.FuelInitialCGEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.FuelInitialMassEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.FuelInitialLabel = uilabel(app.RocketpropertiesTab);
            app.FuelInitialLabel.Text = 'Fuel Initial';
            app.FuelTankCGEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.FuelTankMassEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.FuelTankLabel = uilabel(app.RocketpropertiesTab);
            app.FuelTankLabel.Text = 'Tank';
            app.CCLabel = uilabel(app.RocketpropertiesTab);
            app.CCLabel.Text = 'C.C.';
            app.CVElementCGEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.N2OFinalCGEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.CVElementMassEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.N2OFinalMassEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.CVElementLabel = uilabel(app.RocketpropertiesTab);
            app.CVElementLabel.Text = 'Element';
            app.N2OFinalLabel = uilabel(app.RocketpropertiesTab);
            app.N2OFinalLabel.Text = 'N2O Final';
            app.CVLabel = uilabel(app.RocketpropertiesTab);
            app.CVLabel.Text = 'C.V.';
            app.N2OInitialCGEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.N2OInitialMassEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.N2OInitialLabel = uilabel(app.RocketpropertiesTab);
            app.N2OInitialLabel.Text = 'N2O Initial';
            app.N2OTankCGEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.N2OTankMassEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.N2OTankLabel = uilabel(app.RocketpropertiesTab);
            app.N2OTankLabel.Text = 'Tank';
            app.N2OLabel = uilabel(app.RocketpropertiesTab);
            app.N2OLabel.Text = 'N2O';
            app.RecoveryElementCGEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.RecoveryElementMassEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.RecoveryElementLabel = uilabel(app.RocketpropertiesTab);
            app.RecoveryElementLabel.Text = 'Element';
            app.RecoveryLabel = uilabel(app.RocketpropertiesTab);
            app.RecoveryLabel.Text = 'Recovery';
            app.CanardElementCGEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.CanardElementMassEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.CanardElementLabel = uilabel(app.RocketpropertiesTab);
            app.CanardElementLabel.Text = 'Element';
            app.CanardLabel = uilabel(app.RocketpropertiesTab);
            app.CanardLabel.Text = 'Canard';
            app.AvionicsElementCGEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.AvionicsElementMassEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.AvionicsElementMassEditField.ValueChangedFcn = createCallbackFcn(app, @AvionicsElementMassEditFieldValueChanged, true);
            app.AvionicsElementLabel = uilabel(app.RocketpropertiesTab);
            app.AvionicsElementLabel.Text = 'Element';
            app.AvionicsLabel = uilabel(app.RocketpropertiesTab);
            app.AvionicsLabel.Text = 'Avionics';
            app.NoseElementCGEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.NoseElementMassEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.NoseElementMassEditField.ValueChangedFcn = createCallbackFcn(app, @NoseElementMassEditFieldValueChanged, true);
            app.NoseElementLabel = uilabel(app.RocketpropertiesTab);
            app.NoseElementLabel.Text = 'Element';
            app.NoseLabel = uilabel(app.RocketpropertiesTab);
            app.NoseLabel.Text = 'Nose';
            app.SelectModel_Panel = uipanel(app.RocketpropertiesTab);
            app.SelectModel_Panel.Tooltip = {''};
            app.SelectModelDropDownLabel = uilabel(app.SelectModel_Panel);
            app.SelectModelDropDownLabel.Text = 'Select Model';
            app.SelectModelDropDown = uidropdown(app.SelectModel_Panel);
            app.SelectModelDropDown.Items = {'launch1', 'launch2'};
            app.SelectModelDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectModelDropDownValueChanged, true);
            app.SelectModelDropDown.Value = 'launch1';
            app.kgLabel_2 = uilabel(app.RocketpropertiesTab);
            app.kgLabel_2.Text = 'kg';
            app.kgLabel_4 = uilabel(app.RocketpropertiesTab);
            app.kgLabel_4.Text = 'kg';
            app.kgLabel = uilabel(app.RocketpropertiesTab);
            app.kgLabel.Text = 'kg';
            app.CCCombustionchamberLabel = uilabel(app.RocketpropertiesTab);
            app.CCCombustionchamberLabel.Text = '*C.C.=Combustion chamber';
            app.kgLabel_5 = uilabel(app.RocketpropertiesTab);
            app.kgLabel_5.Text = 'kg';
            app.kgLabel_9 = uilabel(app.RocketpropertiesTab);
            app.kgLabel_9.Text = 'kg';
            app.kgLabel_6 = uilabel(app.RocketpropertiesTab);
            app.kgLabel_6.Text = 'kg';
            app.kgLabel_32 = uilabel(app.RocketpropertiesTab);
            app.kgLabel_32.Text = 'kg';
            app.kgLabel_34 = uilabel(app.RocketpropertiesTab);
            app.kgLabel_34.Text = 'kg';
            app.kgLabel_40 = uilabel(app.RocketpropertiesTab);
            app.kgLabel_40.Text = 'kg';
            app.kgLabel_42 = uilabel(app.RocketpropertiesTab);
            app.kgLabel_42.Text = 'kg';
            app.kgLabel_44 = uilabel(app.RocketpropertiesTab);
            app.kgLabel_44.Text = 'kg';
            app.kgLabel_47 = uilabel(app.RocketpropertiesTab);
            app.kgLabel_47.Text = 'kg';
            app.CVConnectionVolveLabel = uilabel(app.RocketpropertiesTab);
            app.CVConnectionVolveLabel.Text = '*C.V.=Connection Volve';
            app.kgLabel_51 = uilabel(app.RocketpropertiesTab);
            app.kgLabel_51.Text = 'm';
            app.kgLabel_53 = uilabel(app.RocketpropertiesTab);
            app.kgLabel_53.Text = 'm';
            app.kgLabel_58 = uilabel(app.RocketpropertiesTab);
            app.kgLabel_58.Text = 'm';
            app.kgLabel_60 = uilabel(app.RocketpropertiesTab);
            app.kgLabel_60.Text = 'm';
            app.kgLabel_61 = uilabel(app.RocketpropertiesTab);
            app.kgLabel_61.Text = 'm';
            app.kgLabel_62 = uilabel(app.RocketpropertiesTab);
            app.kgLabel_62.Text = 'm';
            app.kgLabel_63 = uilabel(app.RocketpropertiesTab);
            app.kgLabel_63.Text = 'm';
            app.kgLabel_66 = uilabel(app.RocketpropertiesTab);
            app.kgLabel_66.Text = 'm';
            app.kgLabel_65 = uilabel(app.RocketpropertiesTab);
            app.kgLabel_65.Text = 'm';
            app.kgLabel_67 = uilabel(app.RocketpropertiesTab);
            app.kgLabel_67.Text = 'm';
            app.kgLabel_68 = uilabel(app.RocketpropertiesTab);
            app.kgLabel_68.Text = 'm';
            app.kgLabel_69 = uilabel(app.RocketpropertiesTab);
            app.kgLabel_69.Text = 'm';
            app.MassCGpartmassCGLabel = uilabel(app.RocketpropertiesTab);
            app.MassCGpartmassCGLabel.Text = 'Mass&CG:part(mass,CG)';
            app.Image_10 = uiimage(app.RocketpropertiesTab);
            app.Image_10.Enable = 'off';
            app.NoseElementInertiaEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.NoseElementInertiaEditField_2 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.NoseElementInertiaEditField_3 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.NoseElementInertiaEditField_4 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.NoseElementInertiaEditField_5 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.NoseElementInertiaEditField_6 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.NoseElementInertiaEditField_7 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.NoseElementInertiaEditField_8 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.NoseLabel_2 = uilabel(app.RocketpropertiesTab);
            app.NoseLabel_2.Text = 'Nose';
            app.NoseElementInertiaEditField_9 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.NoseElementLabel_2 = uilabel(app.RocketpropertiesTab);
            app.NoseElementLabel_2.Text = 'Element';
            app.AvionicsElementInertiaEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.AvionicsElementInertiaEditField_2 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.AvionicsElementInertiaEditField_3 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.AvionicsElementInertiaEditField_4 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.AvionicsElementInertiaEditField_5 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.AvionicsElementInertiaEditField_6 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.AvionicsElementInertiaEditField_7 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.AvionicsElementInertiaEditField_8 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.AvionicsLabel_2 = uilabel(app.RocketpropertiesTab);
            app.AvionicsLabel_2.Text = 'Avionics';
            app.AvionicsElementInertiaEditField_9 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.AvionicsElementLabel_2 = uilabel(app.RocketpropertiesTab);
            app.AvionicsElementLabel_2.Text = 'Element';
            app.CanardElementInertiaEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.CanardElementInertiaEditField_2 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.CanardElementInertiaEditField_3 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.CanardElementInertiaEditField_4 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.CanardElementInertiaEditField_5 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.CanardElementInertiaEditField_6 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.CanardElementInertiaEditField_7 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.CanardElementInertiaEditField_8 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.CanardLabel_2 = uilabel(app.RocketpropertiesTab);
            app.CanardLabel_2.Text = 'Canard';
            app.CanardElementInertiaEditField_9 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.CanardElementLabel_2 = uilabel(app.RocketpropertiesTab);
            app.CanardElementLabel_2.Text = 'Element';
            app.RecoveryElementInertiaEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.RecoveryElementInertiaEditField_2 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.RecoveryElementInertiaEditField_3 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.RecoveryElementInertiaEditField_4 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.RecoveryElementInertiaEditField_5 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.RecoveryElementInertiaEditField_6 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.RecoveryElementInertiaEditField_7 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.RecoveryElementInertiaEditField_8 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.RecoveryElementInertiaEditField_9 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.RecoveryElementLabel_2 = uilabel(app.RocketpropertiesTab);
            app.RecoveryElementLabel_2.Text = 'Element';
            app.RecoveryLabel_2 = uilabel(app.RocketpropertiesTab);
            app.RecoveryLabel_2.Text = 'Recovery';
            app.N2OFinalInertiaEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.N2OFinalInertiaEditField_2 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.N2OFinalInertiaEditField_3 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.N2OFinalInertiaEditField_4 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.N2OFinalInertiaEditField_5 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.N2OFinalInertiaEditField_6 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.N2OFinalInertiaEditField_7 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.N2OFinalInertiaEditField_8 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.N2OInitialInertiaEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.N2OInitialInertiaEditField_2 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.N2OInitialInertiaEditField_3 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.N2OInitialInertiaEditField_4 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.N2OInitialInertiaEditField_5 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.N2OInitialInertiaEditField_6 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.N2OInitialInertiaEditField_7 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.N2OInitialInertiaEditField_8 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.N2OTankInertiaEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.N2OTankInertiaEditField_2 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.N2OTankInertiaEditField_3 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.N2OTankInertiaEditField_4 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.N2OTankInertiaEditField_5 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.N2OTankInertiaEditField_6 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.N2OTankInertiaEditField_7 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.N2OTankInertiaEditField_8 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.N2OLabel_2 = uilabel(app.RocketpropertiesTab);
            app.N2OLabel_2.Text = 'N2O';
            app.N2OTankInertiaEditField_9 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.N2OTankLabel_2 = uilabel(app.RocketpropertiesTab);
            app.N2OTankLabel_2.Text = 'Tank';
            app.N2OFinalInertiaEditField_9 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.N2OFinalLabel_2 = uilabel(app.RocketpropertiesTab);
            app.N2OFinalLabel_2.Text = 'N2O Final';
            app.N2OInitialInertiaEditField_9 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.N2OInitialLabel_2 = uilabel(app.RocketpropertiesTab);
            app.N2OInitialLabel_2.Text = 'N2O Initial';
            app.CVElementInertiaEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.CVElementInertiaEditField_2 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.CVElementInertiaEditField_3 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.CVElementInertiaEditField_4 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.CVElementInertiaEditField_5 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.CVElementInertiaEditField_6 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.CVElementInertiaEditField_7 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.CVElementInertiaEditField_8 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.CVLabel_2 = uilabel(app.RocketpropertiesTab);
            app.CVLabel_2.Text = 'C.V.';
            app.CVConnectionVolveLabel_2 = uilabel(app.RocketpropertiesTab);
            app.CVConnectionVolveLabel_2.Text = '*C.V.=Connection Volve';
            app.CVElementLabel_2 = uilabel(app.RocketpropertiesTab);
            app.CVElementLabel_2.Text = 'Element';
            app.CVElementInertiaEditField_9 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.FuelFinalInertiaEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.FuelFinalInertiaEditField_2 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.FuelFinalInertiaEditField_3 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.FuelFinalInertiaEditField_4 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.FuelFinalInertiaEditField_5 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.FuelFinalInertiaEditField_6 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.FuelFinalInertiaEditField_7 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.FuelFinalInertiaEditField_8 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.FuelInitialInertiaEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.FuelInitialInertiaEditField_2 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.FuelInitialInertiaEditField_3 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.FuelInitialInertiaEditField_4 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.FuelInitialInertiaEditField_5 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.FuelInitialInertiaEditField_6 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.FuelInitialInertiaEditField_7 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.FuelInitialInertiaEditField_8 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.FuelTankInertiaEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.FuelTankInertiaEditField_2 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.FuelTankInertiaEditField_3 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.FuelTankInertiaEditField_4 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.FuelTankInertiaEditField_5 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.FuelTankInertiaEditField_6 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.FuelTankInertiaEditField_7 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.FuelTankInertiaEditField_8 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.CCLabel_2 = uilabel(app.RocketpropertiesTab);
            app.CCLabel_2.Text = 'C.C.';
            app.CCCombustionchamberLabel_2 = uilabel(app.RocketpropertiesTab);
            app.CCCombustionchamberLabel_2.Text = '*C.C.=Combustion chamber';
            app.FuelTankInertiaEditField_9 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.FuelTankLabel_2 = uilabel(app.RocketpropertiesTab);
            app.FuelTankLabel_2.Text = 'Tank';
            app.FuelFinalInertiaEditField_9 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.FuelFinalLabel_2 = uilabel(app.RocketpropertiesTab);
            app.FuelFinalLabel_2.Text = 'Fuel Final';
            app.FuelInitialInertiaEditField_9 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.FuelInitialLabel_2 = uilabel(app.RocketpropertiesTab);
            app.FuelInitialLabel_2.Text = 'Fuel Initial';
            app.TailElementInertiaEditField = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.TailElementInertiaEditField_2 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.TailElementInertiaEditField_3 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.TailElementInertiaEditField_4 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.TailElementInertiaEditField_5 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.TailElementInertiaEditField_6 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.TailElementInertiaEditField_7 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.TailElementInertiaEditField_8 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.TailLabel_2 = uilabel(app.RocketpropertiesTab);
            app.TailLabel_2.Text = 'Tail';
            app.TailElementInertiaEditField_9 = uieditfield(app.RocketpropertiesTab, 'numeric');
            app.TailElementLabel_2 = uilabel(app.RocketpropertiesTab);
            app.TailElementLabel_2.Text = 'Element';
            app.InertiaILabel = uilabel(app.RocketpropertiesTab);
            app.InertiaILabel.Text = {'Inertia:part(Ixx,Ixy,Ixz'; 'Iyx,Iyy,Iyz'; 'Izx,Izy,Izz)'};
            app.CGDistancefromnoseLabel = uilabel(app.RocketpropertiesTab);
            app.CGDistancefromnoseLabel.Text = '*CG=Distance from nose';
            app.SaveButton = uibutton(app.RocketpropertiesTab, 'push');
            app.SaveButton.Text = 'Save';
            app.kgm2Label_11 = uilabel(app.RocketpropertiesTab);
            app.kgm2Label_11.Text = 'kg/m^2';
            app.kgm2Label_12 = uilabel(app.RocketpropertiesTab);
            app.kgm2Label_12.Text = 'kg/m^2';
            app.kgm2Label_13 = uilabel(app.RocketpropertiesTab);
            app.kgm2Label_13.Text = 'kg/m^2';
            app.kgm2Label_14 = uilabel(app.RocketpropertiesTab);
            app.kgm2Label_14.Text = 'kg/m^2';
            app.kgm2Label_15 = uilabel(app.RocketpropertiesTab);
            app.kgm2Label_15.Text = 'kg/m^2';
            app.kgm2Label_16 = uilabel(app.RocketpropertiesTab);
            app.kgm2Label_16.Text = 'kg/m^2';
            app.kgm2Label_17 = uilabel(app.RocketpropertiesTab);
            app.kgm2Label_17.Text = 'kg/m^2';
            app.kgm2Label_18 = uilabel(app.RocketpropertiesTab);
            app.kgm2Label_18.Text = 'kg/m^2';
            app.kgm2Label_19 = uilabel(app.RocketpropertiesTab);
            app.kgm2Label_19.Text = 'kg/m^2';
            app.kgm2Label_20 = uilabel(app.RocketpropertiesTab);
            app.kgm2Label_20.Text = 'kg/m^2';
            app.kgm2Label_21 = uilabel(app.RocketpropertiesTab);
            app.kgm2Label_21.Text = 'kg/m^2';
            app.kgm2Label_22 = uilabel(app.RocketpropertiesTab);
            app.kgm2Label_22.Text = 'kg/m^2';
            app.ThrustcurveTab = uitab(app.TabGroup2);
            app.ThrustcurveTab.Title = 'Thrust curve';
            app.ThrustcurveTab.ButtonDownFcn = createCallbackFcn(app, @ThrustcurveTabButtonDown, true);
            app.ThrustTimeAxes = uiaxes(app.ThrustcurveTab);
            title(app.ThrustTimeAxes, 'Thrust - Time Curve Plot')
            xlabel(app.ThrustTimeAxes, 'Time(s)')
            ylabel(app.ThrustTimeAxes, 'Thrust(kgf)')
            zlabel(app.ThrustTimeAxes, 'Z')
            app.ThrustTimeCurvePanel = uipanel(app.ThrustcurveTab);
            app.ThrustTimeCurvePanel.Title = 'Thrust - Time Curve';
            app.T3EditFieldLabel = uilabel(app.ThrustTimeCurvePanel);
            app.T3EditFieldLabel.Text = 'T3';
            app.T3EditField = uieditfield(app.ThrustTimeCurvePanel, 'numeric');
            app.T3EditField.ValueChangedFcn = createCallbackFcn(app, @T3EditFieldValueChanged, true);
            app.T3EditField.Value = 5.7;
            app.sLabel_4 = uilabel(app.ThrustTimeCurvePanel);
            app.sLabel_4.Text = 's';
            app.T2EditFieldLabel = uilabel(app.ThrustTimeCurvePanel);
            app.T2EditFieldLabel.Text = 'T2';
            app.T2EditField = uieditfield(app.ThrustTimeCurvePanel, 'numeric');
            app.T2EditField.ValueChangedFcn = createCallbackFcn(app, @T2EditFieldValueChanged, true);
            app.T2EditField.Value = 4.7;
            app.sLabel_3 = uilabel(app.ThrustTimeCurvePanel);
            app.sLabel_3.Text = 's';
            app.T1EditFieldLabel = uilabel(app.ThrustTimeCurvePanel);
            app.T1EditFieldLabel.Text = 'T1';
            app.T1EditField = uieditfield(app.ThrustTimeCurvePanel, 'numeric');
            app.T1EditField.ValueChangedFcn = createCallbackFcn(app, @T1EditFieldValueChanged, true);
            app.T1EditField.Value = 0.2;
            app.sLabel_2 = uilabel(app.ThrustTimeCurvePanel);
            app.sLabel_2.Text = 's';
            app.T0EditFieldLabel = uilabel(app.ThrustTimeCurvePanel);
            app.T0EditFieldLabel.Text = 'T0';
            app.T0EditField = uieditfield(app.ThrustTimeCurvePanel, 'numeric');
            app.T0EditField.ValueChangedFcn = createCallbackFcn(app, @T0EditFieldValueChanged, true);
            app.sLabel = uilabel(app.ThrustTimeCurvePanel);
            app.sLabel.Text = 's';
            app.kgfLabel_5 = uilabel(app.ThrustTimeCurvePanel);
            app.kgfLabel_5.Text = 'kgf';
            app.T3EditField_2Label = uilabel(app.ThrustTimeCurvePanel);
            app.T3EditField_2Label.Text = 'T3';
            app.T3EditField_2 = uieditfield(app.ThrustTimeCurvePanel, 'numeric');
            app.T3EditField_2.ValueChangedFcn = createCallbackFcn(app, @T3EditField_2ValueChanged, true);
            app.T3EditField_2.Value = 400;
            app.T2EditField_2Label = uilabel(app.ThrustTimeCurvePanel);
            app.T2EditField_2Label.Text = 'T2';
            app.T2EditField_2 = uieditfield(app.ThrustTimeCurvePanel, 'numeric');
            app.T2EditField_2.ValueChangedFcn = createCallbackFcn(app, @T2EditField_2ValueChanged, true);
            app.T2EditField_2.Value = 1100;
            app.kgfLabel_6 = uilabel(app.ThrustTimeCurvePanel);
            app.kgfLabel_6.Text = 'kgf';
            app.T1EditField_2Label = uilabel(app.ThrustTimeCurvePanel);
            app.T1EditField_2Label.Text = 'T1';
            app.T1EditField_2 = uieditfield(app.ThrustTimeCurvePanel, 'numeric');
            app.T1EditField_2.ValueChangedFcn = createCallbackFcn(app, @T1EditField_2ValueChanged, true);
            app.T1EditField_2.Value = 1300;
            app.kgfLabel_7 = uilabel(app.ThrustTimeCurvePanel);
            app.kgfLabel_7.Text = 'kgf';
            app.T0EditField_2Label = uilabel(app.ThrustTimeCurvePanel);
            app.T0EditField_2Label.Text = 'T0';
            app.T0EditField_2 = uieditfield(app.ThrustTimeCurvePanel, 'numeric');
            app.T0EditField_2.ValueChangedFcn = createCallbackFcn(app, @T0EditField_2ValueChanged, true);
            app.kgfLabel_8 = uilabel(app.ThrustTimeCurvePanel);
            app.kgfLabel_8.Text = 'kgf';
            app.sLabel_5 = uilabel(app.ThrustTimeCurvePanel);
            app.sLabel_5.Text = 's';
            app.T4EditFieldLabel = uilabel(app.ThrustTimeCurvePanel);
            app.T4EditFieldLabel.Text = 'T4';
            app.T4EditField = uieditfield(app.ThrustTimeCurvePanel, 'numeric');
            app.T4EditField.ValueChangedFcn = createCallbackFcn(app, @T4EditFieldValueChanged, true);
            app.T4EditField.Value = 9.3;
            app.kgfLabel_9 = uilabel(app.ThrustTimeCurvePanel);
            app.kgfLabel_9.Text = 'kgf';
            app.T4EditField_2Label = uilabel(app.ThrustTimeCurvePanel);
            app.T4EditField_2Label.Text = 'T4';
            app.T4EditField_2 = uieditfield(app.ThrustTimeCurvePanel, 'numeric');
            app.T4EditField_2.ValueChangedFcn = createCallbackFcn(app, @T4EditField_2ValueChanged, true);
            app.SelectModel_Panel_3 = uipanel(app.ThrustcurveTab);
            app.SelectModel_Panel_3.Tooltip = {''};
            app.SelectModelDropDown_3Label = uilabel(app.SelectModel_Panel_3);
            app.SelectModelDropDown_3Label.Enable = 'off';
            app.SelectModelDropDown_3Label.Text = 'Select Model';
            app.SelectModelDropDown_3 = uidropdown(app.SelectModel_Panel_3);
            app.SelectModelDropDown_3.Items = {'', 'ver2023', 'ver2024'};
            app.SelectModelDropDown_3.Enable = 'off';
            app.SelectModelDropDown_3.Value = '';
            app.FlightSimulationTab = uitab(app.TabGroup2);
            app.FlightSimulationTab.Title = 'Flight Simulation';
            app.FlightSimulationTab.ButtonDownFcn = createCallbackFcn(app, @FlightSimulationTabButtonDown, true);
            app.InitialconditionPanel = uipanel(app.FlightSimulationTab);
            app.InitialconditionPanel.Title = 'Initial condition';
            app.mLabel = uilabel(app.InitialconditionPanel);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.InitialconditionPanel);
            app.mLabel_2.Text = 'm';
            app.mLabel_3 = uilabel(app.InitialconditionPanel);
            app.mLabel_3.Text = 'm';
            app.zEditField = uieditfield(app.InitialconditionPanel, 'numeric');
            app.zEditField.Value = 1.7;
            app.zEditFieldLabel = uilabel(app.InitialconditionPanel);
            app.zEditFieldLabel.Text = 'z';
            app.yEditField = uieditfield(app.InitialconditionPanel, 'numeric');
            app.yEditFieldLabel = uilabel(app.InitialconditionPanel);
            app.yEditFieldLabel.Text = 'y';
            app.RocketCGpositionxEditField = uieditfield(app.InitialconditionPanel, 'numeric');
            app.RocketCGpositionxEditFieldLabel = uilabel(app.InitialconditionPanel);
            app.RocketCGpositionxEditFieldLabel.Text = 'Rocket CG position x';
            app.degreeLabel = uilabel(app.InitialconditionPanel);
            app.degreeLabel.Text = 'degree';
            app.LaunchangleEditFieldLabel = uilabel(app.InitialconditionPanel);
            app.LaunchangleEditFieldLabel.Text = 'Launch angle';
            app.LaunchangleEditField = uieditfield(app.InitialconditionPanel, 'numeric');
            app.LaunchangleEditField.Value = 85;
            app.degreeLabel_2 = uilabel(app.InitialconditionPanel);
            app.degreeLabel_2.Text = 'degree';
            app.LaunchdirectionN0EditField = uieditfield(app.InitialconditionPanel, 'numeric');
            app.LaunchdirectionN0EditField.Value = 90;
            app.LaunchdirectionN0EditFieldLabel = uilabel(app.InitialconditionPanel);
            app.LaunchdirectionN0EditFieldLabel.Text = 'Launch direction(N=0)';
            app.WhichrocketDropDownLabel = uilabel(app.InitialconditionPanel);
            app.WhichrocketDropDownLabel.Enable = 'off';
            app.WhichrocketDropDownLabel.Text = 'Which rocket';
            app.WhichrocketDropDown = uidropdown(app.InitialconditionPanel);
            app.WhichrocketDropDown.Items = {'ver2023', 'ver2024'};
            app.WhichrocketDropDown.Enable = 'off';
            app.WhichrocketDropDown.Value = 'ver2023';
            app.mLabel_4 = uilabel(app.InitialconditionPanel);
            app.mLabel_4.Text = 'm';
            app.LaunchraillengthEditField = uieditfield(app.InitialconditionPanel, 'numeric');
            app.LaunchraillengthEditField.Value = 13;
            app.LaunchraillengthEditFieldLabel = uilabel(app.InitialconditionPanel);
            app.LaunchraillengthEditFieldLabel.Text = 'Launch rail length';
            app.Run6DOFButton = uibutton(app.FlightSimulationTab, 'push');
            app.Run6DOFButton.ButtonPushedFcn = createCallbackFcn(app, @Run6DOFButtonPushed, true);
            app.Run6DOFButton.Text = 'Run 6DOF';
            app.EnvironmentconditionPanel = uipanel(app.FlightSimulationTab);
            app.EnvironmentconditionPanel.Title = 'Environment condition';
            app.WindgustCheckBox = uicheckbox(app.EnvironmentconditionPanel);
            app.WindgustCheckBox.Text = 'Wind gust';
            app.WinddataDropDown = uidropdown(app.EnvironmentconditionPanel);
            app.WinddataDropDown.Items = {'20200701', '20200702', '20200703', '20200704', '20200705', '20200706', '20200707', '20200708', '20200709', '20200710', '20200711', '20200712', '20200713', '20200714', '20200715', '20200716', '20200717', '20200718', '20200719', '20200720', '20200721', '20200722', '20200723', '20200724', '20200725', '20200726', '20200727', '20200728', '20200729', '20200730'};
            app.WinddataDropDown.Value = '20200701';
            app.WinddataDropDownLabel = uilabel(app.EnvironmentconditionPanel);
            app.WinddataDropDownLabel.Text = 'Wind data date';
            app.WindfieldCheckBox = uicheckbox(app.EnvironmentconditionPanel);
            app.WindfieldCheckBox.Text = 'Wind field';
            app.ControllerconditionPanel = uipanel(app.FlightSimulationTab);
            app.ControllerconditionPanel.Title = 'Controller condition';
            app.RollcontrolCheckBox = uicheckbox(app.ControllerconditionPanel);
            app.RollcontrolCheckBox.Text = 'Roll control';
            app.PitchyawcontrolCheckBox = uicheckbox(app.ControllerconditionPanel);
            app.PitchyawcontrolCheckBox.Text = 'Pitch/yaw control';
            app.SimulationconditionPanel = uipanel(app.FlightSimulationTab);
            app.SimulationconditionPanel.Title = 'Simulation condition';
            app.secLabel = uilabel(app.SimulationconditionPanel);
            app.secLabel.Text = 'sec';
            app.secLabel_2 = uilabel(app.SimulationconditionPanel);
            app.secLabel_2.Text = 'sec';
            app.SamplingtimeEditFieldLabel = uilabel(app.SimulationconditionPanel);
            app.SamplingtimeEditFieldLabel.Text = 'Sampling time';
            app.SamplingtimeEditField = uieditfield(app.SimulationconditionPanel, 'numeric');
            app.SamplingtimeEditField.Value = 0.01;
            app.StoptimeEditFieldLabel = uilabel(app.SimulationconditionPanel);
            app.StoptimeEditFieldLabel.Text = 'Stop time';
            app.StoptimeEditField = uieditfield(app.SimulationconditionPanel, 'numeric');
            app.StoptimeEditField.Value = 60;
            app.runtimeEditFieldLabel = uilabel(app.FlightSimulationTab);
            app.runtimeEditFieldLabel.Text = 'run time';
            app.runtimeEditField = uieditfield(app.FlightSimulationTab, 'numeric');
            app.runtimeEditField.Limits = [1 10000];
            app.runtimeEditField.Value = 1;
            app.DownloaddataButton = uibutton(app.FlightSimulationTab, 'push');
            app.DownloaddataButton.ButtonPushedFcn = createCallbackFcn(app, @DownloaddataButtonPushed, true);
            app.DownloaddataButton.Text = 'Download data';
            app.TabGroup = uitabgroup(app.FlightSimulationTab);
            app.PositionTab = uitab(app.TabGroup);
            app.PositionTab.Title = 'Position';
            app.PositionUIAxes = uiaxes(app.PositionTab);
            title(app.PositionUIAxes, 'Position')
            xlabel(app.PositionUIAxes, 't(s)')
            ylabel(app.PositionUIAxes, 'Distance(m)')
            zlabel(app.PositionUIAxes, 'Z')
            app.AngularVelocityTab = uitab(app.TabGroup);
            app.AngularVelocityTab.Title = 'Angular Velocity';
            app.AngularVUIAxes = uiaxes(app.AngularVelocityTab);
            title(app.AngularVUIAxes, 'Angular velocity(rad/s)')
            xlabel(app.AngularVUIAxes, 't(s)')
            ylabel(app.AngularVUIAxes, 'Angular Velocity(rad/s)')
            zlabel(app.AngularVUIAxes, 'Z')
            app.AOAAOSTab = uitab(app.TabGroup);
            app.AOAAOSTab.Title = 'AOA/AOS';
            app.AOA_AOSUIAxes = uiaxes(app.AOAAOSTab);
            title(app.AOA_AOSUIAxes, 'Title')
            xlabel(app.AOA_AOSUIAxes, 't(s)')
            ylabel(app.AOA_AOSUIAxes, 'AOA/AOS(degree)')
            zlabel(app.AOA_AOSUIAxes, 'Z')
            app.CPCGStabilityTab = uitab(app.TabGroup);
            app.CPCGStabilityTab.Title = 'CP/CG/Stability';
            app.StabilityUIAxes = uiaxes(app.CPCGStabilityTab);
            title(app.StabilityUIAxes, 'CP(m)/CG(m)/Stability(caliber)')
            xlabel(app.StabilityUIAxes, 't(s)')
            zlabel(app.StabilityUIAxes, 'Z')
            app.AttitudeTab = uitab(app.TabGroup);
            app.AttitudeTab.Title = 'Attitude';
            app.AttitudeUIAxes = uiaxes(app.AttitudeTab);
            title(app.AttitudeUIAxes, 'Roll/Pitch/Yaw(degree)')
            xlabel(app.AttitudeUIAxes, 't(s)')
            ylabel(app.AttitudeUIAxes, 'Angle(degree)')
            zlabel(app.AttitudeUIAxes, 'Z')
            app.MachTab = uitab(app.TabGroup);
            app.MachTab.Title = 'Mach';
            app.MachUIAxes = uiaxes(app.MachTab);
            title(app.MachUIAxes, 'Mach number')
            xlabel(app.MachUIAxes, 't(s)')
            zlabel(app.MachUIAxes, 'Z')
            app.StageTab = uitab(app.TabGroup);
            app.StageTab.Title = 'Stage';
            app.StageUIAxes = uiaxes(app.StageTab);
            title(app.StageUIAxes, 'Stage')
            xlabel(app.StageUIAxes, 't(s)')
            zlabel(app.StageUIAxes, 'Z')
            app.VelocityBodyTab = uitab(app.TabGroup);
            app.VelocityBodyTab.Title = 'Velocity(Body)';
            app.VelocityUIAxes = uiaxes(app.VelocityBodyTab);
            title(app.VelocityUIAxes, 'Velocity(m/s)')
            xlabel(app.VelocityUIAxes, 't(s)')
            ylabel(app.VelocityUIAxes, 'Velocity(m/s)')
            zlabel(app.VelocityUIAxes, 'Z')
            app.MassTab = uitab(app.TabGroup);
            app.MassTab.Title = 'Mass';
            app.MassUIAxes = uiaxes(app.MassTab);
            title(app.MassUIAxes, 'Mass(KG)')
            xlabel(app.MassUIAxes, 't(s)')
            ylabel(app.MassUIAxes, 'Mass(KG)')
            zlabel(app.MassUIAxes, 'Z')
            app.ThrustTab = uitab(app.TabGroup);
            app.ThrustTab.Title = 'Thrust';
            app.dynamicpressureTab = uitab(app.TabGroup);
            app.dynamicpressureTab.Title = 'dynamic pressure';
            app.QUIAxes = uiaxes(app.dynamicpressureTab);
            title(app.QUIAxes, 'Dynamic pressure Q')
            xlabel(app.QUIAxes, 't(s)')
            ylabel(app.QUIAxes, 'dynamic pressure(kg-m/s^2)')
            zlabel(app.QUIAxes, 'Z')
            app.EnvironmentConditionTab = uitab(app.TabGroup2);
            app.EnvironmentConditionTab.Title = 'Environment Condition';
            app.ControllerdesignTab = uitab(app.TabGroup2);
            app.ControllerdesignTab.Tooltip = {''};
            app.ControllerdesignTab.Title = 'Controller design';
            app.UIAxes = uiaxes(app.ControllerdesignTab);
            title(app.UIAxes, 'Bode plot')
            xlabel(app.UIAxes, 'rad/s')
            ylabel(app.UIAxes, 'dB')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.ControllerdesignTab);
            title(app.UIAxes_2, 'Time response')
            xlabel(app.UIAxes_2, 'time(s)')
            ylabel(app.UIAxes_2, 'degree')
            zlabel(app.UIAxes_2, 'Z')
            app.ControllerDropDownLabel = uilabel(app.ControllerdesignTab);
            app.ControllerDropDownLabel.Text = 'Controller';
            app.ControllerDropDown = uidropdown(app.ControllerdesignTab);
            app.ControllerDropDown.Items = {'PID', 'Phase-Lead/Lag', 'Hinf', 'Optimal'};
            app.ControllerDropDown.Value = 'PID';
            app.LinearsystemparametersPanel = uipanel(app.ControllerdesignTab);
            app.LinearsystemparametersPanel.Title = 'Linear system parameters';
            app.massEditFieldLabel = uilabel(app.LinearsystemparametersPanel);
            app.massEditFieldLabel.Text = 'mass';
            app.massEditField = uieditfield(app.LinearsystemparametersPanel, 'numeric');
            app.kgLabel_133 = uilabel(app.LinearsystemparametersPanel);
            app.kgLabel_133.Text = 'kg';
            app.massEditFieldLabel_2 = uilabel(app.LinearsystemparametersPanel);
            app.massEditFieldLabel_2.Text = 'p';
            app.massEditField_2 = uieditfield(app.LinearsystemparametersPanel, 'numeric');
            app.radsLabel = uilabel(app.LinearsystemparametersPanel);
            app.radsLabel.Text = 'rad/s';
            app.massEditFieldLabel_3 = uilabel(app.LinearsystemparametersPanel);
            app.massEditFieldLabel_3.Text = 'q';
            app.massEditField_3 = uieditfield(app.LinearsystemparametersPanel, 'numeric');
            app.radsLabel_2 = uilabel(app.LinearsystemparametersPanel);
            app.radsLabel_2.Text = 'rad/s';
            app.massEditFieldLabel_4 = uilabel(app.LinearsystemparametersPanel);
            app.massEditFieldLabel_4.Text = 'r';
            app.massEditField_4 = uieditfield(app.LinearsystemparametersPanel, 'numeric');
            app.radsLabel_3 = uilabel(app.LinearsystemparametersPanel);
            app.radsLabel_3.Text = 'rad/s';
            app.massEditFieldLabel_5 = uilabel(app.LinearsystemparametersPanel);
            app.massEditFieldLabel_5.Text = 'u';
            app.massEditField_5 = uieditfield(app.LinearsystemparametersPanel, 'numeric');
            app.msLabel = uilabel(app.LinearsystemparametersPanel);
            app.msLabel.Text = 'm/s';
            app.massEditFieldLabel_6 = uilabel(app.LinearsystemparametersPanel);
            app.massEditFieldLabel_6.Text = 'v';
            app.massEditField_6 = uieditfield(app.LinearsystemparametersPanel, 'numeric');
            app.msLabel_2 = uilabel(app.LinearsystemparametersPanel);
            app.msLabel_2.Text = 'm/s';
            app.massEditFieldLabel_7 = uilabel(app.LinearsystemparametersPanel);
            app.massEditFieldLabel_7.Text = 'w';
            app.massEditField_7 = uieditfield(app.LinearsystemparametersPanel, 'numeric');
            app.msLabel_3 = uilabel(app.LinearsystemparametersPanel);
            app.msLabel_3.Text = 'm/s';
            app.massEditFieldLabel_8 = uilabel(app.LinearsystemparametersPanel);
            app.massEditFieldLabel_8.Text = 'Ixx';
            app.massEditField_8 = uieditfield(app.LinearsystemparametersPanel, 'numeric');
            app.kgm2Label = uilabel(app.LinearsystemparametersPanel);
            app.kgm2Label.Text = 'kg-m^2';
            app.inertiaLabel = uilabel(app.LinearsystemparametersPanel);
            app.inertiaLabel.Text = 'inertia';
            app.massEditFieldLabel_9 = uilabel(app.LinearsystemparametersPanel);
            app.massEditFieldLabel_9.Text = 'Ixy';
            app.massEditField_9 = uieditfield(app.LinearsystemparametersPanel, 'numeric');
            app.kgm2Label_2 = uilabel(app.LinearsystemparametersPanel);
            app.kgm2Label_2.Text = 'kg-m^2';
            app.massEditFieldLabel_10 = uilabel(app.LinearsystemparametersPanel);
            app.massEditFieldLabel_10.Text = 'Ixz';
            app.massEditField_10 = uieditfield(app.LinearsystemparametersPanel, 'numeric');
            app.kgm2Label_3 = uilabel(app.LinearsystemparametersPanel);
            app.kgm2Label_3.Text = 'kg-m^2';
            app.massEditFieldLabel_11 = uilabel(app.LinearsystemparametersPanel);
            app.massEditFieldLabel_11.Text = 'Iyx';
            app.massEditField_11 = uieditfield(app.LinearsystemparametersPanel, 'numeric');
            app.kgm2Label_4 = uilabel(app.LinearsystemparametersPanel);
            app.kgm2Label_4.Text = 'kg-m^2';
            app.massEditFieldLabel_12 = uilabel(app.LinearsystemparametersPanel);
            app.massEditFieldLabel_12.Text = 'Iyx';
            app.massEditField_12 = uieditfield(app.LinearsystemparametersPanel, 'numeric');
            app.kgm2Label_5 = uilabel(app.LinearsystemparametersPanel);
            app.kgm2Label_5.Text = 'kg-m^2';
            app.massEditFieldLabel_13 = uilabel(app.LinearsystemparametersPanel);
            app.massEditFieldLabel_13.Text = 'Iyy';
            app.massEditField_13 = uieditfield(app.LinearsystemparametersPanel, 'numeric');
            app.kgm2Label_6 = uilabel(app.LinearsystemparametersPanel);
            app.kgm2Label_6.Text = 'kg-m^2';
            app.massEditFieldLabel_14 = uilabel(app.LinearsystemparametersPanel);
            app.massEditFieldLabel_14.Text = 'Iyz';
            app.massEditField_14 = uieditfield(app.LinearsystemparametersPanel, 'numeric');
            app.kgm2Label_7 = uilabel(app.LinearsystemparametersPanel);
            app.kgm2Label_7.Text = 'kg-m^2';
            app.massEditFieldLabel_15 = uilabel(app.LinearsystemparametersPanel);
            app.massEditFieldLabel_15.Text = 'Izx';
            app.massEditField_15 = uieditfield(app.LinearsystemparametersPanel, 'numeric');
            app.kgm2Label_8 = uilabel(app.LinearsystemparametersPanel);
            app.kgm2Label_8.Text = 'kg-m^2';
            app.massEditFieldLabel_16 = uilabel(app.LinearsystemparametersPanel);
            app.massEditFieldLabel_16.Text = 'Izy';
            app.massEditField_16 = uieditfield(app.LinearsystemparametersPanel, 'numeric');
            app.kgm2Label_9 = uilabel(app.LinearsystemparametersPanel);
            app.kgm2Label_9.Text = 'kg-m^2';
            app.massEditFieldLabel_17 = uilabel(app.LinearsystemparametersPanel);
            app.massEditFieldLabel_17.Text = 'Izz';
            app.massEditField_17 = uieditfield(app.LinearsystemparametersPanel, 'numeric');
            app.kgm2Label_10 = uilabel(app.LinearsystemparametersPanel);
            app.kgm2Label_10.Text = 'kg-m^2';
            app.SymmetricCheckBox = uicheckbox(app.LinearsystemparametersPanel);
            app.SymmetricCheckBox.Tooltip = {'if symmetric, Ixy=Iyx, Ixz=Izx ,ets.'};
            app.SymmetricCheckBox.Text = 'Symmetric';
            app.massEditFieldLabel_18 = uilabel(app.LinearsystemparametersPanel);
            app.massEditFieldLabel_18.Text = 'CA';
            app.massEditField_18 = uieditfield(app.LinearsystemparametersPanel, 'numeric');
            app.massEditFieldLabel_19 = uilabel(app.LinearsystemparametersPanel);
            app.massEditFieldLabel_19.Text = 'CN';
            app.massEditField_19 = uieditfield(app.LinearsystemparametersPanel, 'numeric');
            app.massEditFieldLabel_20 = uilabel(app.LinearsystemparametersPanel);
            app.massEditFieldLabel_20.Text = 'CY';
            app.massEditField_20 = uieditfield(app.LinearsystemparametersPanel, 'numeric');
            app.massEditFieldLabel_21 = uilabel(app.LinearsystemparametersPanel);
            app.massEditFieldLabel_21.Text = 'CNa';
            app.massEditField_21 = uieditfield(app.LinearsystemparametersPanel, 'numeric');
            app.massEditFieldLabel_22 = uilabel(app.LinearsystemparametersPanel);
            app.massEditFieldLabel_22.Text = 'CYb';
            app.massEditField_22 = uieditfield(app.LinearsystemparametersPanel, 'numeric');
            app.massEditFieldLabel_23 = uilabel(app.LinearsystemparametersPanel);
            app.massEditFieldLabel_23.Text = 'CMad';
            app.massEditField_23 = uieditfield(app.LinearsystemparametersPanel, 'numeric');
            app.massEditFieldLabel_24 = uilabel(app.LinearsystemparametersPanel);
            app.massEditFieldLabel_24.Text = 'CM';
            app.massEditField_24 = uieditfield(app.LinearsystemparametersPanel, 'numeric');
            app.massEditFieldLabel_25 = uilabel(app.LinearsystemparametersPanel);
            app.massEditFieldLabel_25.Text = 'CLL';
            app.massEditField_25 = uieditfield(app.LinearsystemparametersPanel, 'numeric');
            app.massEditFieldLabel_26 = uilabel(app.LinearsystemparametersPanel);
            app.massEditFieldLabel_26.Text = 'CNad';
            app.massEditField_26 = uieditfield(app.LinearsystemparametersPanel, 'numeric');
            app.massEditFieldLabel_27 = uilabel(app.LinearsystemparametersPanel);
            app.massEditFieldLabel_27.Text = 'CNq';
            app.massEditField_27 = uieditfield(app.LinearsystemparametersPanel, 'numeric');
            app.massEditFieldLabel_28 = uilabel(app.LinearsystemparametersPanel);
            app.massEditFieldLabel_28.Text = 'CYr';
            app.massEditField_28 = uieditfield(app.LinearsystemparametersPanel, 'numeric');
            app.MonteCarlomethodCheckBox = uicheckbox(app.ControllerdesignTab);
            app.MonteCarlomethodCheckBox.Text = 'Monte Carlo method';
            app.RealtimesimulationTab = uitab(app.TabGroup2);
            app.RealtimesimulationTab.Title = 'Real-time simulation';
            app.RealtimesimulationTab.ButtonDownFcn = createCallbackFcn(app, @RealtimesimulationTabButtonDown, true);
            app.PositionAxes = uiaxes(app.RealtimesimulationTab);
            title(app.PositionAxes, 'Position 3D')
            xlabel(app.PositionAxes, 'Longitude(deg)')
            ylabel(app.PositionAxes, 'Latitude(deg)')
            zlabel(app.PositionAxes, 'Height(m)')
            app.AttitudeAxes = uiaxes(app.RealtimesimulationTab);
            title(app.AttitudeAxes, 'Attitude')
            xlabel(app.AttitudeAxes, 'Time(s)')
            ylabel(app.AttitudeAxes, 'Angle(deg)')
            zlabel(app.AttitudeAxes, 'Z')
            app.RateofTurnAxes = uiaxes(app.RealtimesimulationTab);
            title(app.RateofTurnAxes, 'Rate Of Turn')
            ylabel(app.RateofTurnAxes, 'Ang. rate(deg/s)')
            zlabel(app.RateofTurnAxes, 'Z')
            app.AccelerationAxes = uiaxes(app.RealtimesimulationTab);
            title(app.AccelerationAxes, 'Acceleration(body frame)')
            ylabel(app.AccelerationAxes, 'Acc(m/s^2)')
            zlabel(app.AccelerationAxes, 'Z')
            app.VelocityAxes = uiaxes(app.RealtimesimulationTab);
            title(app.VelocityAxes, 'Velocity(body frame)')
            ylabel(app.VelocityAxes, 'Vel(m/s)')
            zlabel(app.VelocityAxes, 'Z')
            app.RealAccelerationAxes = uiaxes(app.RealtimesimulationTab);
            title(app.RealAccelerationAxes, 'Real Acceleration(sensor frame)')
            xlabel(app.RealAccelerationAxes, 'Time(s)')
            ylabel(app.RealAccelerationAxes, 'Acc(m/s^2)')
            zlabel(app.RealAccelerationAxes, 'Z')
            app.Label = uilabel(app.RealtimesimulationTab);
            app.Label.Text = '';
            app.LoRarelatedPanel = uipanel(app.RealtimesimulationTab);
            app.LoRarelatedPanel.Title = 'LoRa related';
            app.PAtempuratureGaugeLabel = uilabel(app.LoRarelatedPanel);
            app.PAtempuratureGaugeLabel.Text = 'PA tempurature';
            app.PAtempuratureGauge = uigauge(app.LoRarelatedPanel, 'linear');
            app.PAtempuratureGauge.Limits = [0 90];
            app.CautionLabel = uilabel(app.LoRarelatedPanel);
            app.CautionLabel.Text = 'Caution !';
            app.testEditFieldLabel = uilabel(app.LoRarelatedPanel);
            app.testEditFieldLabel.Text = 'test';
            app.testEditField = uieditfield(app.LoRarelatedPanel, 'numeric');
            app.UplinkmessagePanel = uipanel(app.LoRarelatedPanel);
            app.UplinkmessagePanel.Title = 'Uplink message';
            app.ResetcoordinateButton = uibutton(app.UplinkmessagePanel, 'push');
            app.ResetcoordinateButton.Text = 'Reset coordinate';
            app.RebootFCButton = uibutton(app.UplinkmessagePanel, 'push');
            app.RebootFCButton.Text = 'Reboot FC';
            app.ModifydownlinkmessageButton = uibutton(app.UplinkmessagePanel, 'push');
            app.ModifydownlinkmessageButton.ButtonPushedFcn = createCallbackFcn(app, @ModifydownlinkmessageButtonPushed, true);
            app.ModifydownlinkmessageButton.Text = 'Modify downlink message';
            app.Tree = uitree(app.UplinkmessagePanel, 'checkbox');
            app.Tree.Visible = 'off';
            app.ChangeMessageNode = uitreenode(app.Tree);
            app.ChangeMessageNode.Text = 'Change Message';
            app.Node2 = uitreenode(app.ChangeMessageNode);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.ChangeMessageNode);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.ChangeMessageNode);
            app.Node4.Text = 'Node4';
            app.FlightmodeOFFButton = uibutton(app.UplinkmessagePanel, 'state');
            app.FlightmodeOFFButton.ValueChangedFcn = createCallbackFcn(app, @FlightmodeOFFButtonValueChanged, true);
            app.FlightmodeOFFButton.Text = {'Flight mode '; 'OFF'};
            app.LinkstatusPanel = uipanel(app.LoRarelatedPanel);
            app.LinkstatusPanel.Title = 'Link status';
            app.StartLinkingButton = uibutton(app.LinkstatusPanel, 'push');
            app.StartLinkingButton.ButtonPushedFcn = createCallbackFcn(app, @StartLinkingButtonPushed, true);
            app.StartLinkingButton.Text = 'Start Linking';
            app.StopLinkingButton = uibutton(app.LinkstatusPanel, 'push');
            app.StopLinkingButton.ButtonPushedFcn = createCallbackFcn(app, @StopLinkingButtonPushed, true);
            app.StopLinkingButton.Enable = 'off';
            app.StopLinkingButton.Text = 'Stop Linking';
            app.StartLamp = uilamp(app.LinkstatusPanel);
            app.COMportListBoxLabel = uilabel(app.LinkstatusPanel);
            app.COMportListBoxLabel.Text = 'COM port';
            app.COMportListBox = uilistbox(app.LinkstatusPanel);
            app.COMportListBox.Items = {'COM1', 'COM2', 'COM3', 'COM4'};
            app.COMportListBox.Value = 'COM1';
            app.BaudrateListBoxLabel = uilabel(app.LinkstatusPanel);
            app.BaudrateListBoxLabel.Text = 'Baudrate';
            app.BaudrateListBox = uilistbox(app.LinkstatusPanel);
            app.BaudrateListBox.Items = {'9600', '19200', '38400', '57600', '115200'};
            app.BaudrateListBox.Value = '9600';
            app.LoRaRSSIEditFieldLabel = uilabel(app.LinkstatusPanel);
            app.LoRaRSSIEditFieldLabel.Text = 'LoRa RSSI';
            app.LoRaRSSIEditField = uieditfield(app.LinkstatusPanel, 'numeric');
            app.LoRaRSSIEditField.Editable = 'off';
            app.LoRaSNREditFieldLabel = uilabel(app.LinkstatusPanel);
            app.LoRaSNREditFieldLabel.Text = 'LoRa SNR';
            app.LoRaSNREditField = uieditfield(app.LinkstatusPanel, 'numeric');
            app.LoRaSNREditField.Editable = 'off';
            app.LoRaRSSIGauge = uigauge(app.LinkstatusPanel, 'linear');
            app.LoRaRSSIGauge.Limits = [-130 20];
            app.LoRaRSSIGaugeLabel = uilabel(app.LinkstatusPanel);
            app.LoRaRSSIGaugeLabel.Text = 'LoRa RSSI';
            app.INSstatusPanel = uipanel(app.RealtimesimulationTab);
            app.INSstatusPanel.Title = 'INS status';
            app.StatuswordEditFieldLabel = uilabel(app.INSstatusPanel);
            app.StatuswordEditFieldLabel.Text = 'Status word';
            app.StatuswordEditField = uieditfield(app.INSstatusPanel, 'numeric');
            app.StatuswordEditField.Editable = 'off';
            app.UTCtimeEditFieldLabel = uilabel(app.INSstatusPanel);
            app.UTCtimeEditFieldLabel.Text = 'UTC time';
            app.WithGNSSLabel = uilabel(app.INSstatusPanel);
            app.WithGNSSLabel.Text = 'With GNSS';
            app.CoastingModelossGNSSin60secLabel = uilabel(app.INSstatusPanel);
            app.CoastingModelossGNSSin60secLabel.Text = {'Coasting Mode'; '(loss GNSS in 60sec)'};
            app.UTCtimeEditField = uieditfield(app.INSstatusPanel, 'text');
            app.WithoutGNSSLabel = uilabel(app.INSstatusPanel);
            app.WithoutGNSSLabel.Text = 'Without GNSS';
            app.FilterModeGaugeLabel = uilabel(app.INSstatusPanel);
            app.FilterModeGaugeLabel.Text = 'Filter Mode';
            app.FilterModeGauge = uigauge(app.INSstatusPanel, 'linear');
            app.FilterModeGauge.Limits = [0 3];
            app.FilterModeGauge.MajorTicks = [0 1 3];
            app.FilterModeGauge.MinorTicks = [];
            app.GNSSFixedGaugeLabel = uilabel(app.INSstatusPanel);
            app.GNSSFixedGaugeLabel.Text = 'GNSS Fixed';
            app.GNSSFixedGauge = uigauge(app.INSstatusPanel, 'linear');
            app.GNSSFixedGauge.Limits = [0 5];
            app.GNSSFixedGauge.MinorTicks = [];
            app.DfixedLabel = uilabel(app.INSstatusPanel);
            app.DfixedLabel.Text = '3D-fixed';
            app.DfixedLabel_2 = uilabel(app.INSstatusPanel);
            app.DfixedLabel_2.Text = '2D-fixed';
            app.DeadreckoningLabel = uilabel(app.INSstatusPanel);
            app.DeadreckoningLabel.Text = 'Dead reckoning';
            app.NofixedLabel = uilabel(app.INSstatusPanel);
            app.NofixedLabel.Text = 'No fixed';
            app.GNSSDeadreckoningLabel = uilabel(app.INSstatusPanel);
            app.GNSSDeadreckoningLabel.Text = 'GNSS+Dead reckoning';
            app.TimefixedLabel = uilabel(app.INSstatusPanel);
            app.TimefixedLabel.Text = 'Time fixed';
            app.GDOPEditFieldLabel = uilabel(app.INSstatusPanel);
            app.GDOPEditFieldLabel.Text = 'GDOP';
            app.GDOPEditField = uieditfield(app.INSstatusPanel, 'numeric');
            app.GDOPEditField.Editable = 'off';
            app.GNSSSatInfoEditFieldLabel = uilabel(app.INSstatusPanel);
            app.GNSSSatInfoEditFieldLabel.Text = 'GNSS Sat Info';
            app.GNSSSatInfoEditField = uieditfield(app.INSstatusPanel, 'numeric');
            app.GNSSSatInfoEditField.Editable = 'off';
            app.IMUtempuratureGaugeLabel = uilabel(app.INSstatusPanel);
            app.IMUtempuratureGaugeLabel.Text = 'IMU tempurature';
            app.IMUtempuratureGauge = uigauge(app.INSstatusPanel, 'linear');
            app.IMUtempuratureGauge.Limits = [0 90];
            app.SatalitenumberGaugeLabel = uilabel(app.INSstatusPanel);
            app.SatalitenumberGaugeLabel.Text = 'Satalite number';
            app.SatalitenumberGauge = uigauge(app.INSstatusPanel, 'linear');
            app.SatalitenumberGauge.Limits = [0 15];
            app.SatalitenumberGauge.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12];
            app.SatalitenumberGauge.MajorTickLabels = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.SatalitenumberGauge.MinorTicks = [];
            app.CautionLabel_2 = uilabel(app.INSstatusPanel);
            app.CautionLabel_2.Text = 'Caution !';
            app.Panel = uipanel(app.RealtimesimulationTab);
            app.CurrentLabel = uilabel(app.Panel);
            app.CurrentLabel.Text = 'Current';
            app.LatitudeEditFieldLabel = uilabel(app.Panel);
            app.LatitudeEditFieldLabel.Text = 'Latitude';
            app.LatitudeEditField = uieditfield(app.Panel, 'numeric');
            app.LatitudeEditField.ValueDisplayFormat = '%5.6f';
            app.LatitudeEditField.Editable = 'off';
            app.LongitudeEditFieldLabel = uilabel(app.Panel);
            app.LongitudeEditFieldLabel.Text = 'Longitude';
            app.LongitudeEditField = uieditfield(app.Panel, 'numeric');
            app.LongitudeEditField.ValueDisplayFormat = '%5.6f';
            app.LongitudeEditField.Editable = 'off';
            app.HeightEditFieldLabel = uilabel(app.Panel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.Panel, 'numeric');
            app.HeightEditField.ValueDisplayFormat = '%5.6f';
            app.HeightEditField.Editable = 'off';
            app.MeasurementdataPanel = uipanel(app.RealtimesimulationTab);
            app.MeasurementdataPanel.Title = 'Measurement data';
            app.AttitudePanel = uipanel(app.MeasurementdataPanel);
            app.AttitudePanel.Title = 'Attitude';
            app.RollEditFieldLabel = uilabel(app.AttitudePanel);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.AttitudePanel, 'numeric');
            app.RollEditField.Editable = 'off';
            app.PitchEditFieldLabel = uilabel(app.AttitudePanel);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.AttitudePanel, 'numeric');
            app.PitchEditField.Editable = 'off';
            app.YawEditFieldLabel = uilabel(app.AttitudePanel);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.AttitudePanel, 'numeric');
            app.YawEditField.Editable = 'off';
            app.RateOfTurnPanel = uipanel(app.MeasurementdataPanel);
            app.RateOfTurnPanel.Title = 'Rate Of Turn';
            app.RollrateEditFieldLabel = uilabel(app.RateOfTurnPanel);
            app.RollrateEditFieldLabel.Text = 'Roll rate';
            app.RollrateEditField = uieditfield(app.RateOfTurnPanel, 'numeric');
            app.RollrateEditField.Editable = 'off';

            app.PitchrateEditFieldLabel = uilabel(app.RateOfTurnPanel);
            app.PitchrateEditFieldLabel.Text = 'Pitch rate';
            app.PitchrateEditField = uieditfield(app.RateOfTurnPanel, 'numeric');
            app.PitchrateEditField.Editable = 'off';
            app.YawrateEditFieldLabel = uilabel(app.RateOfTurnPanel);
            app.YawrateEditFieldLabel.Text = 'Yaw rate';
            app.YawrateEditField = uieditfield(app.RateOfTurnPanel, 'numeric');
            app.YawrateEditField.Editable = 'off';
            app.FreeaccelerationPanel = uipanel(app.MeasurementdataPanel);
            app.FreeaccelerationPanel.Title = 'Free acceleration';
            app.AccXEditFieldLabel = uilabel(app.FreeaccelerationPanel);
            app.AccXEditFieldLabel.Text = 'Acc.X';
            app.AccXEditField = uieditfield(app.FreeaccelerationPanel, 'numeric');
            app.AccXEditField.Editable = 'off';
            app.AccYEditFieldLabel = uilabel(app.FreeaccelerationPanel);
            app.AccYEditFieldLabel.Text = 'Acc.Y';
            app.AccYEditField = uieditfield(app.FreeaccelerationPanel, 'numeric');
            app.AccYEditField.Editable = 'off';
            app.AccZEditFieldLabel = uilabel(app.FreeaccelerationPanel);
            app.AccZEditFieldLabel.Text = 'Acc.Z';
            app.AccZEditField = uieditfield(app.FreeaccelerationPanel, 'numeric');
            app.AccZEditField.Editable = 'off';
            app.VelocityPanel = uipanel(app.MeasurementdataPanel);
            app.VelocityPanel.Title = 'Velocity';
            app.VelXEditFieldLabel = uilabel(app.VelocityPanel);
            app.VelXEditFieldLabel.Text = 'Vel.X';
            app.VelXEditField = uieditfield(app.VelocityPanel, 'numeric');
            app.VelXEditField.Editable = 'off';
            app.VelYEditFieldLabel = uilabel(app.VelocityPanel);
            app.VelYEditFieldLabel.Text = 'Vel.Y';
            app.VelYEditField = uieditfield(app.VelocityPanel, 'numeric');
            app.VelYEditField.Editable = 'off';
            app.VelZEditFieldLabel = uilabel(app.VelocityPanel);
            app.VelZEditFieldLabel.Text = 'Vel.Z';
            app.VelZEditField = uieditfield(app.VelocityPanel, 'numeric');
            app.VelZEditField.Editable = 'off';
            app.ECEFPositionPanel = uipanel(app.MeasurementdataPanel);
            app.ECEFPositionPanel.Title = 'ECEF Position';
            app.PosXEditFieldLabel = uilabel(app.ECEFPositionPanel);
            app.PosXEditFieldLabel.Text = 'Pos.X';
            app.PosXEditField = uieditfield(app.ECEFPositionPanel, 'numeric');
            app.PosXEditField.Editable = 'off';
            app.PosYEditFieldLabel = uilabel(app.ECEFPositionPanel);
            app.PosYEditFieldLabel.Text = 'Pos.Y';
            app.PosYEditField = uieditfield(app.ECEFPositionPanel, 'numeric');
            app.PosYEditField.Editable = 'off';
            app.PosZEditFieldLabel = uilabel(app.ECEFPositionPanel);
            app.PosZEditFieldLabel.Text = 'Pos.Z';
            app.PosZEditField = uieditfield(app.ECEFPositionPanel, 'numeric');
            app.PosZEditField.Editable = 'off';
            app.RealaccelerationPanel = uipanel(app.MeasurementdataPanel);
            app.RealaccelerationPanel.Title = 'Real acceleration';
            app.AccXEditField_2Label = uilabel(app.RealaccelerationPanel);
            app.AccXEditField_2Label.Text = 'Acc.X';
            app.RealAccXEditField = uieditfield(app.RealaccelerationPanel, 'numeric');
            app.RealAccXEditField.Editable = 'off';
            app.AccYEditField_2Label = uilabel(app.RealaccelerationPanel);
            app.AccYEditField_2Label.Text = 'Acc.Y';
            app.RealAccYEditField = uieditfield(app.RealaccelerationPanel, 'numeric');
            app.RealAccYEditField.Editable = 'off';
            app.AccZEditField_2Label = uilabel(app.RealaccelerationPanel);
            app.AccZEditField_2Label.Text = 'Acc.Z';
            app.RealAccZEditField = uieditfield(app.RealaccelerationPanel, 'numeric');
            app.RealAccZEditField.Editable = 'off';
            app.Panel_2 = uipanel(app.RealtimesimulationTab);
            app.PredictIIPLabel = uilabel(app.Panel_2);
            app.PredictIIPLabel.Text = 'Predict IIP';
            app.LatitudeEditField_2Label = uilabel(app.Panel_2);
            app.LatitudeEditField_2Label.Text = 'Latitude';
            app.LatitudeEditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.LatitudeEditField_2.Editable = 'off';
            app.LongitudeEditField_2Label = uilabel(app.Panel_2);
            app.LongitudeEditField_2Label.Text = 'Longitude';
            app.LongitudeEditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.LongitudeEditField_2.Editable = 'off';
            app.HeightEditField_2Label = uilabel(app.Panel_2);
            app.HeightEditField_2Label.Text = 'Height';
            app.HeightEditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.HeightEditField_2.Editable = 'off';
            app.DataloggingPanel = uipanel(app.RealtimesimulationTab);
            app.DataloggingPanel.Title = 'Data logging';
            app.LoggingStopButton = uibutton(app.DataloggingPanel, 'state');
            app.LoggingStopButton.ValueChangedFcn = createCallbackFcn(app, @LoggingStopButtonValueChanged, true);
            app.LoggingStopButton.Text = {'Logging'; 'Stop'};
            app.FilenameEditFieldLabel = uilabel(app.DataloggingPanel);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.DataloggingPanel, 'text');
            app.FilenameEditField.Value = 'Flightdata';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main_func

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end