classdef test1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        StartLamp           matlab.ui.control.Lamp
        StartLinkingButton  matlab.ui.control.Button
        StopLinkingButton   matlab.ui.control.Button
        testEditField       matlab.ui.control.NumericEditField
        EditFieldLabel      matlab.ui.control.Label
        AttitudeAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        comport
        bitrate
        timeout
        ArduinoObj
        Count

        RollPlot
        PositionPlot
    end
    
    methods (Access = private)
        
        function SerialTerminFCN(app,src,~)
            % if mod(app.Count,1000)==0
            %     flush(app.ArduinoObj)
            % end
            datachar = readline(app.ArduinoObj);
            % app.testEditField.Value = str2double(datachar);
            addpoints(app.PositionPlot,app.Count,app.Count,app.Count)
            % addpoints(app.RollPlot,app.Count,str2double(datachar));
            app.Count = app.Count+1;
            % app.AttitudeAxes.XLim = [app.Count-10,app.Count+1];
        end
        %% link comport
        function StartLinkCOMport(app)
            if app.StartLinkingButton.Enable == 1 %Debug
                %Change button state
                app.StartLinkingButton.Enable = 0;
                app.StopLinkingButton.Enable = 1;
                app.StartLamp.Color = 'green';
                %Link COM port and set callback
                app.comport = "COM1";
                app.bitrate = 115200;%
                app.timeout = 100;
                app.ArduinoObj = serialport(app.comport,app.bitrate,"Timeout",app.timeout);%connect to serial port
                configureTerminator(app.ArduinoObj,"CR/LF");
                flush(app.ArduinoObj);%Flush the serialport object to remove any old data.
                % app.ArduinoObj.UserData = struct("Data",[],"Count",1);%Prepare the UserData property to store the Arduino data.

                % app.RollPlot = animatedline(app.AttitudeAxes,'Marker','.','MaximumNumPoints',100,'Color','m');
                app.PositionPlot = animatedline(app.AttitudeAxes,'MaximumNumPoints',100);
                view(app.AttitudeAxes,3);
                app.Count=0;
                configureCallback(app.ArduinoObj,"terminator",@app.SerialTerminFCN);
            end
        end
        function StopLinkCOMport(app)
            if app.StartLinkingButton.Enable == 0 %Debug
                app.StartLinkingButton.Enable = 1;
                app.StopLinkingButton.Enable = 0;
                app.StartLamp.Color = 'red';
                configureCallback(app.ArduinoObj,"off")
                delete(app.ArduinoObj);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: StopLinkingButton
        function StopLinkingButtonPushed(app, event)
            StopLinkCOMport(app);
        end

        % Button pushed function: StartLinkingButton
        function StartLinkingButtonPushed(app, event)
            StartLinkCOMport(app);
            app.Count = 0;%initialize counter
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AttitudeAxes = uiaxes(app.UIFigure);
            title(app.AttitudeAxes, 'Title')
            xlabel(app.AttitudeAxes, 'X')
            ylabel(app.AttitudeAxes, 'Y')
            zlabel(app.AttitudeAxes, 'Z')
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.testEditField = uieditfield(app.UIFigure, 'numeric');
            app.StopLinkingButton = uibutton(app.UIFigure, 'push');
            app.StopLinkingButton.ButtonPushedFcn = createCallbackFcn(app, @StopLinkingButtonPushed, true);
            app.StopLinkingButton.Text = 'Stop Linking';
            app.StartLinkingButton = uibutton(app.UIFigure, 'push');
            app.StartLinkingButton.ButtonPushedFcn = createCallbackFcn(app, @StartLinkingButtonPushed, true);
            app.StartLinkingButton.Text = 'Start Linking';
            app.StartLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end